classdef Parti_Suite_AFM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AsperityradiusmLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        heat_hist_panel                 matlab.ui.container.Panel
        heat_hist_PB                    matlab.ui.control.Button
        hbardisc2                       matlab.ui.control.EditField
        MaxSeparationdistmEditField_3Label  matlab.ui.control.Label
        hbardisc1                       matlab.ui.control.EditField
        MinSeparationdistmEditFieldLabel  matlab.ui.control.Label
        BarrierLabel                    matlab.ui.control.Label
        PrimaryminimumLabel             matlab.ui.control.Label
        hpridisc                        matlab.ui.control.EditField
        MaxSeparationdistmEditFieldLabel  matlab.ui.control.Label
        AuthorsPanel                    matlab.ui.container.Panel
        v221Label                       matlab.ui.control.Label
        EscuelaPolitcnicaNacionalQuitoEcuadorLabel  matlab.ui.control.Label
        UniversityofUtahSaltLakeCityUtahUSALabel  matlab.ui.control.Label
        wpjohnsongrouputaheduLabel      matlab.ui.control.Label
        WilliamPJohnsonandEddyPazmioLabel  matlab.ui.control.Label
        ConceptbyLabel                  matlab.ui.control.Label
        EddyPazmioandWilliamPJohnsonLabel  matlab.ui.control.Label
        CodedevelopedbyLabel            matlab.ui.control.Label
        Deformation_parameters          matlab.ui.container.Panel
        beta                            matlab.ui.control.EditField
        ContactradiusfactorLabel        matlab.ui.control.Label
        w132                            matlab.ui.control.EditField
        WorkofadhesionJm2Label          matlab.ui.control.Label
        kint                            matlab.ui.control.EditField
        CombinedelasticmodulusNm2Label  matlab.ui.control.Label
        Calculation_panel               matlab.ui.container.Panel
        savePB                          matlab.ui.control.Button
        setdirPB                        matlab.ui.control.Button
        hetPB                           matlab.ui.control.Button
        profPB                          matlab.ui.control.Button
        Mean_field_potentials           matlab.ui.container.Panel
        zetacst                         matlab.ui.control.EditField
        DomainzpotentialVLabel          matlab.ui.control.Label
        zetapst                         matlab.ui.control.EditField
        ProbezpotentialVLabel           matlab.ui.control.Label
        zi                              matlab.ui.control.EditField
        ElectrolytevalenceLabel         matlab.ui.control.Label
        is                              matlab.ui.control.EditField
        Ionicstrengthmolm3Label         matlab.ui.control.Label
        lewisab_and_steric_parameters   matlab.ui.container.Panel
        lambdaste                       matlab.ui.control.EditField
        StericdecaylengthmLabel         matlab.ui.control.Label
        gamma0ste                       matlab.ui.control.EditField
        StericenergyperareaJm2Label     matlab.ui.control.Label
        lambdaab                        matlab.ui.control.EditField
        AcidbasedecaylengthmLabel       matlab.ui.control.Label
        gamma0ab                        matlab.ui.control.EditField
        AcidbaseenergyperareaJm2Label   matlab.ui.control.Label
        vdw_coated_panel                matlab.ui.container.Panel
        t2                              matlab.ui.control.EditField
        DomaincoatingthicknessmLabel    matlab.ui.control.Label
        t1                              matlab.ui.control.EditField
        ProbecoatingthicknessmLabel     matlab.ui.control.Label
        a33                             matlab.ui.control.EditField
        FluidHamakercstJLabel           matlab.ui.control.Label
        ac2c2                           matlab.ui.control.EditField
        DomaincoatingHcstJLabel         matlab.ui.control.Label
        a22                             matlab.ui.control.EditField
        DomainHamakercstJLabel          matlab.ui.control.Label
        ac1c1                           matlab.ui.control.EditField
        ProbecoatingHcstJLabel          matlab.ui.control.Label
        a11                             matlab.ui.control.EditField
        ProbeHamakercstJLabel           matlab.ui.control.Label
        van_der_Waals_force_parameters  matlab.ui.container.Panel
        vdwmode                         matlab.ui.control.EditField
        vanderWaalsmode123or4EditFieldLabel  matlab.ui.control.Label
        lambdavdw                       matlab.ui.control.EditField
        vanderWaalsdecaylengthmEditFieldLabel  matlab.ui.control.Label
        a132                            matlab.ui.control.EditField
        CombinedHamakercstJLabel        matlab.ui.control.Label
        Colloidal_probe_surface_heterogeneity_parameters  matlab.ui.container.Panel
        scovp                           matlab.ui.control.EditField
        FractionalsurfacecoverageEditField_2Label  matlab.ui.control.Label
        rhetp1                          matlab.ui.control.EditField
        SmallhetdomainradiusmEditField_2Label  matlab.ui.control.Label
        rhetp0                          matlab.ui.control.EditField
        LargehetdomainradiusmEditField_2Label  matlab.ui.control.Label
        hetmodep                        matlab.ui.control.EditField
        Hetmode1or5EditFieldLabel       matlab.ui.control.Label
        zetahetp                        matlab.ui.control.EditField
        HetdomainzpotentialVEditField_2Label  matlab.ui.control.Label
        Domain_surface_heterogeneity_parameters  matlab.ui.container.Panel
        scov                            matlab.ui.control.EditField
        FractionalsurfacecoverageEditFieldLabel  matlab.ui.control.Label
        rhet2                           matlab.ui.control.EditField
        SmallhetdomainradiusmEditFieldLabel  matlab.ui.control.Label
        rhet0                           matlab.ui.control.EditField
        LargehetdomainradiusmEditFieldLabel  matlab.ui.control.Label
        rhet1                           matlab.ui.control.EditField
        MediumhetdomainradiusmEditFieldLabel  matlab.ui.control.Label
        hetmode                         matlab.ui.control.EditField
        Hetmode15973EditFieldLabel      matlab.ui.control.Label
        zetahet                         matlab.ui.control.EditField
        HetdomainzpotentialVEditFieldLabel  matlab.ui.control.Label
        PartiSuiteAFMForceVolumeSimulatorLabel  matlab.ui.control.Label
        Force_volume_domain             matlab.ui.container.Panel
        adom                            matlab.ui.control.EditField
        DomainlengthmLabel              matlab.ui.control.Label
        nloc                            matlab.ui.control.EditField
        locationsperaxisLabel           matlab.ui.control.Label
        Fluid_physical_properties       matlab.ui.container.Panel
        T                               matlab.ui.control.EditField
        TemperatureKLabel               matlab.ui.control.Label
        er                              matlab.ui.control.EditField
        RelpermittivityEditFieldLabel   matlab.ui.control.Label
        visc                            matlab.ui.control.EditField
        ViscositykgmsEditFieldLabel     matlab.ui.control.Label
        rhow                            matlab.ui.control.EditField
        Densitykgm3EditField_2Label     matlab.ui.control.Label
        Colloidal_probe_physical_properties  matlab.ui.container.Panel
        rhop                            matlab.ui.control.EditField
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        ap                              matlab.ui.control.EditField
        RadiusmEditFieldLabel           matlab.ui.control.Label
        Roughness_parameters            matlab.ui.container.Panel
        aspdomain                       matlab.ui.control.EditField
        DomainEditFieldLabel            matlab.ui.control.Label
        aspcolloid                      matlab.ui.control.EditField
        AsperityheightmModes123Label    matlab.ui.control.Label
        rmode                           matlab.ui.control.EditField
        Roughnessmode012or3Label        matlab.ui.control.Label
        b                               matlab.ui.control.EditField
        SliplengthmLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: profPB
        function profPBPushed(app, event)
            global NPART RLIM
            global AP RHOP
            global RHOW VISC ER T
            global IS ZI ZETAPST ZETACST
            global ZETAHET HETMODE RHET1 RHET0 RHET2 SCOV
            global ZETAHETP HETMODEP RHETP1 RHETP0 SCOVP
            global B RMODE ASPcolloid ASPdomain
            global A132 LAMBDAVDW VDWMODE
            global GAMMA0AB LAMBDAAB GAMMA0STE LAMBDASTE
            global A11 AC1C1 A22 AC2C2 A33 T1 T2
            global KINT W132 BETA
            global HFRIC HMIN Hlow Hhigh
            % load logos
            % % set working directory        
%             if ~isdeployed
%                 % MATLAB environment
%                 mname = mfilename('fullpath');
%                 logopath = strrep(mname,'\Parti_Suite_AFM','\');
%                 logopath = strcat(logopath,'logoUtahEPNv2.png');
%             else
%                %deployed application
%                 logopath=pwd
%                 logopath = strcat(logopath,'logoUtahEPNv2.png')
%             end
%             
%             %logopath = %'logoUtahEPNv2.png'
%             app.Image.ImageSource =logopath;
            % read in input values
            % get number of locations
            NPART = str2double(get(app.nloc,'Value'));
            % get domain radius (m)
            RLIM = str2double(get(app.adom,'Value'));
            %
            % get probe physical properties
            % get probe radius (m)
            AP = str2double(get(app.ap,'Value'));
            % probe density (kg/m3)
            RHOP = str2double(get(app.rhop,'Value'));
            %
            % get fluid physical properties
            % fluid viscosity
            RHOW = str2double(get(app.rhow,'Value'));
            % fluid density (kg/m3)
            VISC = str2double(get(app.visc,'Value'));
            % relative permitivitty
            ER = str2double(get(app.er,'Value'));
            % temperature (K)
            T = str2double(get(app.T,'Value'));
            %
            % get mean field potentials
            %ionic strength (mol/m3)
            IS = str2double(get(app.is,'Value'));
            % electolyte valenece(-)
            ZI = str2double(get(app.zi,'Value'));
            % probe zeta potential (V)
            ZETAPST = str2double(get(app.zetapst,'Value'));
            % domain zeta potential (V)
            ZETACST = str2double(get(app.zetacst,'Value'));
            %
            % domain surface heterogeneity
            % domain heterogeneity zeta potential (V)
            ZETAHET = str2double(get(app.zetahet,'Value'));
            % domain hetmode(-)
            HETMODE = str2double(get(app.hetmode,'Value'));
            % large hetdomain radius (m)
            RHET0 = str2double(get(app.rhet0,'Value'));
            % medium hetdomain radius (m)
            RHET1 = str2double(get(app.rhet1,'Value'));
            % small hetdomain radius (m)
            RHET2 = str2double(get(app.rhet2,'Value'));
            % hetdomain domain surface coverage
            SCOV = str2double(get(app.scov,'Value'));
            %
            % colloidal probe surface heterogeneity
            % probe heterogeneity zeta potential (V)
            ZETAHETP = str2double(get(app.zetahetp,'Value'));
            % probe hetmode(-)
            HETMODEP = str2double(get(app.hetmodep,'Value'));
            % large hetdomain radius (m)
            RHETP0 = str2double(get(app.rhetp0,'Value'));
            % small hetdomain radius (m)
            RHETP1 = str2double(get(app.rhetp1,'Value'));
            %hetdomain probe surface coverage
            SCOVP = str2double(get(app.scovp,'Value'));
            %
            % van der Waals force
            % combined Hamaker constant (J)
            A132 = str2double(get(app.a132,'Value'));
            % vdw characteristic decay length (m)
            LAMBDAVDW = str2double(get(app.lambdavdw,'Value'));
            % vdw mode (1 2 3 4, layer options)
            VDWMODE = str2double(get(app.vdwmode,'Value'));
            %
            % roughness
        
            % slip length (m)
            B = str2double(get(app.b,'Value'));
            % roughnes mode (1 2 3)
            RMODE = str2double(get(app.rmode,'Value'));
            % asperity height(m)
            ASPcolloid = str2double(get(app.aspcolloid,'Value'));
            ASPdomain = str2double(get(app.aspdomain,'Value'));
            %
            % van der Waals COated systems
            % probe Hamaker constant (J)
            A11 = str2double(get(app.a11,'Value'));
            % probe coating Hamaker constant (J)
            AC1C1 = str2double(get(app.ac1c1,'Value'));
            % domain Hamaker constant (J)
            A22 = str2double(get(app.a22,'Value'));
            % domain coating Hamaker constant (J)
            AC2C2 = str2double(get(app.ac2c2,'Value'));
            % fluid Hamaker constant (J)
            A33 = str2double(get(app.a33,'Value'));
            % probe coating thickness (m)
            T1 = str2double(get(app.t1,'Value'));
            % domain coating thickness (m)
            T2 = str2double(get(app.t2,'Value'));
            %
            %Lewis acid-base and steric hydration force parameters
            % acid-base energy per unit area (J/m2)
            GAMMA0AB = str2double(get(app.gamma0ab,'Value'));
            % acid-base decay length(m)
            LAMBDAAB = str2double(get(app.lambdaab,'Value'));
            % steric energy per unit area (J/m2)
            GAMMA0STE = str2double(get(app.gamma0ste,'Value'));
            % steric decay length(m)
            LAMBDASTE = str2double(get(app.lambdaste,'Value'));
            %
            % deformation parameters
            % combined elastic modulus (N/m2)
            KINT = str2double(get(app.kint,'Value'));
            %work of adhesion (J/m2)
            W132 = str2double(get(app.w132,'Value'));
            %contact radius factor (-)
            BETA = str2double(get(app.beta,'Value'));
            % call AFM profile generator
            AFM_happel()
            % enable separation distance limits for heatmap and histogram panels
            app.hpridisc.Enable = 'on';
            app.hbardisc1.Enable = 'on';
            app.hbardisc2.Enable = 'on';
            % set window values dependig on data or simulated profiles 
            %simulated profiles
            app.hpridisc.Value = num2str(Hlow-HMIN+HFRIC);
            app.hbardisc1.Value = num2str(Hlow-HMIN+HFRIC);
            app.hbardisc2.Value = num2str(Hhigh);
            % from data , nned to add checkbox

            % enable generate histograms
            app.heat_hist_PB.Enable = 'on';
        end

        % Button pushed function: hetPB
        function hetPBButtonPushed(app, event)
            
            % hObject    handle to buttonDrawHetdom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % get parameters from fields
            global NPART RLIM
            global AP RHOP
            global RHOW VISC ER T
            global IS ZI ZETAPST ZETACST
            global ZETAHET HETMODE RHET1 RHET0 RHET2 SCOV
            global ZETAHETP HETMODEP RHETP1 RHETP0 SCOVP
            global B RMODE ASP
            global A132 LAMBDAVDW VDWMODE
            global GAMMA0AB LAMBDAAB GAMMA0STE LAMBDASTE
            global A11 AC1C1 A22 AC2C2 A33 T1 T2
            global KINT W132 BETA
            global ASPcolloid ASPdomain
            % get number of locations
            NPART = str2double(get(app.nloc,'Value'));
            % get domain radius (m)
            RLIM = str2double(get(app.adom,'Value'));
            %
            % get probe physical properties
            % get probe radius (m)
            AP = str2double(get(app.ap,'Value'));
            % probe density (kg/m3)
            RHOP = str2double(get(app.rhop,'Value'));
            %
            % get fluid physical properties
            % fluid viscosity
            RHOW = str2double(get(app.rhow,'Value'));
            % fluid density (kg/m3)
            VISC = str2double(get(app.visc,'Value'));
            % relative permitivitty
            ER = str2double(get(app.er,'Value'));
            % temperature (K)
            T = str2double(get(app.T,'Value'));
            %
            % get mean field potentials
            %ionic strength (mol/m3)
            IS = str2double(get(app.is,'Value'));
            % electolyte valenece(-)
            ZI = str2double(get(app.zi,'Value'));
            % probe zeta potential (V)
            ZETAPST = str2double(get(app.zetapst,'Value'));
            % domain zeta potential (V)
            ZETACST = str2double(get(app.zetacst,'Value'));
            %
            % domain surface heterogeneity
            % domain heterogeneity zeta potential (V)
            ZETAHET = str2double(get(app.zetahet,'Value'));
            % domain hetmode(-)
            HETMODE = str2double(get(app.hetmode,'Value'));
            % large hetdomain radius (m)
            RHET0 = str2double(get(app.rhet0,'Value'));
            % medium hetdomain radius (m)
            RHET1 = str2double(get(app.rhet1,'Value'));
            % small hetdomain radius (m)
            RHET2 = str2double(get(app.rhet2,'Value'));
            % hetdomain domain surface coverage
            SCOV = str2double(get(app.scov,'Value'));
            %
            % colloidal probe surface heterogeneity
            % probe heterogeneity zeta potential (V)
            ZETAHETP = str2double(get(app.zetahetp,'Value'));
            % probe hetmode(-)
            HETMODEP = str2double(get(app.hetmodep,'Value'));
            % large hetdomain radius (m)
            RHETP0 = str2double(get(app.rhetp0,'Value'));
            % small hetdomain radius (m)
            RHETP1 = str2double(get(app.rhetp1,'Value'));
            %hetdomain probe surface coverage
            SCOVP = str2double(get(app.scovp,'Value'));
            %
            % van der Waals force
            % combined Hamaker constant (J)
            A132 = str2double(get(app.a132,'Value'));
            % vdw characteristic decay length (m)
            LAMBDAVDW = str2double(get(app.lambdavdw,'Value'));
            % vdw mode (1 2 3 4, layer options)
            VDWMODE = str2double(get(app.vdwmode,'Value'));
            %
            % roughness
        
            % slip length (m)
            B = str2double(get(app.b,'Value'));
            % roughnes mode (1 2 3)
            RMODE = str2double(get(app.rmode,'Value'));
            % asperity height(m)
            ASP = [];
            ASPcolloid =str2double(get(app.aspcolloid,'Value'));
            ASPdomain =str2double(get(app.aspdomain,'Value'));
            if ASPcolloid<=ASPdomain
                ASP=ASPcolloid;
            else
                ASP=ASPdomain;
            end

            %
            % van der Waals COated systems
            % probe Hamaker constant (J)
            A11 = str2double(get(app.a11,'Value'));
            % probe coating Hamaker constant (J)
            AC1C1 = str2double(get(app.ac1c1,'Value'));
            % domain Hamaker constant (J)
            A22 = str2double(get(app.a22,'Value'));
            % domain coating Hamaker constant (J)
            AC2C2 = str2double(get(app.ac2c2,'Value'));
            % fluid Hamaker constant (J)
            A33 = str2double(get(app.a33,'Value'));
            % probe coating thickness (m)
            T1 = str2double(get(app.t1,'Value'));
            % domain coating thickness (m)
            T2 = str2double(get(app.t2,'Value'));
            %
            %Lewis acid-base and steric hydration force parameters
            % acid-base energy per unit area (J/m2)
            GAMMA0AB = str2double(get(app.gamma0ab,'Value'));
            % acid-base decay length(m)
            LAMBDAAB = str2double(get(app.lambdaab,'Value'));
            % steric energy per unit area (J/m2)
            GAMMA0STE = str2double(get(app.gamma0ste,'Value'));
            % steric decay length(m)
            LAMBDASTE = str2double(get(app.lambdaste,'Value'));
            %
            % deformation parameters
            % combined elastic modulus (N/m2)
            KINT = str2double(get(app.kint,'Value'));
            %work of adhesion (J/m2)
            W132 = str2double(get(app.w132,'Value'));
            %contact radius factor (-)
            BETA = str2double(get(app.beta,'Value'));
    
    %
            % hardwire grain radius to approximate flat surface
            AG = 1e9*AP;
            %% calculate RZOI
            %     CALCULATE KAPPA NOTE: IS FOR 1:1 ELECTROLYTE ONLY
            PI=3.14159265359;         %PIE (CHERRY,STRAWBERRY RHUBARB, ETC.)
            E0=8.85418781762E-12;     %VACUUM PERMITTIVITY (C^2/N M^2)
            ECHG=1.602176621E-19;     %ELEMENTARY CHARGE (C)
            KB=1.3806485E-23;         %BOLTZMANN CONSTANT (J/K)
            ERE0 = ER*E0;            %ABSOLUTE PERMITTIVITY
            NIO = IS*2*6.02214086E23;
            KAPPA = ((ECHG^2.0)*NIO*(ZI^2.0)/(ERE0*KB*T))^0.5;
            %   CALCULATE RZOIBULK: RADIUS OF ZONE OF INFLUENCE WITHOUT DEFORMATION
            RZOIBULK = 2.0*((1/KAPPA)*AP)^0.5;
            %% figure name detailed near surface figure
            Dnearfig = 'Near_Surface_Geometry';
            % set figure number
            finitNear = 100;
            figure(finitNear);
            set(gcf,'name',Dnearfig,'NumberTitle','off');
            %% define scaling of figure
            % scale near surface plot based on colloid size
            nang=500;% resolution angular grid points for collector sphere cap array
            fsurf = 3; % factor of sphere cap (as amultiple of the arc of length AP)
            % set factor to axis limits around center of plot (as a factor of AP)
            axsc = 1.3*fsurf/2;
            nsplotH = 2.0e-7; % default near surface visualization distance
            if axsc*AP<2.0e-7
                nsplotH = axsc*AP;
            end
            % estimate sphere cap grid resolution
            res = fsurf*AP*2/nang;
            % set location vectors
            Xvect = [0.0+1e-10, 0.0];
            Yvect = [0.0+1e-10, AG+2*ASP+AP];
            Zvect = [AG+2*ASP+AP, 0.0];
            % set subplot titles
            ltitle = {'Pole Normal','Equator Normal'};
            rtitle = {'Pole Perspective','Equator Perspective'};
            % set collector color based on charge sign
            if ZETACST<0.0
                collecolor = 'r';
            else
                collecolor = 'g';
            end
            % set colloid color based on charge sign
            if ZETAPST<0.0
                colcolor = [0.9290 0.6940 0.1250];
            else
                colcolor = 'b';
            end
            for i=1:1
                %% define colloid location and collector location for pole location
                % se collector center
                Xm0 = 0.0; Ym0=0.0; Zm0=0.0;
                % colloid center (at equator)
                X=Xvect(i); Y=Yvect(i); Z =Zvect(i);
                % CALCULATE RADIAL DISTANCE
                R = ((X-Xm0)*(X-Xm0)+(Y-Ym0)*(Y-Ym0)+(Z-Zm0)*(Z-Zm0))^0.5;
                H = R-AG-AP;
                %% plot geometry NEAR SURFACE
                % draw roughness only if its scale exceeds a factor of res TBD
                [xcap,ycap,zcap,xs,ys,zs,thetap,phip,rxys,unitx,unity,unitz]=AFMsphere_cap_OLD(X,Y,Z,AP,AG,fsurf,nang,res);
                %[xcap,ycap,zcap,xs,ys,zs,thetap,phip,rxys,unitx,unity,unitz]=AFMsphere_cap(X,Y,Z,AP,AG,fsurf,nang,res);
                % Generate dummy collector hetdomains center and radii for diagnostics
                %[xhetv,yhetv,zhetv,rhetv] = dummyhetcap(xcap,ycap,zcap,nhet0,nhet1,nhet2,rhet0,rhet1,rhet2);
                % Generate dummy colloid hetdomains center and radii for diagnostics
                %[xhetvcol,yhetvcol,zhetvcol,rhetvcol] = dummyhetsphere(X,Y,Z,AP,nhetp0,nhetp1,rhetp0,rhetp1);
                % calculate center of plot
                xpl = unitx*(AG+AP);
                ypl = unity*(AG+AP);
                zpl = unitz*(AG+AP);
                %% calculate POV angles (elevation and azimuth) for near surface plot
                % perspective POV
                azoff = -25;
                azpers = (phip/2/pi*360+90)+90+azoff;
                elpers = 10.0;
                %normal
                aznorm = phip/2/pi*360+90;
                if Z>0.0
                    elnorm = (-thetap+pi/2)/2/pi*360;
                else
                    elnorm = (-thetap+pi/2)/2/pi*360;
                end
                %   CALCULATE UNIT VECTORS
                ENX = (X-Xm0)/R;
                ENY = (Y-Ym0)/R;
                ENZ = (Z-Zm0)/R ;
                %   CALCULATE PROJECTION OF COLLOID CENTER ON HAPPEL SPHERE
                XG = Xm0+ENX*AG;
                YG = Ym0+ENY*AG;
                ZG = Zm0+ENZ*AG;
                %   CALCULATE COLLOID RADIAL POSITION - SPHERICAL COORDINATES AS USED IN PHYSICS (ISO 80000-2:2019 CONVENTION)
                RO = ((X-Xm0)*(X-Xm0)+(Y-Ym0)*(Y-Ym0)+(Z-Zm0)*(Z-Zm0))^0.5;
                %   CALCULATE COLLOID THETA ANGLE - SPHERICAL COORDINATES AS USED IN PHYSICS (ISO 80000-2:2019 CONVENTION)
                THETA = acos((Z-Zm0)/RO);
                %   CALCULATE PROJECTION OF COLLOID POSITION ON XY PLANE
                ROXY = ((X-Xm0)*(X-Xm0)+(Y-Ym0)*(Y-Ym0))^0.5;
                %   CALCULATE COLLOID PHI ANGLE - SPHERICAL COORDINATES AS USED IN PHYSICS (ISO 80000-2:2019 CONVENTION)
                if (ROXY==0.0)
                    PHI = 0.0;
                else
                    if ((Y-Ym0)>=0.0)
                        PHI = acos((X-Xm0)/ROXY);
                    else
                        PHI = 2.0*pi-acos((X-Xm0)/ROXY);
                    end
                end
                %% obtain hetdomain locations
                %   HETEROGENEITY ON COLLOID (HETP) SUBROUTINE TO DETERMINE HETERODOMAIN PROJECTIONS
                %   HETP PROJECTIONS WILL BE GENERATED ASSUMING THAT THE COLLOID CENTER COINCIDES WITH THE HAPPEL SPHERE CENTER
                %   MHETP CONTAINS HETP COORDINATES AND RADII(FORMAT: [XHETP YHETP ZHET RHETP])
                %   MPRO CONTAINS HETP PROJECTION COORDINATES AND RADII(FORMAT: [XPRO YPRO ZPRO RPRO])
                [MHETP, MPRO]=AFMHETTRACKP(Xm0,Ym0,Zm0,H,RZOIBULK,AP,HETMODEP,SCOVP,RHETP0,RHETP1);
                %   HETP WILL BE TRANSLATED TO THE COLLECTOR FRAME OF REFERENCE AND
                %   ROTATED BASED ON COLLOID SPHERICAL COORDINATES, FOR FRONT-END PLOTTING
                %   HETP PROJECTIONS WILL BE TRANSLATED AND ROTATED TO THE FRAME OF REFERENCE WITH X-Y PLANE MATCHING
                %   THE PLANE PARALLEL TO THE HAPPEL SPHERE AT THE POINT OF PROJECTION OF THE COLLOID CENTER, TO CALCULATE AFRACT
                [MHETP_PLOT,MPRO_AF] = AFMHETP_TRANSFORM(X,Y,Z,XG,YG,ZG,THETA,PHI,MHETP,MPRO);
                
                %% plot
                % plot geometry
                figure(finitNear)
                subplot(2,2,1+(i-1)*2)
                plot3(xpl,ypl,zpl,'ob')
                title(cell2mat(ltitle(i)))
                axis([-axsc*AP+xpl axsc*AP+xpl -axsc*AP+ypl axsc*AP+ypl -axsc*AP+zpl axsc*AP+zpl])
                axis square
                view(aznorm,elnorm)
                hold on
                xlabel('X'); ylabel('Y'); zlabel('Z');
                % plot sphere cap surface on collector
                surf(xcap,ycap,zcap,'FaceColor',collecolor,'EdgeColor','none')
                subp=[2,2,1+(i-1)*2];
                [XHET,YHET,ZHET,RHET]=AFMHETTRACK(X,Y,Z,Xm0,Ym0,Zm0,AG,HETMODE,SCOV,RHET0,RHET1,RHET2,1);
                % paint hetdomains on collector cap
                if SCOV>0
                    AFMhetpaint(finitNear,subp,xcap,ycap,zcap,XHET,YHET,ZHET,RHET)
                end
                % draw hetdomains on colloid surface
                if SCOVP>0
                    AFMcolloidHetPaint(finitNear,subp,MHETP_PLOT(:,1),MHETP_PLOT(:,2),MHETP_PLOT(:,3),MHETP_PLOT(:,4),X,Y,Z,AP)
                end
                %% generate colloid ring and zoi to visualizae colloid border and zoi
                [xcirc,ycirc,zcirc,xzoi,yzoi,zzoi]=AFMcolloid_circle(X,Y,Z,AP,AG,RZOIBULK);
                figure(finitNear)
                subplot(2,2,1+(i-1)*2)
                plot3(xcirc,ycirc,zcirc,'.','MarkerEdgeColor','k')
                plot3(xzoi,yzoi,zzoi,'.','MarkerEdgeColor',colcolor)
                %%
                figure(finitNear)
                subplot(2,2,2+(i-1)*2)
                plot3(xpl,ypl,zpl,'ob')
                title(cell2mat(rtitle(i)))
                axis([-axsc*AP+xpl axsc*AP+xpl -axsc*AP+ypl axsc*AP+ypl -axsc*AP+zpl axsc*AP+zpl])
                axis square
                view(azpers,elpers)
                hold on
                xlabel('X'); ylabel('Y'); zlabel('Z');
                % plot sphere cap surface on collector
                surf(xcap,ycap,zcap,'FaceColor',collecolor,'EdgeColor','none')
                subp=[2,2,2+(i-1)*2];
                % paint hetdomains on collector cap
                if SCOV>0
                    AFMhetpaint(finitNear,subp,xcap,ycap,zcap,XHET,YHET,ZHET,RHET)
                end
                % draw hetdomains on colloid surface
                if SCOVP>0
                    AFMcolloidHetPaint(finitNear,subp,MHETP_PLOT(:,1),MHETP_PLOT(:,2),MHETP_PLOT(:,3),MHETP_PLOT(:,4),X,Y,Z,AP)
                end
                %% generate colloid ring and zoi to visualizae colloid border and zoi
                figure(finitNear)
                subplot(2,2,2+(i-1)*2)
                plot3(xcirc,ycirc,zcirc,'.','MarkerEdgeColor','k')
                plot3(xzoi,yzoi,zzoi,'.','MarkerEdgeColor',colcolor)
                %%
                % plot center of cap from angles
                z1=cos(thetap)*AG;
                x1=cos(phip)*rxys;
                y1=sin(phip)*rxys;
                % hold off figure
                figure(finitNear)
                subplot(2,2,1+(i-1)*2)
                hold off
                figure(finitNear)
                subplot(2,2,2+(i-1)*2)
                hold off
                
            end
            %% contact figure geometry
            % set figure number
            Dcontactfig =  strcat('Contact_Geometry');
            fDcontact=200;
            figure(fDcontact)
            set(gcf,'name',Dcontactfig,'NumberTitle','off');
            % set location vectors
            Xvect = [0.0+1e-10; 0.0];
            Yvect = [0.0+1e-10, AG+2*ASP+AP];
            Zvect = [AG+2*ASP+AP, 0.0];
            % set subplot titles
            % ltitle = {'Pole Normal','Equator Normal'};
            % rtitle = {'Pole Perspective','Equator Perspective'};
            for i=1:1
                %% define colloid location and collector location for pole location
                % se collector center
                Xm0 = 0.0; Ym0=0.0; Zm0=0.0;
                % colloid center (at equator)
                X=Xvect(i); Y=Yvect(i); Z =Zvect(i);
                % CALCULATE RADIAL DISTANCE
                R = ((X-Xm0)*(X-Xm0)+(Y-Ym0)*(Y-Ym0)+(Z-Zm0)*(Z-Zm0))^0.5;
                H = R-AG-AP;
                % adjust resolution based on smallest roughness respresented
                minasp = 3.3333e-09/2; %minimum asperity size represented in visualization
                if ASP <  minasp
                    res = 2*minasp;
                else
                    res = 2*ASP;
                end
                % compare ACONTMAX vs. RLEV to determine the largest area to render
                % surfaces in the zoom-in visualization.
                % calculate the number of asperities that match area to render
                %         if ACONTMAX>=RLEV
                %             if 40*res>=ACONTMAX
                %                 numasp=40;
                %             elseif ceil(ACONTMAX/res)>80
                %                 numasp=80;
                %             else
                %                 numasp=ceil(ACONTMAX/res);
                %             end
                %         else
                %             if 5*res>=RLEV
                %                 numasp=5;
                %             elseif ceil(RLEV/res)>10
                %                 numasp=10;
                %             else
                %                 numasp=ceil(RLEV/res);
                %             end
                %         end
                % set sphere cap number of nodes as a factor of asperity height
                % (3.3333e-09 m, minimum ASP)
                % set surface domain size as a fractio of AP
                fsurf = 0.35;
                % calculate number of asperities in surface domain
                numasp = ceil(fsurf*AP/res);
                if numasp<10
                    numasp=10;
                    fsurf = numasp*res/(AP);
                    if fsurf>=1
                        fsurf = 0.9;
                        numasp = ceil(fsurf*AP/res);
                    end
                end
                % assing surface element resolution depending on RMODE
                if RMODE==0  % RMODE = 0: Smooth collector and colloid
                    numaspAP = 100;
                    numaspAG = 100;
                end
                if RMODE==1  % RMODE = 1: Smooth collector rough colloid
                    numaspAP = numasp;
                    numaspAG = 100;
                end
                if RMODE==2  % RMODE = 2: Smooth colloid rough collector
                    numaspAP = 100;
                    numaspAG = numasp;
                end
                if RMODE==3  % RMODE = 3: Rough collector and colloid
                    numaspAP = numasp;
                    numaspAG = numasp;
                end
                %% plot geometry
                % draw wire sphere for colloid as reference
                [xw,yw,zw]=sphere(200);
                xw=xw*AP+X; yw=yw*AP+Y; zw=zw*AP+Z;
                % draw roughness only if its scale exceeds a factor of res TBD
                [xcap,ycap,zcap,xs,ys,zs,thetap,phip,rxys,unitx,unity,unitz]=AFMsphere_cap_OLD(X,Y,Z,AP,AG,fsurf,numaspAG,res);
                %  Generate dummy collector hetdomains center and radii for diagnostics
                %[xhetv,yhetv,zhetv,rhetv] = dummyhetcap(xcap,ycap,zcap,nhet0,nhet1,nhet2,rhet0,rhet1,rhet2);
                % create colloid and collector
                [xc,yc,zc]= sphere;
                % scale spheres and translate
                xc=AG*xc; yc=AG*yc; zc=AG*zc;
                % calculate center of plot
                xpl = unitx*(AG+H/2);
                ypl = unity*(AG+H/2);
                zpl = unitz*(AG+H/2);
                % set factor to axis limits around center of plot (as a factor of AP)
                axsc = fsurf/3;
                % zoom in figure
                azoff = -5;
                az1 = (phip/2/pi*360+90)+90+azoff;
                el1 = 0.0;
                %% generate roughness on the colloid
                % use same sphere cap function to generate cap on colloid surface.
                % note that AP and AG order are swap to accomplish this objective
                [xcapcol,ycapcol,zcapcol,xscol,yscol,zscol,thetapcol,phipcol,rxyscol,unitxcol,unitycol,unitzcol]=AFMsphere_cap_OLD(-X,-Y,-Z,AG,AP,fsurf,numaspAP,res);
                % translate cap relative to colloid center.
                xcapcol=xcapcol+X;
                ycapcol=ycapcol+Y;
                zcapcol=zcapcol+Z;
                %% generate hetdomains on colloid
                % Generate dummy colloid hetdomains center and radii for diagnostics
                %[xhetvcol,yhetvcol,zhetvcol,rhetvcol] = dummyhetcap(xcapcol,ycapcol,zcapcol,nhet0,nhet1,0.0,rhet0,rhet1,rhet2);
                %% plot
                % plot geometry
                figure(fDcontact)
                subplot(2,2,1+(i-1)*2)
                plot3(xpl,ypl,zpl,'ob')
                title ('Rendering contact figure...')
                drawnow
                axis([-axsc*AP+xpl axsc*AP+xpl -axsc*AP+ypl axsc*AP+ypl -axsc*AP+zpl axsc*AP+zpl])
                axis square
                view(az1,el1)
                hold on
                if RMODE==1||RMODE==3
                    % draw wired sphere to represent smooth colloid surface if
                    % roughness is present on colloid
                    surf(xw,yw,zw,'FaceColor','none','EdgeColor',colcolor)
                end
                xlabel('X'); ylabel('Y'); zlabel('Z');
                subp=[2,2,1+(i-1)*2];
                % generate heterogeneity in the colloid the attachment region
                if SCOVP>0.0
                    %   HETEROGENEITY ON COLLOID (HETP) SUBROUTINE TO DETERMINE HETERODOMAIN PROJECTIONS
                    %   HETP PROJECTIONS WILL BE GENERATED ASSUMING THAT THE COLLOID CENTER COINCIDES WITH THE HAPPEL SPHERE CENTER
                    %   MHETP CONTAINS HETP COORDINATES AND RADII(FORMAT: [XHETP YHETP ZHET RHETP])
                    %   MPRO CONTAINS HETP PROJECTION COORDINATES AND RADII(FORMAT: [XPRO YPRO ZPRO RPRO])
                    [MHETP, MPRO]=AFMHETTRACKP(Xm0,Ym0,Zm0,H,RZOIBULK,AP,HETMODEP,SCOVP,RHETP0,RHETP1);
                    %   HETP WILL BE TRANSLATED TO THE COLLECTOR FRAME OF REFERENCE AND
                    %   ROTATED BASED ON COLLOID SPHERICAL COORDINATES, FOR FRONT-END PLOTTING
                    %   HETP PROJECTIONS WILL BE TRANSLATED AND ROTATED TO THE FRAME OF REFERENCE WITH X-Y PLANE MATCHING
                    %   THE PLANE PARALLEL TO THE HAPPEL SPHERE AT THE POINT OF PROJECTION OF THE COLLOID CENTER, TO CALCULATE AFRACT
                    [MHETP_PLOT,MPRO_AF] = AFMHETP_TRANSFORM(X,Y,Z,XG,YG,ZG,THETA,PHI,MHETP,MPRO);
                else
                    MHETP_PLOT(:,1) = 0.0;
                    MHETP_PLOT(:,2) = 0.0;
                    MHETP_PLOT(:,3) = 0.0;
                    MHETP_PLOT(:,4) = 0.0;
                end
                % paint hetdomains on collextor
                %% drawn roughness hemispheres on collector (colflag=1)
                colflag = 1;
                % if no heterogeneity present in the collector
                if SCOV==0
                    XHET = 0.0; YHET = 0.0; ZHET = 0.0; RHET = 0.0;
                else
                    [XHET,YHET,ZHET,RHET]=AFMHETTRACK(X,Y,Z,Xm0,Ym0,Zm0,AG,HETMODE,SCOV,RHET0,RHET1,RHET2,1);
                    
                end
                AFMroughspheresHet(@AFMhetpaint,@AFMcolloidHetPaint,fDcontact,subp,xcap,ycap,zcap,XHET,YHET,ZHET,RHET,xs,ys,zs,X,Y,Z,AG,AP,res,numaspAG,colflag,RMODE)
                %roughspheresHet(fD,subp,xcap,ycap,zcap,XHET,YHET,ZHET,RHET,xs,ys,zs,X,Y,Z,AG,AP,res,numasp,colflag,RMODE)
                %% drawn roughness hemispheres on colloid (colflag=2)
                colflag = 2;
                AFMroughspheresHet(@AFMhetpaint,@AFMcolloidHetPaint,fDcontact,subp,xcapcol,ycapcol,zcapcol,MHETP_PLOT(:,1),MHETP_PLOT(:,2),MHETP_PLOT(:,3),MHETP_PLOT(:,4),xscol,yscol,zscol,X,Y,Z,AG,AP,res,numaspAP,colflag,RMODE)
                %           roughspheresHet(fD,subp,xcapcol,ycapcol,zcapcol,XHETP,YHETP,ZHETP,RHETP,xs,ys,zs,X,Y,Z,AG,AP,res,numasp,colflag,RMODE)
                %%
                % plot center of cap from angles
                z1=cos(thetap)*AG;
                x1=cos(phip)*rxys;
                y1=sin(phip)*rxys;
                %%
                subplot(2,2,2+(i-1)*2)
                surf(xc,yc,zc,'FaceColor','none')
                drawnow
                hold on
                plot3(X,Y,Z,'ok','MarkerFaceColor',colcolor,'MarkerSize',10)
                axis ([-1.5*AG 1.5*AG -1.5*AG 1.5*AG -1.5*AG 1.5*AG])
                axis square
                %% generate colloid ring and zoi to visualizae colloid border and zoi
                [xcirc,ycirc,zcirc,xzoi,yzoi,zzoi]=AFMcolloid_circle(X,Y,Z,AP,AG,RZOIBULK);
                figure(fDcontact)
                subplot(2,2,1+(i-1)*2)
                %         plot3(xcirc,ycirc,zcirc,'.','MarkerEdgeColor',colcolor)
                plot3(xzoi,yzoi,zzoi,'.','MarkerEdgeColor',colcolor,'MarkerSize',10)
                %% hold off plots
                figure(fDcontact)
                title (' ')
                drawnow
                subplot(2,2,1+(i-1)*2)
                hold off
                subplot(2,2,2+(i-1)*2)
                hold off
                %%
            end

        end

        % Button pushed function: setdirPB
        function setdirPBButtonPushed(app, event)
            global dname
            %% Create OUTPUT directory to write output profile, and sim parameters
            % %  create string from output directory
            % OUTDIR=get(handles.outputname,'String');
            OUTDIR='Output_AFM';
            outs = strcat('\',OUTDIR,'\');
            % % set working directory
            if ~isdeployed
                % MATLAB environment
                mname = mfilename('fullpath');
                outputpath = strrep(mname,'\Parti_Suite_AFM','\')
                %     mkdir(outputpath,OUTDIR);
                %     outputdir = strrep(mfilename('fullpath'),'\GUIxDLVO',outs);
            else
                %deployed application
                outputpath=pwd
                %     mkdir(outputpath,OUTDIR);
                %     outputdir=strcat(outputpath,outs);
            end
            dname = uigetdir(outputpath,'Select Output Directory');
            % enable save button
            set(app.savePB,'Enable','on')
        end

        % Button pushed function: savePB
        function savePBPushed(app, event)
            % parameters
            global NPART RLIM
            global AP RHOP
            global RHOW VISC ER T
            global IS ZI ZETAPST ZETACST
            global ZETAHET HETMODE RHET1 RHET0 RHET2 SCOV
            global ZETAHETP HETMODEP RHETP1 RHETP0 SCOVP
            global B RMODE ASP
            global A132 LAMBDAVDW VDWMODE
            global GAMMA0AB LAMBDAAB GAMMA0STE LAMBDASTE
            global A11 AC1C1 A22 AC2C2 A33 T1 T2
            global KINT W132 BETA
            % histogram data
            global barFdisc priFdisc HbarFdisc HpriFdisc
            global LAT1barFdisc LAT2barFdisc LAT1priFdisc LAT2priFdisc
        
            % directory name
            global dname
            % global variables to write
            global HVECTOR HOT FCOLLOT FVDWOT FEDLOT FABOT FSTEOT FBORNOT
            global LAT1V LAT2V AFRACTOT
            global mxhetPLOT  myhetPLOT mrhetOUT
            global Ybar Ypri centersBar centersPri
            % start waitbar
            h = waitbar(0,'Saving data to file');
            
            %% WRITE TO FILE
            filecount = 1;
            filename1 = strcat(dname,'\001_output_AFM.xlsx');
            % delete previous output file
            while exist(filename1, 'file')==2
                %   delete(filename1);
                filecount=filecount+1;
                strcount = strcat('00',num2str(filecount));
                filename1 = strcat(dname,'\',strcount,'_output_AFM.xlsx');
            end
            filename1
            %headers for output files
            % input parameters
            format101 =cell2table({'Locations_per_axis', 'Domain_length(m)', 'Probe_radius(m)',...
                         'Probe_density(kg/m3)','Fluid_density(kg/m3)','Fluid_viscosity(kg/m/s)',...
                         'Temperature(K)','Rel_permittivity(-)','Ionic_strength(mol/m3)',...
                         'Electrolyte_valence(-)','Domain_z_potential(V)','Probe_z_potential(V)'});
            format101data =cell2table({NPART,RLIM,AP,RHOP,RHOW,VISC,ER,T,IS,ZI,ZETAPST,ZETACST});         
            %
            format102 =cell2table({'Domain_hetdomain_z_potential(V)','Hetmode_domain(-)','Domain_large_hetdomain_radius(m)',... 
                        'Domain_medium_hetdomain_radius(m)','Domain_small_hetdomain_radius(m)','Domain_fractional_surface_coverage(-)',...
                        'Probe_hetdomain_z_potential(V)','Hetmode_probe(-)','Probe_large_hetdomain_radius(m)',... 
                        'Probe_small_hetdomain_radius(m)','Probe_fractional_surface_coverage(-)',...
                        'Combined_Hamaker_constant(J)','van_der_Waals_decay_length(m)','van_der_Waals_mode(-)',...
                        'Slip_length(m)','Roughness_mode(-)','Asperity_height(m)'});
            format102data =cell2table({ZETAHET,HETMODE,RHET1,RHET0,RHET2,SCOV,ZETAHETP,HETMODEP,RHETP1,RHETP0,SCOVP,...
                            A132,LAMBDAVDW,VDWMODE,B,RMODE,ASP});
            %
            format103 =cell2table({'Probe_Hamaker_constant(J)','Probe_Coating_Hamaker_constant(J)','Domain_Hamaker_constant(J)',...
                        'Domain_coating_Hamaker_constant(J)','Fluid_Hamaker_constant(J)','Probe_Coating_thickness(m)','Domain_Coating_thickness(m)',...
                        'Acid_base_energy_per_area(J/m2)','Acid_base_decay_length(m)','Steric_energy_per_area(J/m2)','Steric_decay_length(m)',...
                        'Combined_elastic_modulus(N/m2)','Work_of_adhesion(J/m2)','Contact_radius_factor(-)'});
            format103data =cell2table({A11,AC1C1,A22,AC2C2,A33,T1,T2,GAMMA0AB,LAMBDAAB,GAMMA0STE,LAMBDASTE,KINT,W132,BETA});
           %
            
%             col_headerE={'H(m)','E_van_der_Waals','E_EDL','E_AB','E_Born','E_Steric','E_total'};
%             col_headerF={'H(m)','F_van_der_Waals','F_EDL','F_AB','F_Born','F_Steric','F_total'}; %Row cell array (for column labels)
%             col_headerEhet={'H(m)','AFRACT_0.25_ZOI','AFRACT_0.5_ZOI','AFRACT_0.75_ZOI','AFRACT_1.0_ZOI'};
%             col_headerFhet={'H(m)','AFRACT_0.25_ZOI','AFRACT_0.5_ZOI','AFRACT_0.75_ZOI','AFRACT_1.0_ZOI'};
%             % headers with hetdomain radii
%             col_headersRHETV={'Rhet_radii(m)',num2str(rhetv(1)),num2str(rhetv(2)),num2str(rhetv(3)),num2str(rhetv(4))};
%             %% parameter values
%             par_cell=fpar_out_xDLVO();
            waitbar(0.1,h) % show saving progress
            
            %%  write parameters and data
            exvar = 0; % not use Excel
            % Parameters
            sheet_name = 'Parameters';
            writetable(format101,filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)  
            writetable(format101data,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)  
            writetable(format102,filename1,'Sheet',sheet_name,'Range','A3','WriteVariableNames',0,'UseExcel',exvar)  
            writetable(format102data,filename1,'Sheet',sheet_name,'Range','A4','WriteVariableNames',0,'UseExcel',exvar)  
            writetable(format103,filename1,'Sheet',sheet_name,'Range','A5','WriteVariableNames',0,'UseExcel',exvar)  
            writetable(format103data,filename1,'Sheet',sheet_name,'Range','A6','WriteVariableNames',0,'UseExcel',exvar)  
            writetable(cell2table({'X_probe_location(m)'}),filename1,'Sheet',sheet_name,'Range','A7','WriteVariableNames',0,'UseExcel',exvar)  
            writetable(cell2table({LAT1V}),filename1,'Sheet',sheet_name,'Range','B7','WriteVariableNames',0,'UseExcel',exvar)     
            writetable(cell2table({'Y_probe_location(m)'}),filename1,'Sheet',sheet_name,'Range','A8','WriteVariableNames',0,'UseExcel',exvar)  
            writetable(cell2table({LAT2V}),filename1,'Sheet',sheet_name,'Range','B8','WriteVariableNames',0,'UseExcel',exvar)     
            %%  FCOLLOT
            %% write data
            % create table
            sheet_name = 'FCOLL(N)';
            tab1 = table(HVECTOR',FCOLLOT);   
            writetable(cell2table({'H(m)','FCollTotal(N)_each_column_represents_one_probe_location'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)                  
            waitbar(1/10,h) % show saving progress
            %
            %%  FVDWOT
            %% write data
            sheet_name = 'FVDW(N)';
            tab1 = table(HVECTOR',FVDWOT);
            writetable(cell2table({'H(m)','FvdW(N)_each_column_represents_one_probe_location'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(2/10,h) % show saving progress
            %  
            %%  FEDLOT
            %% write data
            sheet_name = 'FEDL(N)';
            tab1 = table(HVECTOR',FEDLOT);
            writetable(cell2table({'H(m)','Fedl(N)_each_column_represents_one_probe_location'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(3/10,h) % show saving progress            
            %               
            %%  FABOT
            %% write data
            sheet_name = 'FAB(N)';
            tab1 = table(HVECTOR',FABOT);  
            writetable(cell2table({'H(m)','Facid_base(N)_each_column_represents_one_probe_location'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(4/10,h) % show saving progress               
            % 
            %%  FSTEOT
            %% write data
            sheet_name = 'FSTE(N)';
            tab1 = table(HVECTOR',FSTEOT);    
            writetable(cell2table({'H(m)','Fsteric(N)_each_column_represents_one_probe_location'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(5/10,h) % show saving progress    
            %    
            %%  FBORNOT
            %% write data
            sheet_name = 'FBORN(N)';
            tab1 = table(HVECTOR',FBORNOT);
            writetable(cell2table({'H(m)','FBorn(N)_each_column_represents_one_probe_location'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(6/10,h) % show saving progress                
            %       
            %% Hist
            sheet_name = 'Hist';
            tab1 = table(centersBar',Ybar',centersPri',Ypri');   
            writetable(cell2table({'barrier_Force(N)','Frecuency(#)','minimum_Force(N)','Frecuency(#)'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(7/10,h) % show saving progress  
            %
            %% Raw output for barrier and miminum
            sheet_name = 'raw_Bar_Min_data';
            tab1 = table(barFdisc',HbarFdisc',LAT1barFdisc',LAT2barFdisc',priFdisc',HpriFdisc',LAT1priFdisc',LAT2priFdisc');   
            writetable(cell2table({'barrier_Force(N)','separation_distance(m)','LAT1_location(m)','LAT2_location(m)','minimum_Force(N)','separation_distance(m)','LAT1_location(m)','LAT2_location(m)'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(7.5/10,h) % show saving progress              
            % hetdomain locations
            % mxhetPLOT
            sheet_name = 'hetXloc';
            tab1 = table(mxhetPLOT);   
            writetable(cell2table({'Domain_Heterodomain_X_locations(m)'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(8/10,h) % show saving progress  
            %
            % myhetPLOT
            sheet_name = 'hetYloc';
            tab1 = table(myhetPLOT);    
            writetable(cell2table({'Domain_Heterodomain_Y_locations(m)'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(9/10,h) % show saving progress              
            %
            % mrhetOUT
            sheet_name = 'hetRadii';
            tab1 = table(mrhetOUT);   
            writetable(cell2table({'Domain_Heterodomain_radii(m)'}),filename1,'Sheet',sheet_name,'Range','A1','WriteVariableNames',0,'UseExcel',exvar)   
            writetable(tab1,filename1,'Sheet',sheet_name,'Range','A2','WriteVariableNames',0,'UseExcel',exvar)               
            waitbar(10/10,h) % show saving progress             
            %
            disp('Output Complete');
            close(h)
        end

        % Value changed function: vdwmode
        function vdwmodeValueChanged(app, event)
            value = app.vdwmode.Value;
            value = str2double(value);
            if value>1
               app.vdw_coated_panel.Visible='on';
            else
               app.vdw_coated_panel.Visible='off';
            end
            %vdw_coated_panel
        end

        % Value changed function: rmode
        function rmodeValueChanged(app, event)
            value = app.rmode.Value;
            value = str2double(value);
            value = floor(value);
            if value<=0.0||value>3.0
               app.rmode.Value = '0';
               value = 0;
               app.aspcolloid.Enable = 'off';     
               app.aspdomain.Enable = 'off'; 
               app.rmode.Value = '0';
               app.aspcolloid.Value = '0';
               app.aspdomain.Value = '0';
            end 
            if value==1||value==3
                app.aspcolloid.Enable = 'on';
                app.rmode.Value = num2str(value);
            else
                app.aspcolloid.Enable = 'off';   
            end
             if value==2||value==3
                app.aspdomain.Enable = 'on';
                app.rmode.Value = num2str(value);
            else
                app.aspdomain.Enable = 'off';   
            end           
            % checkk if roughness and surface heterogeniety are
            % combined
            valuer = app.rmode.Value;
            valuer = str2double(valuer);
            valuer = floor(valuer);
            values = app.scov.Value;
            values = str2double(values);
            if valuer>0&&values>0.0
                warndlg('For combined roughness and charge heterogeneity impacts: Charge heterogeneity impacts are accounted for by the faction of the ZOI occupied by heterodomains (AFRACT) on the equivalent smooth surfaces.  The corresponding smooth surface forces are calculated with an offset H2 relative to the actual distance H between each asperity and the other surface (or between asperity pairs for the case where both surfaces have roughness).  The force contributions from asperities is calculated assuming that each asperity has the reported AFRACT such that each asperity contributes attractive (AFRACT) and repulsive (1-AFRACT) interactions.  The approach is a superposition that does not account for the location of the heterogeneity on the asperities. ')
            end
            

        end

        % Button pushed function: heat_hist_PB
        function heat_hist_PBButtonPushed(app, event)
        % pass boundary separation distance values from GUI
            global hpridisc hbardisc1 hbardisc2
            hpridisc = str2double(app.hpridisc.Value);
            hbardisc1 = str2double(app.hbardisc1.Value);
            hbardisc2 = str2double(app.hbardisc2.Value);
            AFM_heat_hist()
            % enable output button
            set(app.setdirPB,'Enable','on')
        end

        % Value changed function: hetmode
        function hetmodeValueChanged(app, event)
            global HETMODE
            HETMODE = str2double(app.hetmode.Value);
            % error catching
            if HETMODE<1||isnan(HETMODE)
                HETMODE = 1;
                app.hetmode.Value = '1';
            else
                if sum(HETMODE==[1 5 9 73])==0
                    HETMODE = 1;
                    app.hetmode.Value = '1';
                end
            end
            if HETMODE==5||HETMODE==9
                app.rhet0.Enable='on';
                app.rhet1.Enable='on';
                app.rhet2.Enable='off';
                app.zetahet.Enable='on';
            elseif HETMODE==73
                app.rhet0.Enable='on';
                app.rhet1.Enable='on';
                app.rhet2.Enable='on';
                app.zetahet.Enable='on';
            elseif HETMODE==1
                app.rhet0.Enable='on';
                app.rhet1.Enable='off';
                app.rhet2.Enable='off';
                app.zetahet.Enable='on';
            end


        end

        % Value changed function: scov
        function scovValueChanged(app, event)
            global SCOV
            SCOV = str2double(app.scov.Value);
            disp(SCOV)
             % checkk if roughness and surface heterogeniety are
            % combined
            valuer = app.rmode.Value;
            valuer = str2double(valuer);
            valuer = floor(valuer);
            values = app.scov.Value;
            values = str2double(values);
            if valuer>0&&values>0.0
                warndlg('For combined roughness and charge heterogeneity impacts: Charge heterogeneity impacts are accounted for by the faction of the ZOI occupied by heterodomains (AFRACT) on the equivalent smooth surfaces.  The corresponding smooth surface forces are calculated with an offset H2 relative to the actual distance H between each asperity and the other surface (or between asperity pairs for the case where both surfaces have roughness).  The force contributions from asperities is calculated assuming that each asperity has the reported AFRACT such that each asperity contributes attractive (AFRACT) and repulsive (1-AFRACT) interactions.  The approach is a superposition that does not account for the location of the heterogeneity on the asperities. ')
            end


            if SCOV <= 0.0||isnan(SCOV)||SCOV>1.0
                SCOV = 0.0;
                app.scov.Value = '0.0';
            else
                app.zetahet.Enable = 'on';
                app.hetmode.Enable = 'on';
                app.rhet0.Enable='on';
                AFM_hetdomain_info()
                
            end


            if SCOV==0.0 
                app.zetahet.Enable = 'off';
                app.hetmode.Enable='off';
                app.rhet0.Enable='off';
                app.rhet1.Enable='off';
                app.rhet2.Enable='off';
            end


        end

        % Value changed function: scovp
        function scovpValueChanged(app, event)
            global SCOVP
            SCOVP = str2double(app.scovp.Value);
            disp(SCOVP)
            if SCOVP <= 0.0||isnan(SCOVP)||SCOVP>1.0
                SCOVP = 0.0;
                app.scovp.Value = '0.0';
            else
                app.zetahetp.Enable = 'on';
                app.hetmodep.Enable = 'on';
                app.rhetp0.Enable='on';
                AFM_hetdomain_info()
                
            end
            if SCOVP==0.0 
                app.zetahetp.Enable = 'off';
                app.hetmodep.Enable='off';
                app.rhetp0.Enable='off';
                app.rhetp1.Enable='off';
                %app.rhet2.Enable='off';
            end


        end

        % Value changed function: hetmodep
        function hetmodepValueChanged(app, event)
            global HETMODEP
            HETMODEP = str2double(app.hetmodep.Value);
            % error catching
            if HETMODEP<1||isnan(HETMODEP)
                HETMODEP = 1;
                app.hetmodep.Value = '1';
            else
                if sum(HETMODEP==[1 5])==0
                    HETMODEP = 1;
                    app.hetmodep.Value = '1';
                end
            end
            if HETMODEP==5
                app.rhetp0.Enable='on';
                app.rhetp1.Enable='on';
                app.zetahetp.Enable='on';
            elseif HETMODEP==1
                app.rhetp0.Enable='on';
                app.rhetp1.Enable='off';
                app.zetahetp.Enable='on';
            end           
        end

        % Image clicked function: Image
        function ImageClicked(app, event)

        end

        % Value changed function: aspcolloid
        function aspcolloidValueChanged(app, event)
            global ASPcolloid
            ASPcolloid = app.aspcolloid.Value;
            ASPcolloid = str2double(ASPcolloid);
            if ASPcolloid<1e-8
                warndlg('Asperiy roughness <= 1e-8 (m) will require significantly longer calculation times')
            end
        end

        % Value changed function: aspdomain
        function aspdomainValueChanged(app, event)
            global ASPdomain
            ASPdomain = app.aspdomain.Value;
            ASPdomain = str2double(ASPdomain);
            if ASPdomain<1e-8
                warndlg('Asperiy roughness <= 1e-8 (m) will require significantly longer calculation times')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Roughness_parameters = uipanel(app.UIFigure);
            app.Roughness_parameters.Title = 'Roughness parameters';
            app.SliplengthmLabel = uilabel(app.Roughness_parameters);
            app.SliplengthmLabel.Text = 'Slip length (m)';
            app.b = uieditfield(app.Roughness_parameters, 'text');
            app.b.Enable = 'off';
            app.b.Value = '0.0';
            app.Roughnessmode012or3Label = uilabel(app.Roughness_parameters);
            app.Roughnessmode012or3Label.Text = 'Roughness mode(0,1,2 or 3)';
            app.rmode = uieditfield(app.Roughness_parameters, 'text');
            app.rmode.ValueChangedFcn = createCallbackFcn(app, @rmodeValueChanged, true);
            app.rmode.Tooltip = {'RMODE values:'; '0 smooth surfaces'; '1 rough colloid only'; '2 rough domain only'; '3 rough colloid and rough domain'};
            app.rmode.Value = '0';
            app.AsperityheightmModes123Label = uilabel(app.Roughness_parameters);
            app.AsperityheightmModes123Label.Text = 'Colloid';
            app.aspcolloid = uieditfield(app.Roughness_parameters, 'text');
            app.aspcolloid.ValueChangedFcn = createCallbackFcn(app, @aspcolloidValueChanged, true);
            app.aspcolloid.Enable = 'off';
            app.aspcolloid.Tooltip = {'lower limit: 1e-9 (m)'; 'upper limit: colloidal probe radius'};
            app.aspcolloid.Value = '0.0';
            app.DomainEditFieldLabel = uilabel(app.Roughness_parameters);
            app.DomainEditFieldLabel.Text = 'Domain';
            app.aspdomain = uieditfield(app.Roughness_parameters, 'text');
            app.aspdomain.ValueChangedFcn = createCallbackFcn(app, @aspdomainValueChanged, true);
            app.aspdomain.Enable = 'off';
            app.aspdomain.Tooltip = {'lower limit: 1e-9 (m)'};
            app.aspdomain.Value = '0.0';
            app.Colloidal_probe_physical_properties = uipanel(app.UIFigure);
            app.Colloidal_probe_physical_properties.Title = 'Colloidal probe physical properties';
            app.RadiusmEditFieldLabel = uilabel(app.Colloidal_probe_physical_properties);
            app.RadiusmEditFieldLabel.Text = 'Radius (m)';
            app.ap = uieditfield(app.Colloidal_probe_physical_properties, 'text');
            app.ap.Tooltip = {'probe size lower limit of 5.0e-7 (m). Consistent with AFM practice'};
            app.ap.Value = '5.0e-7';
            app.Densitykgm3EditFieldLabel = uilabel(app.Colloidal_probe_physical_properties);
            app.Densitykgm3EditFieldLabel.Tooltip = {''};
            app.Densitykgm3EditFieldLabel.Text = 'Density (kg/m3)';
            app.rhop = uieditfield(app.Colloidal_probe_physical_properties, 'text');
            app.rhop.Tooltip = {'Probe density (kg/m3)'; ''};
            app.rhop.Value = '1050.0';
            app.Fluid_physical_properties = uipanel(app.UIFigure);
            app.Fluid_physical_properties.Title = 'Fluid physical properties (default = CML-water-silica @ pH 6.7, IS 6mM)';
            app.Densitykgm3EditField_2Label = uilabel(app.Fluid_physical_properties);
            app.Densitykgm3EditField_2Label.Text = 'Density (kg/m3)';
            app.rhow = uieditfield(app.Fluid_physical_properties, 'text');
            app.rhow.Value = '998.0';
            app.ViscositykgmsEditFieldLabel = uilabel(app.Fluid_physical_properties);
            app.ViscositykgmsEditFieldLabel.Text = 'Viscosity (kg/m/s)';
            app.visc = uieditfield(app.Fluid_physical_properties, 'text');
            app.visc.Value = '9.98e-4';
            app.RelpermittivityEditFieldLabel = uilabel(app.Fluid_physical_properties);
            app.RelpermittivityEditFieldLabel.Text = 'Rel. permittivity (-)';
            app.er = uieditfield(app.Fluid_physical_properties, 'text');
            app.er.Value = '80.0';
            app.TemperatureKLabel = uilabel(app.Fluid_physical_properties);
            app.TemperatureKLabel.Text = 'Temperature (K)';
            app.T = uieditfield(app.Fluid_physical_properties, 'text');
            app.T.Value = '293.2';
            app.Force_volume_domain = uipanel(app.UIFigure);
            app.Force_volume_domain.Title = 'Force volume domain';
            app.locationsperaxisLabel = uilabel(app.Force_volume_domain);
            app.locationsperaxisLabel.Text = '# locations per axis';
            app.nloc = uieditfield(app.Force_volume_domain, 'text');
            app.nloc.Value = '20';
            app.DomainlengthmLabel = uilabel(app.Force_volume_domain);
            app.DomainlengthmLabel.Text = 'Domain length (m)';
            app.adom = uieditfield(app.Force_volume_domain, 'text');
            app.adom.Tooltip = {'Spherical domain is approximated as a flat domain using a sphere a factor of 1e9 times larger than the spherical colloidal probe'};
            app.adom.Value = '5.0e-6';
            app.PartiSuiteAFMForceVolumeSimulatorLabel = uilabel(app.UIFigure);
            app.PartiSuiteAFMForceVolumeSimulatorLabel.Text = 'Parti-Suite AFM Force-Volume Simulator';
            app.Domain_surface_heterogeneity_parameters = uipanel(app.UIFigure);
            app.Domain_surface_heterogeneity_parameters.Title = 'Domain surface heterogeneity parameters';
            app.HetdomainzpotentialVEditFieldLabel = uilabel(app.Domain_surface_heterogeneity_parameters);
            app.HetdomainzpotentialVEditFieldLabel.Text = 'Hetdomain z-potential (V)';
            app.zetahet = uieditfield(app.Domain_surface_heterogeneity_parameters, 'text');
            app.zetahet.Enable = 'off';
            app.zetahet.Value = '0.065';
            app.Hetmode15973EditFieldLabel = uilabel(app.Domain_surface_heterogeneity_parameters);
            app.Hetmode15973EditFieldLabel.Text = 'Hetmode1,5,9,73';
            app.hetmode = uieditfield(app.Domain_surface_heterogeneity_parameters, 'text');
            app.hetmode.ValueChangedFcn = createCallbackFcn(app, @hetmodeValueChanged, true);
            app.hetmode.Enable = 'off';
            app.hetmode.Value = '1';
            app.MediumhetdomainradiusmEditFieldLabel = uilabel(app.Domain_surface_heterogeneity_parameters);
            app.MediumhetdomainradiusmEditFieldLabel.Text = 'Medium hetdomain radius (m)';
            app.rhet1 = uieditfield(app.Domain_surface_heterogeneity_parameters, 'text');
            app.rhet1.Enable = 'off';
            app.rhet1.Value = '0.0';
            app.LargehetdomainradiusmEditFieldLabel = uilabel(app.Domain_surface_heterogeneity_parameters);
            app.LargehetdomainradiusmEditFieldLabel.Text = 'Large hetdomain radius (m)';
            app.rhet0 = uieditfield(app.Domain_surface_heterogeneity_parameters, 'text');
            app.rhet0.Enable = 'off';
            app.rhet0.Value = '2e-7';
            app.SmallhetdomainradiusmEditFieldLabel = uilabel(app.Domain_surface_heterogeneity_parameters);
            app.SmallhetdomainradiusmEditFieldLabel.Text = 'Small hetdomain radius (m)';
            app.rhet2 = uieditfield(app.Domain_surface_heterogeneity_parameters, 'text');
            app.rhet2.Enable = 'off';
            app.rhet2.Value = '0.0';
            app.FractionalsurfacecoverageEditFieldLabel = uilabel(app.Domain_surface_heterogeneity_parameters);
            app.FractionalsurfacecoverageEditFieldLabel.Text = 'Fractional surface coverage (-)';
            app.scov = uieditfield(app.Domain_surface_heterogeneity_parameters, 'text');
            app.scov.ValueChangedFcn = createCallbackFcn(app, @scovValueChanged, true);
            app.scov.Value = '0.0';
            app.Colloidal_probe_surface_heterogeneity_parameters = uipanel(app.UIFigure);
            app.Colloidal_probe_surface_heterogeneity_parameters.Title = 'Colloidal probe surface heterogeneity parameters';
            app.HetdomainzpotentialVEditField_2Label = uilabel(app.Colloidal_probe_surface_heterogeneity_parameters);
            app.HetdomainzpotentialVEditField_2Label.Text = 'Hetdomain z-potential (V)';
            app.zetahetp = uieditfield(app.Colloidal_probe_surface_heterogeneity_parameters, 'text');
            app.zetahetp.Enable = 'off';
            app.zetahetp.Value = '0.065';
            app.Hetmode1or5EditFieldLabel = uilabel(app.Colloidal_probe_surface_heterogeneity_parameters);
            app.Hetmode1or5EditFieldLabel.Text = 'Hetmode (1 or 5)';
            app.hetmodep = uieditfield(app.Colloidal_probe_surface_heterogeneity_parameters, 'text');
            app.hetmodep.ValueChangedFcn = createCallbackFcn(app, @hetmodepValueChanged, true);
            app.hetmodep.Enable = 'off';
            app.hetmodep.Value = '1';
            app.LargehetdomainradiusmEditField_2Label = uilabel(app.Colloidal_probe_surface_heterogeneity_parameters);
            app.LargehetdomainradiusmEditField_2Label.Text = 'Large hetdomain radius (m)';
            app.rhetp0 = uieditfield(app.Colloidal_probe_surface_heterogeneity_parameters, 'text');
            app.rhetp0.Enable = 'off';
            app.rhetp0.Value = '0.0';
            app.SmallhetdomainradiusmEditField_2Label = uilabel(app.Colloidal_probe_surface_heterogeneity_parameters);
            app.SmallhetdomainradiusmEditField_2Label.Text = 'Small hetdomain radius (m)';
            app.rhetp1 = uieditfield(app.Colloidal_probe_surface_heterogeneity_parameters, 'text');
            app.rhetp1.Enable = 'off';
            app.rhetp1.Value = '0.0';
            app.FractionalsurfacecoverageEditField_2Label = uilabel(app.Colloidal_probe_surface_heterogeneity_parameters);
            app.FractionalsurfacecoverageEditField_2Label.Text = 'Fractional surface coverage (-)';
            app.scovp = uieditfield(app.Colloidal_probe_surface_heterogeneity_parameters, 'text');
            app.scovp.ValueChangedFcn = createCallbackFcn(app, @scovpValueChanged, true);
            app.scovp.Value = '0.0';
            app.van_der_Waals_force_parameters = uipanel(app.UIFigure);
            app.van_der_Waals_force_parameters.Title = 'van der Waals force parameters';
            app.CombinedHamakercstJLabel = uilabel(app.van_der_Waals_force_parameters);
            app.CombinedHamakercstJLabel.Text = 'Combined Hamaker cst. (J)';
            app.a132 = uieditfield(app.van_der_Waals_force_parameters, 'text');
            app.a132.Value = '7.18e-21';
            app.vanderWaalsdecaylengthmEditFieldLabel = uilabel(app.van_der_Waals_force_parameters);
            app.vanderWaalsdecaylengthmEditFieldLabel.Text = 'van der Waals decay length (m)';
            app.lambdavdw = uieditfield(app.van_der_Waals_force_parameters, 'text');
            app.lambdavdw.Value = '1.0e-7';
            app.vanderWaalsmode123or4EditFieldLabel = uilabel(app.van_der_Waals_force_parameters);
            app.vanderWaalsmode123or4EditFieldLabel.Enable = 'off';
            app.vanderWaalsmode123or4EditFieldLabel.Text = 'van der Waals mode (1, 2, 3 or 4)';
            app.vdwmode = uieditfield(app.van_der_Waals_force_parameters, 'text');
            app.vdwmode.ValueChangedFcn = createCallbackFcn(app, @vdwmodeValueChanged, true);
            app.vdwmode.Editable = 'off';
            app.vdwmode.Enable = 'off';
            app.vdwmode.Value = '1';
            app.vdw_coated_panel = uipanel(app.UIFigure);
            app.vdw_coated_panel.Title = 'van der Waals force parameters - Coated systems';
            app.vdw_coated_panel.Visible = 'off';
            app.ProbeHamakercstJLabel = uilabel(app.vdw_coated_panel);
            app.ProbeHamakercstJLabel.Text = 'Probe Hamaker cst. (J)';
            app.a11 = uieditfield(app.vdw_coated_panel, 'text');
            app.a11.Value = '0.0';
            app.ProbecoatingHcstJLabel = uilabel(app.vdw_coated_panel);
            app.ProbecoatingHcstJLabel.Text = 'Probe coating H. cst. (J)';
            app.ac1c1 = uieditfield(app.vdw_coated_panel, 'text');
            app.ac1c1.Value = '0.0';
            app.DomainHamakercstJLabel = uilabel(app.vdw_coated_panel);
            app.DomainHamakercstJLabel.Text = 'Domain Hamaker cst. (J)';
            app.a22 = uieditfield(app.vdw_coated_panel, 'text');
            app.a22.Value = '0.0';
            app.DomaincoatingHcstJLabel = uilabel(app.vdw_coated_panel);
            app.DomaincoatingHcstJLabel.Text = 'Domain coating H. cst. (J)';
            app.ac2c2 = uieditfield(app.vdw_coated_panel, 'text');
            app.ac2c2.Value = '0.0';
            app.FluidHamakercstJLabel = uilabel(app.vdw_coated_panel);
            app.FluidHamakercstJLabel.Text = 'Fluid Hamaker cst. (J)';
            app.a33 = uieditfield(app.vdw_coated_panel, 'text');
            app.a33.Value = '0.0';
            app.ProbecoatingthicknessmLabel = uilabel(app.vdw_coated_panel);
            app.ProbecoatingthicknessmLabel.Text = 'Probe coating thickness (m)';
            app.t1 = uieditfield(app.vdw_coated_panel, 'text');
            app.t1.Value = '0.0';
            app.DomaincoatingthicknessmLabel = uilabel(app.vdw_coated_panel);
            app.DomaincoatingthicknessmLabel.Text = 'Domain coating thickness (m)';
            app.t2 = uieditfield(app.vdw_coated_panel, 'text');
            app.t2.Value = '0.0';
            app.lewisab_and_steric_parameters = uipanel(app.UIFigure);
            app.lewisab_and_steric_parameters.Title = 'Lewis acid-base and steric hydration force parameters';
            app.AcidbaseenergyperareaJm2Label = uilabel(app.lewisab_and_steric_parameters);
            app.AcidbaseenergyperareaJm2Label.Text = 'Acid-base energy per area (J/m2)';
            app.gamma0ab = uieditfield(app.lewisab_and_steric_parameters, 'text');
            app.gamma0ab.Value = '-2.7e-2';
            app.AcidbasedecaylengthmLabel = uilabel(app.lewisab_and_steric_parameters);
            app.AcidbasedecaylengthmLabel.Text = 'Acid-base decay length (m)';
            app.lambdaab = uieditfield(app.lewisab_and_steric_parameters, 'text');
            app.lambdaab.Value = '6.0e-10';
            app.StericenergyperareaJm2Label = uilabel(app.lewisab_and_steric_parameters);
            app.StericenergyperareaJm2Label.Text = 'Steric energy per area (J/m2)';
            app.gamma0ste = uieditfield(app.lewisab_and_steric_parameters, 'text');
            app.gamma0ste.Value = '0.017';
            app.StericdecaylengthmLabel = uilabel(app.lewisab_and_steric_parameters);
            app.StericdecaylengthmLabel.Text = 'Steric decay length (m)';
            app.lambdaste = uieditfield(app.lewisab_and_steric_parameters, 'text');
            app.lambdaste.Value = '4.1e-10';
            app.Mean_field_potentials = uipanel(app.UIFigure);
            app.Mean_field_potentials.Title = 'Mean field potentials';
            app.Ionicstrengthmolm3Label = uilabel(app.Mean_field_potentials);
            app.Ionicstrengthmolm3Label.Text = 'Ionic strength (mol/m3)';
            app.is = uieditfield(app.Mean_field_potentials, 'text');
            app.is.Tooltip = {'working range from'; '1 to 100 mol/m3'};
            app.is.Value = '6.0';
            app.ElectrolytevalenceLabel = uilabel(app.Mean_field_potentials);
            app.ElectrolytevalenceLabel.Text = 'Electrolyte valence (-)';
            app.zi = uieditfield(app.Mean_field_potentials, 'text');
            app.zi.Value = '1.0';
            app.ProbezpotentialVLabel = uilabel(app.Mean_field_potentials);
            app.ProbezpotentialVLabel.Text = 'Probe z-potential (V)';
            app.zetapst = uieditfield(app.Mean_field_potentials, 'text');
            app.zetapst.Value = '-0.065';
            app.DomainzpotentialVLabel = uilabel(app.Mean_field_potentials);
            app.DomainzpotentialVLabel.Text = 'Domain z-potential (V)';
            app.zetacst = uieditfield(app.Mean_field_potentials, 'text');
            app.zetacst.Value = '-0.065';
            app.Calculation_panel = uipanel(app.UIFigure);
            app.Calculation_panel.Title = 'Actions';
            app.profPB = uibutton(app.Calculation_panel, 'push');
            app.profPB.ButtonPushedFcn = createCallbackFcn(app, @profPBPushed, true);
            app.profPB.Text = 'Simulate Force Profiles';
            app.hetPB = uibutton(app.Calculation_panel, 'push');
            app.hetPB.ButtonPushedFcn = createCallbackFcn(app, @hetPBButtonPushed, true);
            app.hetPB.Text = 'Draw Heterogeneity';
            app.setdirPB = uibutton(app.Calculation_panel, 'push');
            app.setdirPB.ButtonPushedFcn = createCallbackFcn(app, @setdirPBButtonPushed, true);
            app.setdirPB.Enable = 'off';
            app.setdirPB.Text = 'Set Output Directory';
            app.savePB = uibutton(app.Calculation_panel, 'push');
            app.savePB.ButtonPushedFcn = createCallbackFcn(app, @savePBPushed, true);
            app.savePB.Enable = 'off';
            app.savePB.Text = 'Save data to file';
            app.Deformation_parameters = uipanel(app.UIFigure);
            app.Deformation_parameters.Title = 'Deformation parameters';
            app.CombinedelasticmodulusNm2Label = uilabel(app.Deformation_parameters);
            app.CombinedelasticmodulusNm2Label.Text = 'Combined elastic modulus (N/m2)';
            app.kint = uieditfield(app.Deformation_parameters, 'text');
            app.kint.Value = '4.36e9';
            app.WorkofadhesionJm2Label = uilabel(app.Deformation_parameters);
            app.WorkofadhesionJm2Label.Text = 'Work of adhesion (J/m2)';
            app.w132 = uieditfield(app.Deformation_parameters, 'text');
            app.w132.Value = '-2.9e-2';
            app.ContactradiusfactorLabel = uilabel(app.Deformation_parameters);
            app.ContactradiusfactorLabel.Text = 'Contact radius factor (-)';
            app.beta = uieditfield(app.Deformation_parameters, 'text');
            app.beta.Value = '0.5';
            app.AuthorsPanel = uipanel(app.UIFigure);
            app.AuthorsPanel.Title = 'Authors';
            app.CodedevelopedbyLabel = uilabel(app.AuthorsPanel);
            app.CodedevelopedbyLabel.Text = 'Code developed by ';
            app.EddyPazmioandWilliamPJohnsonLabel = uilabel(app.AuthorsPanel);
            app.EddyPazmioandWilliamPJohnsonLabel.Text = 'Eddy Pazmiño** and William P. Johnson';
            app.ConceptbyLabel = uilabel(app.AuthorsPanel);
            app.ConceptbyLabel.Text = 'Concept by';
            app.WilliamPJohnsonandEddyPazmioLabel = uilabel(app.AuthorsPanel);
            app.WilliamPJohnsonandEddyPazmioLabel.Text = 'William P. Johnson* and Eddy Pazmiño**';
            app.wpjohnsongrouputaheduLabel = uilabel(app.AuthorsPanel);
            app.wpjohnsongrouputaheduLabel.Text = ' wpjohnsongroup.utah.edu';
            app.UniversityofUtahSaltLakeCityUtahUSALabel = uilabel(app.AuthorsPanel);
            app.UniversityofUtahSaltLakeCityUtahUSALabel.Text = '*University of Utah,Salt Lake City, Utah, USA';
            app.EscuelaPolitcnicaNacionalQuitoEcuadorLabel = uilabel(app.AuthorsPanel);
            app.EscuelaPolitcnicaNacionalQuitoEcuadorLabel.Text = '**Escuela Politécnica Nacional - Quito, Ecuador';
            app.v221Label = uilabel(app.AuthorsPanel);
            app.v221Label.Text = 'v 2.21';
            app.heat_hist_panel = uipanel(app.UIFigure);
            app.heat_hist_panel.Title = 'Separation distance limits';
            app.MaxSeparationdistmEditFieldLabel = uilabel(app.heat_hist_panel);
            app.MaxSeparationdistmEditFieldLabel.Text = 'Max. Separation dist. (m)';
            app.hpridisc = uieditfield(app.heat_hist_panel, 'text');
            app.hpridisc.Enable = 'off';
            app.PrimaryminimumLabel = uilabel(app.heat_hist_panel);
            app.PrimaryminimumLabel.Text = 'Primary minimum';
            app.BarrierLabel = uilabel(app.heat_hist_panel);
            app.BarrierLabel.Text = 'Barrier';
            app.MinSeparationdistmEditFieldLabel = uilabel(app.heat_hist_panel);
            app.MinSeparationdistmEditFieldLabel.Text = 'Min. Separation dist. (m)';
            app.hbardisc1 = uieditfield(app.heat_hist_panel, 'text');
            app.hbardisc1.Enable = 'off';
            app.MaxSeparationdistmEditField_3Label = uilabel(app.heat_hist_panel);
            app.MaxSeparationdistmEditField_3Label.Text = 'Max. Separation dist. (m)';
            app.hbardisc2 = uieditfield(app.heat_hist_panel, 'text');
            app.hbardisc2.Enable = 'off';
            app.heat_hist_PB = uibutton(app.heat_hist_panel, 'push');
            app.heat_hist_PB.ButtonPushedFcn = createCallbackFcn(app, @heat_hist_PBButtonPushed, true);
            app.heat_hist_PB.Enable = 'off';
            app.heat_hist_PB.Text = 'Generate heatmaps and histograms';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'F:\GdriveF\MATLAB current projects\AFM module\logoUtahEPNv2.jpg';
            app.AsperityradiusmLabel = uilabel(app.UIFigure);
            app.AsperityradiusmLabel.Text = 'Asperity radius (m)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Parti_Suite_AFM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end