classdef GUIxDLVO_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanelPLOTS     matlab.ui.container.Panel
        axesF            matlab.ui.control.UIAxes
        axesE            matlab.ui.control.UIAxes
        ppClosePlots     matlab.ui.control.Button
        sliderEx         matlab.ui.control.Slider
        text117          matlab.ui.control.Label
        Xemax            matlab.ui.control.EditField
        sliderFx         matlab.ui.control.Slider
        Xfmax            matlab.ui.control.EditField
        text121          matlab.ui.control.Label
        text122          matlab.ui.control.Label
        Yemax            matlab.ui.control.EditField
        text123          matlab.ui.control.Label
        Yemin            matlab.ui.control.EditField
        text124          matlab.ui.control.Label
        Yfmax            matlab.ui.control.EditField
        text125          matlab.ui.control.Label
        Yfmin            matlab.ui.control.EditField
        uipanel34        matlab.ui.container.Panel
        uipanel35        matlab.ui.container.Panel
        calculateButton  matlab.ui.control.Button
        cbHET            matlab.ui.control.CheckBox
        cbMEAN           matlab.ui.control.CheckBox
        text458          matlab.ui.control.Label
        zetahet          matlab.ui.control.EditField
        text127          matlab.ui.control.Label
        rZOI             matlab.ui.control.Label
        text129          matlab.ui.control.Label
        savebutton       matlab.ui.control.Button
        pb_setdir        matlab.ui.control.Button
        rhet02           matlab.ui.control.Label
        rhet04           matlab.ui.control.Label
        rhet06           matlab.ui.control.Label
        rhet08           matlab.ui.control.Label
        text133          matlab.ui.control.Label
        text134          matlab.ui.control.Label
        text135          matlab.ui.control.Label
        text136          matlab.ui.control.Label
        pbLOAD           matlab.ui.control.Button
        uipanel30        matlab.ui.container.Panel
        text109          matlab.ui.control.Label
        text110          matlab.ui.control.Label
        text111          matlab.ui.control.Label
        text112          matlab.ui.control.Label
        text113          matlab.ui.control.Label
        text114          matlab.ui.control.Label
        text48           matlab.ui.control.Label
        uipanelA132      matlab.ui.container.Panel
        ve               matlab.ui.control.EditField
        veTEXT           matlab.ui.control.Label
        e1               matlab.ui.control.EditField
        e1TEXT           matlab.ui.control.Label
        n1               matlab.ui.control.EditField
        n1TEXT           matlab.ui.control.Label
        e2               matlab.ui.control.EditField
        e2TEXT           matlab.ui.control.Label
        n2               matlab.ui.control.EditField
        n2TEXT           matlab.ui.control.Label
        e3               matlab.ui.control.EditField
        e3TEXT           matlab.ui.control.Label
        n3               matlab.ui.control.EditField
        n3TEXT           matlab.ui.control.Label
        A132calc         matlab.ui.control.EditField
        A132TEXT         matlab.ui.control.Label
        calcA132         matlab.ui.control.Button
        uipanelgammaAB   matlab.ui.container.Panel
        calcgammaAB      matlab.ui.control.Button
        gammaABTEXT      matlab.ui.control.Label
        gammaABcalc      matlab.ui.control.EditField
        g3negTEXT        matlab.ui.control.Label
        g3neg            matlab.ui.control.EditField
        g3posTEXT        matlab.ui.control.Label
        g3pos            matlab.ui.control.EditField
        g2negTEXT        matlab.ui.control.Label
        g2neg            matlab.ui.control.EditField
        g2posTEXT        matlab.ui.control.Label
        g2pos            matlab.ui.control.EditField
        g1negTEXT        matlab.ui.control.Label
        g1neg            matlab.ui.control.EditField
        g1posTEXT        matlab.ui.control.Label
        g1pos            matlab.ui.control.EditField
        uipanelW132      matlab.ui.container.Panel
        g1LW             matlab.ui.control.EditField
        g2LW             matlab.ui.control.EditField
        g2LWTEXT         matlab.ui.control.Label
        g3LW             matlab.ui.control.EditField
        g3LWTEXT         matlab.ui.control.Label
        W132calc         matlab.ui.control.EditField
        W132calcTEXT     matlab.ui.control.Label
        calcW132         matlab.ui.control.Button
        g1LWTEXT         matlab.ui.control.Label
        INDgammaAB       matlab.ui.control.EditField
        INDgammaABTEXT   matlab.ui.control.Label
        text47           matlab.ui.control.Label
        uipanelacont     matlab.ui.container.Panel
        CALCacont        matlab.ui.control.Button
        acontCALCTEXT    matlab.ui.control.Label
        acontCALC        matlab.ui.control.EditField
        KintTEXT         matlab.ui.control.Label
        Kint             matlab.ui.control.EditField
        INDW132TEXT      matlab.ui.control.Label
        INDW132          matlab.ui.control.EditField
        v2TEXT           matlab.ui.control.Label
        v2               matlab.ui.control.EditField
        E2TEXT           matlab.ui.control.Label
        E2               matlab.ui.control.EditField
        v1TEXT           matlab.ui.control.Label
        v1               matlab.ui.control.EditField
        E1TEXT           matlab.ui.control.Label
        E1               matlab.ui.control.EditField
        uipanel3         matlab.ui.container.Panel
        a1               matlab.ui.control.EditField
        text2            matlab.ui.control.Label
        a2               matlab.ui.control.EditField
        text4            matlab.ui.control.Label
        IS               matlab.ui.control.EditField
        text5            matlab.ui.control.Label
        text6            matlab.ui.control.Label
        zetac            matlab.ui.control.EditField
        text7            matlab.ui.control.Label
        zetap            matlab.ui.control.EditField
        text11           matlab.ui.control.Label
        sigmac           matlab.ui.control.EditField
        T                matlab.ui.control.EditField
        text13           matlab.ui.control.Label
        lambdaVDW        matlab.ui.control.EditField
        text14           matlab.ui.control.Label
        epsilonR         matlab.ui.control.EditField
        text18           matlab.ui.control.Label
        z                matlab.ui.control.EditField
        text19           matlab.ui.control.Label
        uipanel11        matlab.ui.container.Panel
        cbgammaAB        matlab.ui.control.CheckBox
        gammaAB          matlab.ui.control.EditField
        uipanel12        matlab.ui.container.Panel
        cbW132           matlab.ui.control.CheckBox
        W132             matlab.ui.control.EditField
        uipanel13        matlab.ui.container.Panel
        cbRmode0         matlab.ui.control.CheckBox
        cbRmode2         matlab.ui.control.CheckBox
        cbRmode1         matlab.ui.control.CheckBox
        cbRmode3         matlab.ui.control.CheckBox
        uipanel16        matlab.ui.container.Panel
        cbacont          matlab.ui.control.CheckBox
        acont            matlab.ui.control.EditField
        uipanel21        matlab.ui.container.Panel
        uipanelUNCOATED  matlab.ui.container.Panel
        cbA132           matlab.ui.control.CheckBox
        A132             matlab.ui.control.EditField
        cbCOATED         matlab.ui.control.CheckBox
        pbCOATED         matlab.ui.control.Button
        uipanel22        matlab.ui.container.Panel
        text9            matlab.ui.control.Label
        aasp             matlab.ui.control.EditField
        text10           matlab.ui.control.Label
        Nco              matlab.ui.control.EditField
        lambdaAB         matlab.ui.control.EditField
        text15           matlab.ui.control.Label
        lambdaSTE        matlab.ui.control.EditField
        text16           matlab.ui.control.Label
        gammaSTE         matlab.ui.control.EditField
        text17           matlab.ui.control.Label
        uipanel4         matlab.ui.container.Panel
        text76           matlab.ui.control.Label
        text75           matlab.ui.control.Label
        checkboxSP       matlab.ui.control.CheckBox
        checkboxSS       matlab.ui.control.CheckBox
        uipanelCOATED    matlab.ui.container.Panel
        uipanel17        matlab.ui.container.Panel
        cb2              matlab.ui.control.CheckBox
        cb3              matlab.ui.control.CheckBox
        cb1              matlab.ui.control.CheckBox
        uipanel18        matlab.ui.container.Panel
        T1               matlab.ui.control.EditField
        text60           matlab.ui.control.Label
        T2               matlab.ui.control.EditField
        text61           matlab.ui.control.Label
        A33              matlab.ui.control.EditField
        textA33          matlab.ui.control.Label
        uipanel19        matlab.ui.container.Panel
        A12              matlab.ui.control.EditField
        text63           matlab.ui.control.Label
        A12p             matlab.ui.control.EditField
        text64           matlab.ui.control.Label
        A13              matlab.ui.control.EditField
        text65           matlab.ui.control.Label
        A1p2             matlab.ui.control.EditField
        text66           matlab.ui.control.Label
        A1p2p            matlab.ui.control.EditField
        text67           matlab.ui.control.Label
        A1p3             matlab.ui.control.EditField
        text68           matlab.ui.control.Label
        text69           matlab.ui.control.Label
        A23              matlab.ui.control.EditField
        text70           matlab.ui.control.Label
        A2p3             matlab.ui.control.EditField
        cbA12            matlab.ui.control.CheckBox
        cbA13            matlab.ui.control.CheckBox
        cbA1p2           matlab.ui.control.CheckBox
        cbA1p2p          matlab.ui.control.CheckBox
        cbA1p3           matlab.ui.control.CheckBox
        cbA23            matlab.ui.control.CheckBox
        cbA12p           matlab.ui.control.CheckBox
        cbA2p3           matlab.ui.control.CheckBox
        uipanelHam       matlab.ui.container.Panel
        A11              matlab.ui.control.EditField
        textA11          matlab.ui.control.Label
        A1p1p            matlab.ui.control.EditField
        textA1p1p        matlab.ui.control.Label
        A22              matlab.ui.control.EditField
        textA22          matlab.ui.control.Label
        A2p2p            matlab.ui.control.EditField
        textA2p2p        matlab.ui.control.Label
        pbCLOSEcoated    matlab.ui.control.Button
        pbCalcCoated     matlab.ui.control.Button
        pbOKcoating      matlab.ui.control.Button
        uipanelS3        matlab.ui.container.Panel
        text108          matlab.ui.control.Label
        text107          matlab.ui.control.Label
        s3A12            matlab.ui.control.Label
        s3A1p2           matlab.ui.control.Label
        text2S3          matlab.ui.control.Label
        text1S3          matlab.ui.control.Label
        uipanelS1        matlab.ui.container.Panel
        text1S1          matlab.ui.control.Label
        text2S1          matlab.ui.control.Label
        text3S1          matlab.ui.control.Label
        text4S1          matlab.ui.control.Label
        s1A1p2p          matlab.ui.control.Label
        s1A12p           matlab.ui.control.Label
        s1A1p2           matlab.ui.control.Label
        s1A12            matlab.ui.control.Label
        text101          matlab.ui.control.Label
        text102          matlab.ui.control.Label
        text103          matlab.ui.control.Label
        text104          matlab.ui.control.Label
        uipanelS2        matlab.ui.container.Panel
        text1S2          matlab.ui.control.Label
        text2S2          matlab.ui.control.Label
        s2A12p           matlab.ui.control.Label
        s2A12            matlab.ui.control.Label
        text105          matlab.ui.control.Label
        text106          matlab.ui.control.Label
        text131          matlab.ui.control.Label
    end

    
    methods (Access = private)
        function Rmode_Callback(app, hObject, eventdata, handles)
            % hObject    handle to Rmode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Rmode as text
            %        str2double(get(hObject,'String')) returns contents of Rmode as a double
            global Rmode
            Rmode = str2double(get(hObject,'String' ));
        end
        
        function Rmode_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to Rmode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function cbUNCOATED_Callback(app, hObject, eventdata, handles)
            % hObject    handle to cbUNCOATED (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbUNCOATED
            cbUNCOATED = get(hObject,'Value');
            if cbUNCOATED
                set(app.cbCOATED,'Value',0)
            else
                set(app.cbCOATED,'Value',1)
            end
        end
        
        function edit38_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit38 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit39_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit39 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit53_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit53 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function g0ab_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to g0ab (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function h0_Callback(app, hObject, eventdata, handles)
            % hObject    handle to h0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of h0 as text
            %        str2double(get(hObject,'String')) returns contents of h0 as a double
            global h0
            h0 = str2double(get(hObject,'String' ));
        end
        
        function h0_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to h0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function outputname_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to outputname (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pbRVIEW_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pbRVIEW (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.uipanelCOATED,'visible','off')
            set(app.uipanelPLOTS,'visible','off')
            set(app.BGpanel,'visible','on')
            
            global atemp
            % check if hamaker constant contrbutions were calculated, otherwise
            % indicate user that coated system parameters will not be used and reset
            % checkbox in main panel
            cb = get(app.cbCOATED,'Value');
            if cb
            % determine if coating system hamaker constant contributions were calulated
            c1 = str2double(get(app.s1A1p2p,'String'));
            c2 = str2double(get(app.s2A12p,'String'));
            c3 = str2double(get(app.s3A1p2,'String'));
                %  let user know that calculate has to be pressed before accepting values
                if isnan(c1)&&isnan(c2)&&isnan(c3)
                    set(app.uipanelCOATED,'visible','off');
                    set (app.cbCOATED,'Value',0);
                    set (app.cbCOATED,'ForegroundColor','r');
                    msg_cancel_coating;
                    set(app.A132,'Enable','on');
                    set(app.A132,'String',atemp);
                    set(app.cbA132,'Enable','on');
                else
                    set(app.uipanelCOATED,'visible','off')
                    set(app.calculateButton,'Enable','on')
                end
            end
        end
        
        function rhet_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to rhet (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUIxDLVO_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to GUIxDLVO (see VARARGIN)
%             
%             % Choose default command line output for GUIxDLVO
%             app.output = hObject;
%              set(gcf, 'Units', 'normal')
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: A132
        function A132_Callback(app, event)
            % hObject    handle to A132 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of A132 as text
            %        str2double(get(hObject,'String')) returns contents of A132 as a double
            global A132
            A132 = str2double(get(hObject,'String' ));
        end

        % Value changed function: A132calc
        function A132calc_Callback(app, event)
            % hObject    handle to A132calc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of A132calc as text
            %        str2double(get(hObject,'String')) returns contents of A132calc as a double
            global A132calc
            A132calc = str2double(get(hObject,'String' ));
        end

        % Button pushed function: CALCacont
        function CALCacont_Callback(app, event)
            % hObject    handle to CALCacont (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global E1 v1 E2 v2 W132 a1
            %get values
            E1=str2double(get(app.E1,'String'));
            v1=str2double(get(app.v1,'String'));
            E2=str2double(get(app.E2,'String'));
            v2=str2double(get(app.v2,'String'));
            W132=str2double(get(app.W132,'String'));
            a1=str2double(get(app.a1,'String'));
            
            %calculate acont
            [cacont,Kint] = fcalcacont;
            colh4 = 1/255*[200 250 200];
            % write value in calculated and main parameter field
            set (app.acontCALC,'String',num2str(cacont));
            set (app.Kint,'String',num2str(Kint,'%5.2e\n'));
            set (app.acontCALC,'Enable','inactive');
            set (app.Kint,'Enable','inactive');
            set (app.acont,'BackgroundColor',colh4);
            set (app.acont,'String',num2str(cacont));
            set (app.W132,'Enable','inactive');
            %% enable/disable calculate profiles if all conditions apply
            % initialize flag
            calcFLAG = 1;
            % get checkboxes values
            cbCOATED = get(app.cbCOATED,'Value');
            cbA132 = get(app.cbA132,'Value');
            cbgammaAB = get(app.cbgammaAB,'Value');
            cbW132 = get(app.cbW132,'Value');
            cbacont = get(app.cbacont,'Value');
            % evaluate each checkbox
            if cbCOATED+cbA132+cbgammaAB+cbW132+cbacont==0
                %enable
                calcFLAG =1;
            else
                if cbA132==1
                    if isnan(str2double(get(app.A132calc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbgammaAB==1
                    if isnan(str2double(get(app.gammaABcalc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbW132==1
                    if isnan(str2double(get(app.W132calc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbacont==1
                    if isnan(str2double(get(app.acontCALC,'String')))
                        calcFLAG = 0;
                    end
                end
            end
            if calcFLAG ==1
                set(app.calculateButton,'Enable','on')
            else
                set(app.calculateButton,'Enable','off')
            end
        end

        % Value changed function: IS
        function IS_Callback(app, event)
            % hObject    handle to IS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of IS as text
            %        str2double(get(hObject,'String')) returns contents of IS as a double
            global IS
            IS = str2double(get(hObject,'String' ));
        end

        % Value changed function: Nco
        function Nco_Callback(app, event)
            % hObject    handle to Nco (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Nco as text
            %        str2double(get(hObject,'String')) returns contents of Nco as a double
            global Nco
            Nco = str2double(get(hObject,'String' ));
        end

        % Value changed function: T
        function T_Callback(app, event)
            % hObject    handle to T (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of T as text
            %        str2double(get(hObject,'String')) returns contents of T as a double
            global T
            T = str2double(get(hObject,'String' ));
        end

        % Value changed function: W132
        function W132_Callback(app, event)
            % hObject    handle to W132 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of W132 as text
            %        str2double(get(hObject,'String')) returns contents of W132 as a double
            global W132
            W132 = str2double(get(hObject,'String' ));
            set(app.INDW132,'String',num2str(W132));
        end

        % Value changed function: Xemax
        function Xemax_Callback(app, event)
            % hObject    handle to Xemax (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global hmaxdef
            % Hints: get(hObject,'String') returns contents of Xemax as text
            %        str2double(get(hObject,'String')) returns contents of Xemax as a double
            xl=get(hObject,'String');
            xl =str2double(xl);
            %catch errors in user input
            if isnan(xl)
                xl = hmaxdef;
            else
                if xl<1
                    xl=1;
                end
                if xl>1000
                    xl = 1000;
                end
            end
            set(app.sliderEx,'Value',xl)
            set (app.axesE,'Xlim',[0 xl])
            set(hObject,'String',num2str(xl))
        end

        % Value changed function: Xfmax
        function Xfmax_Callback(app, event)
            % hObject    handle to Xfmax (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global hmaxdef
            % Hints: get(hObject,'String') returns contents of Xfmax as text
            %        str2double(get(hObject,'String')) returns contents of Xfmax as a double
            xl=get(hObject,'String');
            xl =str2double(xl);
            %catch errors in user input
            if isnan(xl)
                xl = hmaxdef;
            else
                if xl<1
                    xl=1;
                end
                if xl>1000
                    xl = 1000;
                end
            end
            set(app.sliderFx,'Value',xl)
            set (app.axesF,'Xlim',[0 xl])
            set(hObject,'String',num2str(xl))
        end

        % Value changed function: Yemax
        function Yemax_Callback(app, event)
            % hObject    handle to Yemax (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global limvekt
            % Hints: get(hObject,'String') returns contents of Yemax as text
            %        str2double(get(hObject,'String')) returns contents of Yemax as a double
            Yemax = str2double(get(app.Yemax,'String'));
            Yemin = str2double(get(app.Yemin,'String'));
            %check user input errors
            if isnan(Yemax)||(Yemax==0&&Yemin==0)
                Yemax = limvekt;
                Yemin = -limvekt;
            else
                if Yemax<=Yemin
                    Yemax = abs(Yemax);
                    Yemin = -Yemax;
                end
            end
            % set Yaxis
            set(app.axesE,'Ylim',[Yemin Yemax])
            % set text values
            set(app.Yemax,'String',num2str(Yemax))
            set(app.Yemin,'String',num2str(Yemin))
        end

        % Value changed function: Yemin
        function Yemin_Callback(app, event)
            % hObject    handle to Yemin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Yemin as text
            %        str2double(get(hObject,'String')) returns contents of Yemin as a double
            global limvekt
            % Hints: get(hObject,'String') returns contents of Yemax as text
            %        str2double(get(hObject,'String')) returns contents of Yemax as a double
            Yemax = str2double(get(app.Yemax,'String'));
            Yemin = str2double(get(app.Yemin,'String'));
            %check user input errors
            if isnan(Yemin)||(Yemax==0&&Yemin==0)
                Yemax = limvekt;
                Yemin = -limvekt;
            else
                if Yemax<=Yemin
                    Yemax = abs(Yemax);
                    Yemin = -Yemax;
                end
            end
            % set Yaxis
            set(app.axesE,'Ylim',[Yemin Yemax])
            % set text values
            set(app.Yemax,'String',num2str(Yemax))
            set(app.Yemin,'String',num2str(Yemin))
        end

        % Value changed function: Yfmax
        function Yfmax_Callback(app, event)
            % hObject    handle to Yfmax (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Yfmax as text
            %        str2double(get(hObject,'String')) returns contents of Yfmax as a double
            global limvf
            % Hints: get(hObject,'String') returns contents of Yemax as text
            %        str2double(get(hObject,'String')) returns contents of Yemax as a double
            Yfmax = str2double(get(app.Yfmax,'String'));
            Yfmin = str2double(get(app.Yfmin,'String'));
            %check user input errors
            if isnan(Yfmax)||(Yfmax==0&&Yfmin==0)
                Yfmax = limvf;
                Yfmin = -limvf;
            else
                if Yfmax<=Yfmin
                    Yfmax = abs(Yfmax);
                    Yfmin = -Yfmax;
                end
            end
            % set Yaxis
            set(app.axesF,'Ylim',[Yfmin Yfmax])
            % set text values
            set(app.Yfmax,'String',num2str(Yfmax))
            set(app.Yfmin,'String',num2str(Yfmin))
        end

        % Value changed function: Yfmin
        function Yfmin_Callback(app, event)
            % hObject    handle to Yfmin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Yfmin as text
            %        str2double(get(hObject,'String')) returns contents of Yfmin as a double
            global limvf
            % Hints: get(hObject,'String') returns contents of Yemax as text
            %        str2double(get(hObject,'String')) returns contents of Yemax as a double
            Yfmax = str2double(get(app.Yfmax,'String'));
            Yfmin = str2double(get(app.Yfmin,'String'));
            %check user input errors
            if isnan(Yfmin)||(Yfmax==0&&Yfmin==0)
                Yfmax = limvf;
                Yfmin = -limvf;
            else
                if Yfmax<=Yfmin
                    Yfmax = abs(Yfmax);
                    Yfmin = -Yfmax;
                end
            end
            % set Yaxis
            set(app.axesF,'Ylim',[Yfmin Yfmax])
            % set text values
            set(app.Yfmax,'String',num2str(Yfmax))
            set(app.Yfmin,'String',num2str(Yfmin))
        end

        % Value changed function: a1
        function a1_Callback(app, event)
            
            % hObject    handle to a1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of a1 as text
            %        str2double(get(hObject,'String')) returns contents of a1 as a double
            global a1
            a1 = str2double(get(hObject,'String' ));
        end

        % Value changed function: a2
        function a2_Callback(app, event)
            % hObject    handle to a2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of a2 as text
            %        str2double(get(hObject,'String')) returns contents of a2 as a double
            global a2
            a2 = str2double(get(hObject,'String' ));
        end

        % Value changed function: aasp
        function aasp_Callback(app, event)
            % hObject    handle to aasp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of aasp as text
            %        str2double(get(hObject,'String')) returns contents of aasp as a double
            
            global aasp
            aasp = str2double(get(hObject,'String' ));
            if aasp <=0.0;
                aasp= 0.0;
                set(app.aasp,'String',num2str(aasp))
                set(app.cbRmode0,'Value',1)
                set(app.cbRmode1,'Value',0)
                set(app.cbRmode2,'Value',0)
                set(app.cbRmode3,'Value',0)
            else
                % check status of roughness and coated
                cbRmode0= get (app.cbRmode0,'Value');
                cbRmode1= get (app.cbRmode1,'Value');
                cbRmode2= get (app.cbRmode2,'Value');
                cbRmode3= get (app.cbRmode3,'Value');
                cbCOATED= get (app.cbCOATED,'Value');
                if cbRmode0==1
                   set(app.cbRmode0,'Value',0)
                end
                if cbRmode1+cbRmode2+cbRmode3==0
                    % by default select rough collector
                    set (app.cbRmode1,'Value',1)
                    if cbCOATED
                        msg_select_roughness_uncoat
                    else
                       % alert user to select a type of rough system
                       msg_select_roughness
                    end
                end
                if aasp<5.0e-9
                    %reset to minimum
                    aasp= 5.0e-9;
                    set(app.aasp,'String',num2str(aasp))
                    msg_min_roughness
                end
                if aasp>1.0e-5
                    % reset value to 10 um and alert user
                    aasp = 1.0e-5;
                    set(app.aasp,'String',num2str(aasp))
                    msg_select_roughness_large
                end
                %reset coated checkbox if needed
                if cbCOATED==1
                   set(app.cbCOATED,'Value',0)
                end
            
            end
        end

        % Value changed function: acont
        function acont_Callback(app, event)
            % hObject    handle to acont (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of acont as text
            %        str2double(get(hObject,'String')) returns contents of acont as a double
            global acont
            acont = str2double(get(hObject,'String' ));
            set(app.INDW132,'String',num2str(acont));
        end

        % Button pushed function: calcA132
        function calcA132_Callback(app, event)
            
            % hObject    handle to calcA132 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ve e1 n1 e2 n2 e3 n3 T kb hp
            % Boltzmann constant J/K
            kb =1.3806485E-23;
            %Planck constant J.s
            hp = 6.62607004E-34;
            % get temperature value in Kelvin
            T=str2double(get(app.T,'String'))+273.15;
            % get values from edit fields
            ve=str2double(get(app.ve,'String'));
            e1=str2double(get(app.e1,'String'));
            n1=str2double(get(app.n1,'String'));
            e2=str2double(get(app.e2,'String'));
            n2=str2double(get(app.n2,'String'));
            e3=str2double(get(app.e3,'String'));
            n3=str2double(get(app.n3,'String'));
            % calculate Hamaker
            cA132 = fcalcA132;
            colh1 = [1  240/255 0];
            % write value in calculated and main parameter field
            set (app.A132calc,'String',num2str(cA132));
            set (app.A132calc,'Enable','inactive');
            set (app.A132,'BackgroundColor',colh1);
            set (app.A132,'String',num2str(cA132));
            set (app.A132,'Enable','inactive');
            %% enable/disable calculate profiles if all conditions apply
            % initialize flag
            calcFLAG = 1;
            % get checkboxes values
            cbCOATED = get(app.cbCOATED,'Value');
            cbA132 = get(app.cbA132,'Value');
            cbgammaAB = get(app.cbgammaAB,'Value');
            cbW132 = get(app.cbW132,'Value');
            cbacont = get(app.cbacont,'Value');
            % evaluate each checkbox
            if cbCOATED+cbA132+cbgammaAB+cbW132+cbacont==0
                %enable
                calcFLAG =1;
            else
                if cbA132==1
                    if isnan(str2double(get(app.A132calc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbgammaAB==1
                    if isnan(str2double(get(app.gammaABcalc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbW132==1
                    if isnan(str2double(get(app.W132calc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbacont==1
                    if isnan(str2double(get(app.acontCALC,'String')))
                        calcFLAG = 0;
                    end
                end
            end
            if calcFLAG ==1
                set(app.calculateButton,'Enable','on')
            else
                set(app.calculateButton,'Enable','off')
            end
        end

        % Button pushed function: calcW132
        function calcW132_Callback(app, event)
            % hObject    handle to calcW132 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global g1LW g2LW g3LW gammaAB
            %get values
            g1LW=str2double(get(app.g1LW,'String'));
            g2LW=str2double(get(app.g2LW,'String'));
            g3LW=str2double(get(app.g3LW,'String'));
            gammaAB=str2double(get(app.gammaAB,'String'));
            
            %calculate W132
            cW132 = fcalcW132;
            colh3 = 1/255*[255 100 100];
            % write value in calculated and main parameter field
            set(app.W132calc,'String',num2str(cW132));
            set(app.W132calc,'Enable','inactive');
            set(app.W132,'BackgroundColor',colh3);
            set(app.W132,'String',num2str(cW132));
            set(app.W132,'Enable','inactive');
            % set value to indicator field in W132 panel
            set(app.INDW132,'String',num2str(cW132));
            set(app.INDW132,'BackgroundColor',colh3);
            set(app.INDW132,'Enable','inactive');
            %% enable/disable calculate profiles if all conditions apply
            % initialize flag
            calcFLAG = 1;
            % get checkboxes values
            cbCOATED = get(app.cbCOATED,'Value');
            cbA132 = get(app.cbA132,'Value');
            cbgammaAB = get(app.cbgammaAB,'Value');
            cbW132 = get(app.cbW132,'Value');
            cbacont = get(app.cbacont,'Value');
            % evaluate each checkbox
            if cbCOATED+cbA132+cbgammaAB+cbW132+cbacont==0
                %enable
                calcFLAG =1;
            else
                if cbA132==1
                    if isnan(str2double(get(app.A132calc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbgammaAB==1
                    if isnan(str2double(get(app.gammaABcalc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbW132==1
                    if isnan(str2double(get(app.W132calc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbacont==1
                    if isnan(str2double(get(app.acontCALC,'String')))
                        calcFLAG = 0;
                    end
                end
            end
            if calcFLAG ==1
                set(app.calculateButton,'Enable','on')
            else
                set(app.calculateButton,'Enable','off')
            end
        end

        % Button pushed function: calcgammaAB
        function calcgammaAB_Callback(app, event)
            % hObject    handle to calcgammaAB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global g1pos g1neg g2pos g2neg g3pos g3neg
            %get values
            g1pos=str2double(get(app.g1pos,'String'));
            g1neg=str2double(get(app.g1neg,'String'));
            g2pos=str2double(get(app.g2pos,'String'));
            g2neg=str2double(get(app.g2neg,'String'));
            g3pos=str2double(get(app.g3pos,'String'));
            g3neg=str2double(get(app.g3neg,'String'));
            %calculate gammaAB
            cgammaAB = fcalcgammaAB;
            colh2 = [0.68 0.92 1];
            % write value in calculated and main parameter field
            set (app.gammaABcalc,'String',num2str(cgammaAB));
            set (app.gammaABcalc,'Enable','inactive');
            set (app.gammaAB,'BackgroundColor',colh2);
            set (app.gammaAB,'String',num2str(cgammaAB));
            set (app.gammaAB,'Enable','inactive');
            % set value to indicator field in W132 panel
            set(app.INDgammaAB,'String',num2str(cgammaAB));
            set(app.INDgammaAB,'Enable','inactive');
            set (app.INDgammaAB,'BackgroundColor',colh2);
            %% enable/disable calculate profiles if all conditions apply
            % initialize flag
            calcFLAG = 1;
            % get checkboxes values
            cbCOATED = get(app.cbCOATED,'Value');
            cbA132 = get(app.cbA132,'Value');
            cbgammaAB = get(app.cbgammaAB,'Value');
            cbW132 = get(app.cbW132,'Value');
            cbacont = get(app.cbacont,'Value');
            % evaluate each checkbox
            if cbCOATED+cbA132+cbgammaAB+cbW132+cbacont==0
                %enable
                calcFLAG =1;
            else
                if cbA132==1
                    if isnan(str2double(get(app.A132calc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbgammaAB==1
                    if isnan(str2double(get(app.gammaABcalc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbW132==1
                    if isnan(str2double(get(app.W132calc,'String')))
                        calcFLAG = 0;
                    end
                end
                if cbacont==1
                    if isnan(str2double(get(app.acontCALC,'String')))
                        calcFLAG = 0;
                    end
                end
            end
            if calcFLAG ==1
                set(app.calculateButton,'Enable','on')
            else
                set(app.calculateButton,'Enable','off')
            end
        end

        % Button pushed function: calculateButton
        function calculateButton_Callback(app, event)
            % --- Executes on button press in calculateButton.
            % hObject    handle to calculateButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % output parameters
            global H Hnm EJ Ekt F H2 cbHETPLOTS rhetv cbHET zetahet
            % initialize hetdomain influence plots toggle as zero
            cbHETPLOTS = 0;
            %% input parameters
            global  a1 a2 IS zetac zetap aasp sigmac T
            global lambdaVDW lambdaAB lambdaSTE gammaSTE epsilonR z A132
            global SP SS Rmode VDWmode
            % global vdw from fundamentals
            global ve e1 n1 e2 n2 e3 n3 A132calc
            % global vdw coated systems
            global T1 T2 A33 %coating thickness and fluid Hamaker
            global A12  A12p A13  A1p2  A1p2p A1p3 A23 A2p3 %combined Hamaker
            global A12c A12pc A13c A1p2c  A1p2pc A1p3c A23c A2p3c  %combined Hamaker calculated
            global A11 A1p1p A22 A2p2p % single materials Hamaker
            global s1A1p2p s1A12p s1A1p2 s1A12 s2A12p s2A12 s3A1p2 s3A12 % hamaker constributions
            %acid-base energy fundamentals
            global g1pos g1neg g2pos g2neg g3pos g3neg gammaABcalc gammaAB
            %work of adhesion fundamentals
            global g1LW g2LW g3LW INDgammaAB W132calc W132
            %aconr for steric fundamentals
            global E1 E2 v1 v2 INDW132  Kint acontCALC  acont
            % aproximated Hamaker for Born in coated system case
            global aproxA132
            % checkboxes
            global cbCOATED cbA132  cbgammaAB cbW132 cbacont
            global cb1 cb2 cb3
            global  cbRmode1 cbRmode2 cbRmode3
            % global for plots
            global limvekt limvf hmaxdef h21 h22 FhetT EhetT lw
            %% read main parameters
            %% Geometry
            if (get(app.checkboxSS,'Value'))
                SS = 1;
                SP = 0;
            else
                SP = 1;
                SS = 0;
            end
            %% Roughness mode
            if (get(app.cbRmode0,'Value'))
                Rmode = 0;
            end
            if (get(app.cbRmode1,'Value'))
                Rmode = 1;
            end
            if (get(app.cbRmode2,'Value'))
                Rmode = 2;
            end
            if (get(app.cbRmode3,'Value'))
                Rmode = 3;
            end
            %% over mean field surface or over heterodomain
            cbHET = get(app.cbHET,'Value');
            % read heterodomain properties
            if cbHET
                %rhet = str2double(get(app.rhet,'String'));
                zetahet = str2double(get(app.zetahet,'String'));
            end
            %% main DLVO
            T = str2double(get(app.T,'String' ));
            IS = str2double(get(app.IS,'String' ));
            a1 = str2double(get(app.a1,'String' ));
            a2 = str2double(get(app.a2,'String' ));
            zetac = str2double(get(app.zetac,'String' ));
            zetap = str2double(get(app.zetap,'String' ));
            z = str2double(get(app.z,'String' ));
            epsilonR = str2double(get(app.epsilonR,'String' ));
            lambdaVDW = str2double(get(app.lambdaVDW,'String' ));
            sigmac = str2double(get(app.sigmac,'String' ));
            %% xDLVO parameters
            lambdaAB = str2double(get(app.lambdaAB,'String' ));
            lambdaSTE = str2double(get(app.lambdaSTE,'String' ));
            gammaSTE = str2double(get(app.gammaSTE,'String' ));
            aasp = str2double(get(app.aasp,'String' ));
            %% vdW parameters
            % initialize vdw fundamentals
            ve = 'N/A'; e1 = 'N/A'; n1 = 'N/A'; e2 = 'N/A'; n2 = 'N/A';
            e3 = 'N/A'; n3 = 'N/A'; A132calc = 'N/A';
            %% initialize vdw coated system parameters
            %coating thickness and Fluid Hamaker constant
            T1 = 'N/A'; T2 = 'N/A'; A33 = 'N/A';
            %combined Hamaker
            A12 = 'N/A'; A12p = 'N/A';  A13 = 'N/A'; A1p2  = 'N/A';
            A1p2p = 'N/A'; A1p3 = 'N/A'; A23 = 'N/A'; A2p3 = 'N/A';
            %combined Hamaker calculated flag
            A12c = 0; A12pc = 0; A13c = 0; A1p2c = 0;  A1p2pc = 0;
            A1p3c = 0; A23c = 0; A2p3c = 0;
            % single materials Hamaker
            A11 = 'N/A'; A1p1p = 'N/A'; A22 = 'N/A';  A2p2p = 'N/A';
            % hamaker constributions
            s1A1p2p = 'N/A'; s1A12p = 'N/A'; s1A1p2 = 'N/A';  s1A12 = 'N/A';
            s2A12p = 'N/A'; s2A12 = 'N/A'; s3A1p2 = 'N/A';  s3A12 = 'N/A';
            %% initialize acid-base energy fundamentals
            g1pos = 'N/A'; g1neg = 'N/A'; g2pos = 'N/A';
            g2neg = 'N/A'; g3pos = 'N/A'; g3neg = 'N/A'; gammaABcalc = 'N/A';
            %% initialize work of adhesion fundamentals
            g1LW = 'N/A'; g2LW = 'N/A'; g3LW = 'N/A'; INDgammaAB = 'N/A'; W132calc= 'N/A';
            %% initialize contact radius for steric interaction fundamentals
            E1 = 'N/A'; E2 = 'N/A'; v1 = 'N/A'; v2 = 'N/A';
            INDW132 = 'N/A';  Kint = 'N/A'; acontCALC = 'N/A';
            %% check if coated system for vdw was active
            cbCOATED=get(app.cbCOATED,'Value');
            cbA132=get(app.cbA132,'Value');
            if cbCOATED
                % set combined Hamnaker to N/A (not used in coated systems)
                A132 = 'N/A';
                % load values from used parameters
                %coating thickness and Fluid Hamaker constant
                T1 = str2double(get(app.T1,'String'));
                T2 = str2double(get(app.T2,'String'));
                A33 = str2double(get(app.A33,'String'));
                %combined Hamaker
                A12 = str2double(get(app.A12,'String'));
                A12p = str2double(get(app.A12p,'String'));
                A13 = str2double(get(app.A13,'String'));
                A1p2 = str2double(get(app.A1p2,'String'));
                A1p2p = str2double(get(app.A1p2p,'String'));
                A1p3 = str2double(get(app.A1p3,'String'));
                A23 = str2double(get(app.A23,'String'));
                A2p3 = str2double(get(app.A2p3,'String'));
                %combined Hamaker calculated flag
                A12c = get(app.cbA12,'Value');
                A12pc = get(app.cbA12p,'Value');
                A13c = get(app.cbA13,'Value');
                A1p2c = get(app.cbA1p2,'Value');
                A1p2pc = get(app.cbA1p2p,'Value');
                A1p3c = get(app.cbA1p3,'Value');
                A23c = get(app.cbA23,'Value');
                A2p3c = get(app.cbA2p3,'Value');
                % single materials Hamaker
                A11 = str2double(get(app.A11,'String'));
                A1p1p = str2double(get(app.A1p1p,'String'));
                A22 = str2double(get(app.A22,'String'));
                A2p2p = str2double(get(app.A2p2p,'String'));
                % load corresponding hamaker contributions depending on type of coated
                % system
                cb1 = get(app.cb1,'Value');
                if cb1
                    VDWmode = 1;
                    %get Hamaker contributions values
                    s1A1p2p = str2double(get(app.s1A1p2p,'String'));
                    s1A12p = str2double(get(app.s1A12p,'String'));
                    s1A1p2 = str2double(get(app.s1A1p2,'String'));
                    s1A12 = str2double(get(app.s1A12,'String'));
                    %set corresponding combined Hamaker constant aproximated from
                    %coating contributions
                    aproxA132 = s1A1p2p;
                end
                cb2 = get(app.cb2,'Value');
                if cb2
                    VDWmode = 2;
                    %get Hamaker contributions values
                    s2A12p = str2double(get(app.s2A12p,'String'));
                    s2A12 = str2double(get(app.s2A12,'String'));
                    %set corresponding combined Hamaker constant aproximated from
                    %coating contributions
                    aproxA132 = s2A12p;
                end
                cb3 = get(app.cb3,'Value');
                if cb3
                    VDWmode = 3;
                    %get Hamaker contributions values
                    s3A1p2 = str2double(get(app.s3A1p2,'String'));
                    s3A12 = str2double(get(app.s3A12,'String'));
                    %set corresponding combined Hamaker constant aproximated from
                    %coating contributions
                    aproxA132 = s3A1p2;
                end
            else
               VDWmode = 0;
               A132 = str2double(get(app.A132,'String'));
               % get values if A132 calculated from fundamentals
               cbA132=get(app.cbA132,'Value');
                if cbA132
                    ve = str2double(get(app.ve,'String'));
                    e1 = str2double(get(app.e1,'String'));
                    n1 = str2double(get(app.n1,'String'));
                    e2 = str2double(get(app.e2,'String'));
                    n2 = str2double(get(app.n2,'String'));
                    e3 = str2double(get(app.e3,'String'));
                    n3 = str2double(get(app.n3,'String'));
                    A132calc = str2double(get(app.A132calc,'String'));
                end
            end
            %% check if acid-base energy was calculated from fundamentals
            gammaAB = str2double(get(app.gammaAB,'String'));
            cbgammaAB=get(app.cbgammaAB,'Value');
            if cbgammaAB
                g1pos = str2double(get(app.g1pos,'String'));
                g1neg = str2double(get(app.g1neg,'String'));
                g2pos = str2double(get(app.g2pos,'String'));
                g2neg = str2double(get(app.g2neg,'String'));
                g3pos = str2double(get(app.g3pos,'String'));
                g3neg = str2double(get(app.g3neg,'String'));
                gammaABcalc = str2double(get(app.gammaABcalc,'String'));
            end
            %% check if work  of adhesion, was calculated from fundamentals
            W132 = str2double(get(app.W132,'String'));
            cbW132 = get(app.cbW132,'Value');
            if cbW132
                g1LW = str2double(get(app.g1LW,'String'));
                g2LW = str2double(get(app.g2LW,'String'));
                g3LW = str2double(get(app.g3LW,'String'));
                INDgammaAB = str2double(get(app.INDgammaAB,'String'));
                W132calc = str2double(get(app.W132calc,'String'));
            end
            %% check if contat radius was calculated from fundamentals
            acont = str2double(get(app.acont,'String'));
            cbacont = get(app.cbacont,'Value');
            if cbacont
                E1 = str2double(get(app.E1,'String'));
                E2 = str2double(get(app.E2,'String'));
                v1 = str2double(get(app.v1,'String'));
                v2 = str2double(get(app.v2,'String'));
                INDW132 = str2double(get(app.INDW132,'String'));
                Kint = str2double(get(app.Kint,'String'));
                acontCALC = str2double(get(app.acontCALC,'String'));
            end
            %%  CALCULATE PROFILES
            % define constants
            kB = 1.3806485e-23; %Boltzmann J/k
            kt = kB*T; %(J)
            hP = 6.62607004e-34 ;%Plank constant (J.s)
            e_charge=1.602176621e-19; % elementry charge (C)
            Na = 6.02214086e+23;%avogradro number (-)
            epsilon0 = 8.85418781762e-12; % Vacuum permitivity (C2/(N.m2))
            epsilonW = epsilon0*epsilonR;
            % number of interactions per asperity
            % Ranges from 1-4 for opposed and complimentary packed asperities, respectively.
            % Recommended value is 2.5
            Nco = 2.5;
            % Minimum separation distance, generally accepted to be 1.58 amstrongs (in vacuum Israelachvili)
            % ho = 1.58e-10;
            % calculated parameters
            %inverse Debye lenght
            K = 1/(epsilonW*kB*T/2/Na/z^2/e_charge^2/IS)^0.5;
            % radius of zone of influence (rZOI) for EDL
            rZOI = 2*(a1*K^-1)^0.5;
            % set rZOI field value
            set(app.rZOI,'Enable','on')
            set(app.rZOI,'String',num2str(rZOI))
            % radius of zone of influence (rZOIAB) for Acid-base
            rZOIAB = 2*(a1*lambdaAB)^0.5;
            % calculate rhetvector corresponding to 0.2 0.4 0.6 and 0.8 ZOI
            afvector = [0.2 0.4 0.6 0.8]; % if changed change GUI respectively
            rhetv = (afvector*rZOI^2).^0.5;
            % populate rhetv values in GUI
            if cbHET
                set(app.rhet02,'String',num2str(rhetv(1)))
                set(app.rhet02,'ForegroundColor','b')
                set(app.rhet04,'String',num2str(rhetv(2)))
                set(app.rhet04,'ForegroundColor','b')
                set(app.rhet06,'String',num2str(rhetv(3)))
                set(app.rhet06,'ForegroundColor','b')
                set(app.rhet08,'String',num2str(rhetv(4)))
                set(app.rhet08,'ForegroundColor','b')
            
            end
            % set number of asperities in zone of influence EDL and vdw
            n = rZOI^2/(aasp^2)*(pi/4);
            % calculate number of asperities in zone of influence for AB
            nAB = (rZOIAB^2)/(aasp^2)*(pi/4);
            % smooth surface coverage for EDL
            if Rmode>0&&aasp>0.0
                theta = 1.0-pi/4;
            else
                theta = 1.0;
            end
            % radius of steric hydration contact
            aSte=(acont^2+2*lambdaSTE*(a1+(a1^2-acont^2)^0.5))^0.5;
            %% define separation distance vector
            % step factor , hmax
            sf = 0.01; hmax = 1.0e-6; hmin =  1.0E-10;
            % generate vector
            H(1) = hmin; i =1;
            while H(i)<=hmax;
                H(i+1) = H(i)*(1+sf);
                i=i+1;
            end
            % separation distance for offset smooth surface (Rmode > 0)
            if (Rmode==1)||(Rmode==2)
                Hoff = aasp; % complimentary offset surface distance
                H2 = H + Hoff;
            elseif Rmode==3
                Hoff = 0.5*(2*aasp + sqrt(3)*aasp); % complimentary offset surface distance
                H2 = H + Hoff;
            end
            %% Sphere_sphere geometry
            if SS==1
                %interactions independet of coating and roughness (assumed)
                % Born interaction
                if cbCOATED
                    A132c = aproxA132;
                else
                    A132c = A132;
                end
                Eborn=E_Born_SS(H,A132c,sigmac,a1);
                Fborn=F_Born_SS(H,A132c,sigmac,a1);
                % Steric interaction
                Este=E_Ste_SS(H,lambdaSTE,gammaSTE,aSte);
                Fste=F_Ste_SS(H,lambdaSTE,gammaSTE,aSte);
                % smooth surface
                if Rmode==0
                    if cbCOATED
                        %coated colloid-coated collector
                        if VDWmode==1
                           Evdw=E_vdW_SS_coated_systems(H,a1,a2,lambdaVDW,...
                                T1,T2,s1A1p2p,s1A12p,s1A1p2,s1A12,VDWmode);
                           Fvdw=F_vdW_SS_coated_systems(H,a1,a2,lambdaVDW,...
                                T1,T2,s1A1p2p,s1A12p,s1A1p2,s1A12,VDWmode);
                        end
                        %colloid-coated collector
                        if VDWmode==2
                           Evdw=E_vdW_SS_coated_systems(H,a1,a2,lambdaVDW,...
                                T1,T2,0.0,s2A12p,0.0,s2A12,VDWmode);
                           Fvdw=F_vdW_SS_coated_systems(H,a1,a2,lambdaVDW,...
                                T1,T2,0.0,s2A12p,0.0,s2A12,VDWmode);
                        end
                        %coated colloid-collector
                        if VDWmode==3
                           Evdw=E_vdW_SS_coated_systems(H,a1,a2,lambdaVDW,...
                                T1,T2,0.0,0.0,s3A1p2,s3A12,VDWmode);
                           Fvdw=F_vdW_SS_coated_systems(H,a1,a2,lambdaVDW,...
                                T1,T2,0.0,0.0,s3A1p2,s3A12,VDWmode);
                        end
                    else %not coated smooth
                        %Evdw
                        Evdw=E_vdW_SS_colloid_plate(H,A132,a1,a2,lambdaVDW);
                        Fvdw=F_vdW_SS_colloid_plate(H,A132,a1,a2,lambdaVDW);
                    end
                    %EDL smooth
                    Eedl =E_EDL_SS_colloid_plate(H,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetac,a1,a2);
                    Fedl =F_EDL_SS_colloid_plate(H,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetac,a1,a2);
                    if cbHET
                        Eedlhet =E_EDL_SS_colloid_plate(H,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,a1,a2);
                        Fedlhet =F_EDL_SS_colloid_plate(H,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,a1,a2);
                    end
                    %acid base smooth
                    % determine ho for minimum (Eborn+Evdw) Energy
                    [Emin,imin]=min(Eborn+Evdw);
                    ho = H(imin);
                    Eab =E_AB_SS_colloid_plate(H,lambdaAB,a1,gammaAB,ho,a2);
                    Fab =F_AB_SS_colloid_collector(H,lambdaAB,a1,gammaAB,ho,a2);
                else
                    % Rmode1 = Rough Colloid; rmode=2 Rough Collector; Rmode=3 Rough Colloid and Collector;
                    Evdw =E_vdW_SS_colloid_plate(H2,A132,a1,a2,lambdaVDW)...
                          + E_vdW_SS_RMODE(n,H,A132,aasp,a2,lambdaVDW,a1,Rmode,Nco);
                    Fvdw =F_vdW_SS_colloid_plate(H2,A132,a1,a2,lambdaVDW)...
                          + F_vdW_SS_RMODE(n,H,a2,A132,aasp,lambdaVDW,a1,Rmode,Nco);
                    %EDL rough
                    Eedl =theta*E_EDL_SS_colloid_plate(H2,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetac,a1,a2)...
                          + E_EDL_SS_RMODE(H,a2,epsilonW,K,kB,T,z,zetap,e_charge,zetac,aasp,a1,Rmode,n,Nco);
                    Fedl =theta*F_EDL_SS_colloid_plate(H2,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetac,a1,a2)...
                          + F_EDL_SS_RMODE(H,a2,epsilonW,K,kB,T,z,zetap,e_charge,zetac,aasp,a1,Rmode,n,Nco);
                    if cbHET
                        Eedlhet =theta*E_EDL_SS_colloid_plate(H2,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,a1,a2)...
                                 + E_EDL_SS_RMODE(H,a2,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,aasp,a1,Rmode,n,Nco);
                        Fedlhet =theta*F_EDL_SS_colloid_plate(H2,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,a1,a2)...
                                 + F_EDL_SS_RMODE(H,a2,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,aasp,a1,Rmode,n,Nco);
                    end
                    % determine ho for minimum (Eborn+Evdw) Energy
                    [Emin,imin]=min(Eborn+Evdw);
                    ho = H(imin);
                    Eab =E_AB_SS_RMODE(H,lambdaAB,aasp,nAB,gammaAB,ho,Rmode,a1,a2,Nco);
                    Fab =F_AB_SS_RMODE(H,lambdaAB,aasp,nAB,gammaAB,ho,Rmode,a1,a2,Nco);
                end
            end
            if SP==1
                %interactions independet of coating and roughness (assumed)
                % Born interaction
                if cbCOATED
                    A132c = aproxA132;
                else
                    A132c = A132;
                end
                Eborn=E_Born_SP_colloid_plate(H,A132c,sigmac,a1);
                Fborn=F_Born_SP_colloid_plate(H,A132c,sigmac,a1);
                % Steric interaction
                Este=E_Ste_SP_colloid_plate(H,lambdaSTE,gammaSTE,aSte);
                Fste=F_Ste_SP_colloid_plate(H,lambdaSTE,gammaSTE,aSte);
                % smooth surface
                if Rmode==0
                    if cbCOATED
                        %coated colloid-coated collector
                        if VDWmode==1
                           Evdw=E_vdW_SP_coated_systems(H,a1,lambdaVDW,...
                                T1,T2,s1A1p2p,s1A12p,s1A1p2,s1A12,VDWmode);
                           Fvdw=F_vdW_SP_coated_systems(H,a1,lambdaVDW,...
                                T1,T2,s1A1p2p,s1A12p,s1A1p2,s1A12,VDWmode);
                        end
                        %colloid-coated collector
                        if VDWmode==2
                           Evdw=E_vdW_SP_coated_systems(H,a1,lambdaVDW,...
                                T1,T2,0.0,s2A12p,0.0,s2A12,VDWmode);
                           Fvdw=F_vdW_SP_coated_systems(H,a1,lambdaVDW,...
                                T1,T2,0.0,s2A12p,0.0,s2A12,VDWmode);
                        end
                        %coated colloid-collector
                        if VDWmode==3
                           Evdw=E_vdW_SP_coated_systems(H,a1,lambdaVDW,...
                                T1,T2,0.0,0.0,s3A1p2,s3A12,VDWmode);
                           Fvdw=F_vdW_SP_coated_systems(H,a1,lambdaVDW,...
                                T1,T2,0.0,0.0,s3A1p2,s3A12,VDWmode);
                        end
                    else %not coated smooth
                        %Evdw
                        Evdw=E_vdW_SP_colloid_plate(H,A132,a1,lambdaVDW);
                        Fvdw=F_vdW_SP_colloid_plate(H,A132,a1,lambdaVDW);
                    end
                    %EDL smooth
                    Eedl =E_EDL_SP_colloid_plate(H,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetac,a1);
                    Fedl =F_EDL_SP_colloid_plate(H,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetac,a1);
                    if cbHET
                        Eedlhet =E_EDL_SP_colloid_plate(H,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,a1);
                        Fedlhet =F_EDL_SP_colloid_plate(H,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,a1);
                    end
                    %acid base smooth
                    % determine ho for minimum (Eborn+Evdw) Energy
                    [Emin,imin]=min(Eborn+Evdw);
                    ho = H(imin);
                    Eab =E_AB_SP_colloid_plate(H,lambdaAB,a1,gammaAB,ho);
                    Fab =F_AB_SP_colloid_plate(H,lambdaAB,a1,gammaAB,ho);
                else
                    % Rmode1 = Rough Collector; rmode=2 Rough Colloid; Rmode=3 Rough Colloid and Collector;
                    Evdw =E_vdW_SP_colloid_plate(H2,A132,a1,lambdaVDW)...
                          + E_vdW_SP_RMODE(H,n,A132,aasp,a1,lambdaVDW,Rmode,Nco);
                    Fvdw =F_vdW_SP_colloid_plate(H2,A132,a1,lambdaVDW)...
                          + F_vdW_SP_RMODE(H,n,A132,aasp,a1,lambdaVDW,Rmode,Nco);
                    % EDL rough
                    Eedl =theta*E_EDL_SP_colloid_plate(H2,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetac,a1)...
                          + E_EDL_SP_RMODE(H,n,epsilonW,K,kB,T,z,zetap,e_charge,zetac,aasp,a1,Rmode,Nco);
                    Fedl =theta*F_EDL_SP_colloid_plate(H2,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetac,a1)...
                          + F_EDL_SP_RMODE(H,n,epsilonW,K,kB,T,z,zetap,e_charge,zetac,aasp,a1,Rmode,Nco);
                    if cbHET
                        Eedlhet =theta*E_EDL_SP_colloid_plate(H2,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,a1)...
                                 + E_EDL_SP_RMODE(H,n,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,aasp,a1,Rmode,Nco);
                        Fedlhet =theta*F_EDL_SP_colloid_plate(H2,1.0,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,a1)...
                                 + F_EDL_SP_RMODE(H,n,epsilonW,K,kB,T,z,zetap,e_charge,zetahet,aasp,a1,Rmode,Nco);
                    end
                    % determine ho for minimum (Eborn+Evdw) Energy
                    [Emin,imin]=min(Eborn+Evdw);
                    ho = H(imin);
                    Eab =E_AB_SP_RMODE(H,lambdaAB,aasp,nAB,gammaAB,ho,Rmode,a1,Nco);
                    Fab =F_AB_SP_RMODE(H,lambdaAB,aasp,nAB,gammaAB,ho,Rmode,a1,Nco);
                end
            end
            %% create dummy Eedlhet and Fedlhet vectors
            if cbHET==0
                Eedlhet = zeros(1,length(H));
                Fedlhet = zeros(1,length(H));
            end
            %% remove insignificant values (<abs(10e-30))
            tol = 1e-30;
            % Energy
            Evdw(abs(Evdw)<tol)=0.0;
            Eedl(abs(Eedl)<tol)=0.0;
            Eedlhet(abs(Eedlhet)<tol)=0.0;
            Eab(abs(Eab)<tol)=0.0;
            Eborn(abs(Eborn)<tol)=0.0;
            Este(abs(Este)<tol)=0.0;
            % Force
            Fvdw(abs(Fvdw)<tol)=0.0;
            Fedl(abs(Fedl)<tol)=0.0;
            Fedlhet(abs(Fedlhet)<tol)=0.0;
            Fab(abs(Fab)<tol)=0.0;
            Fborn(abs(Fborn)<tol)=0.0;
            Fste(abs(Fste)<tol)=0.0;
            %% calculate matrix for hetdomain influence
            if cbHET
                for i=1:length(afvector)
                    % calculate combined EDL energy and force
                    EedlhetM(:,i) = (1-afvector(i))*Eedl+afvector(i)*Eedlhet;
                    FedlhetM(:,i) = (1-afvector(i))*Fedl+afvector(i)*Fedlhet;
                    EhetT(:,i) =  Evdw'+EedlhetM(:,i)+Eab'+Eborn'+Este';
                    FhetT(:,i) =  Fvdw'+FedlhetM(:,i)+Fab'+Fborn'+Fste';
                end
                EhetT=EhetT*1/kt;
            end
            %% save to Energy matrix
            EJ(:,1)=Evdw;
            EJ(:,2)=Eedl;
            EJ(:,3)=Eab;
            EJ(:,4)=Eborn;
            EJ(:,5)=Este;
            % EJ(:,6)=Evdw+Eedl+Eab+Eborn+Este;
            EJ(:,6)=Evdw+Eedl+Eab+Eborn+Este;
            % calculate kt Energy matrix
            Ekt=EJ*1/kt;
            %% save to Force matrix
            F(:,1)=Fvdw;
            F(:,2)=Fedl;
            F(:,3)=Fab;
            F(:,4)=Fborn;
            F(:,5)=Fste;
            F(:,6)=Fvdw+Fedl+Fab+Fborn+Fste;
            %% plot
            % clear plot data
            cla(app.axesE)
            cla(app.axesF)
            %make plot panel visible
            set(app.uipanelPLOTS,'Visible','on')
            %set limits for y axis Ekt and force
            limvekt= 1.5*abs(min(Ekt(:,6)));
            limvf = 1.5*abs(min(F(:,6)));
            % set max limit for x axis (nm)
            hmaxdef = 100;
            %set line width for axis
            lw = 2;
            %get separation distance in nm
            Hnm = H/1.0e-9;
            
            axes(app.axesE);
            plot (Hnm,Ekt(:,1),'-b','LineWidth',lw)
            hold on;
            plot (Hnm,Ekt(:,2),'-r','LineWidth',lw)
            plot (Hnm,Ekt(:,3),'-g','LineWidth',lw)
            plot (Hnm,Ekt(:,4),'-m','LineWidth',lw)
            plot (Hnm,Ekt(:,5),'-','LineWidth',lw,'Color',[255/255 165/255 0])
            plot (Hnm,Ekt(:,6),'--k','LineWidth',lw+0.5)
            axis([0.0 hmaxdef -limvekt limvekt])
            legend('van der Waals','EDL','Acid-Base','Born','Steric','Total')
            xlabel('Separation Distance   (nm)');ylabel('Energy   (kT)');
            set(axes,'Fontsize',40);
            set(legend,'Fontsize',60);
            hold off;
            %
            axes(app.axesF);
            plot (Hnm,F(:,1),'-b','LineWidth',lw)
            hold on;
            plot (Hnm,F(:,2),'-r','LineWidth',lw)
            plot (Hnm,F(:,3),'-g','LineWidth',lw)
            plot (Hnm,F(:,4),'-m','LineWidth',lw)
            plot (Hnm,F(:,5),'-','LineWidth',lw,'Color',[255/255 165/255 0])
            plot (Hnm,F(:,6),'--k','LineWidth',lw+0.5)
            axis([0.0 hmaxdef/5 -limvf limvf])
            legend('van der Waals','EDL','Acid-Base','Born','Steric','Total')
            xlabel('Separation Distance   (nm)');ylabel('Force   (N)');
            set(axes,'Fontsize',40);
            set(legend,'Fontsize',60);
            hold off;
            %% set sliders properties (X axis)
            %E
            set(app.sliderEx,'Min',1.0);
            set(app.sliderEx,'Max',max(Hnm));
            set(app.sliderEx,'Value',hmaxdef);
            set(app.Xemax,'String',num2str(hmaxdef));
            %F
            set(app.sliderFx,'Min',1.0);
            set(app.sliderFx,'Max',max(Hnm));
            set(app.sliderFx,'Value',hmaxdef/5);
            set(app.Xfmax,'String',num2str(hmaxdef/5));
            %% set Y axis values
            set(app.Yemax,'String',num2str(limvekt))
            set(app.Yemin,'String',num2str(-limvekt))
            set(app.Yfmax,'String',num2str(limvf))
            set(app.Yfmin,'String',num2str(-limvf))
            %% plot hetdomain profiles if apply
            if cbHET
            %     figure(2)
            %     h21=subplot(1,2,1);
            %     handles21 = guihandles(h21);
            %     guidata(h21,handles21);
            %     plot (Hnm,EhetT(:,1),'-r','LineWidth',lw)
            %     hold on;
            %     title('Heterodomain Afract')
            %     plot (Hnm,EhetT(:,2),'-m','LineWidth',lw)
            %     plot (Hnm,EhetT(:,3),'-','LineWidth',lw,'Color',[148/255 44/255 210/255])
            %     plot (Hnm,EhetT(:,4),'-b','LineWidth',lw)
            %     axis([0.0 hmaxdef -limvekt limvekt])
            %     legend('0.2 ZOI','0.4 ZOI','0.6 ZOI','0.8 ZOI')
            %     xlabel('Separation Distance   (nm)');ylabel('Energy   (kT)');
            %
            %     figure(2)
            %     h22=subplot(1,2,2);
            %     handles22 = guihandles(h22);
            %     guidata(h22,handles22);
            %     plot (Hnm,FhetT(:,1),'-r','LineWidth',lw)
            %     title('Rhet(nm)')
            %     hold on;
            %     plot (Hnm,FhetT(:,2),'-m','LineWidth',lw)
            %     plot (Hnm,FhetT(:,3),'-','LineWidth',lw,'Color',[148/255 44/255 210/255])
            %     plot (Hnm,FhetT(:,4),'-b','LineWidth',lw)
            %     axis([0.0 hmaxdef -limvf limvf])
            %     legend(num2str(rhetv(1)*1e9),num2str(rhetv(2)*1e9),num2str(rhetv(3)*1e9),num2str(rhetv(4)*1e9))
            %     xlabel('Separation Distance   (nm)');ylabel('Force   (N)');
                axes(app.axesE);
            	hold off
                plot (Hnm,EhetT(:,1),'-b','LineWidth',lw)
                hold on;
                plot (Hnm,EhetT(:,2),'-r','LineWidth',lw)
                plot (Hnm,EhetT(:,3),'-g','LineWidth',lw)
                plot (Hnm,EhetT(:,4),'-m','LineWidth',lw)
                plot (Hnm,Ekt(:,6),'--k','LineWidth',lw+0.5)
                axis([0.0 hmaxdef -limvekt limvekt])
                legend('0.2 ZOI','0.4 ZOI','0.6 ZOI','0.8 ZOI','Mean Field')
                xlabel('Separation Distance   (nm)');ylabel('Total Energy   (kT)');
                set(axes,'Fontsize',40);
                set(legend,'Fontsize',60);
                hold off;
                %
                axes(app.axesF);
            	hold off
                plot (Hnm,FhetT(:,1),'-b','LineWidth',lw)
                hold on;
                plot (Hnm,FhetT(:,2),'-r','LineWidth',lw)
                plot (Hnm,FhetT(:,3),'-g','LineWidth',lw)
                plot (Hnm,FhetT(:,4),'-m','LineWidth',lw)
                plot (Hnm,F(:,6),'--k','LineWidth',lw+0.5)
                axis([0.0 hmaxdef/5 -limvf limvf])
                legend('0.2 ZOI','0.4 ZOI','0.6 ZOI','0.8 ZOI','Mean Field')
                xlabel('Separation Distance   (nm)');ylabel('Total Force   (N)');
                set(axes,'Fontsize',40);
                set(legend,'Fontsize',60);
                hold off;
            
            
            end
            %% enable set output directory button
            set(app.pb_setdir,'Enable','on')
        end

        % Value changed function: cb1
        function cb1_Callback(app, event)
            % hObject    handle to cb1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cb1
            colr =[0.94 0.94 0.94];
            colhs = 1/255*[186 255 150];
            cb1=get(hObject,'Value');
            if cb1
                set(app.cb2,'Value',0);
                set(app.cb3,'Value',0);
                %activate corresponding edit fields coating thickness
                set(app.T1,'Enable','on');
                set(app.T2,'Enable','on');
                % activate corresponding edit texts combined Hamaker
                set(app.A12,'Enable','on');
                set(app.A12p,'Enable','on');
                set(app.A13,'Enable','on');
                set(app.A1p2,'Enable','on');
                set(app.A1p2p,'Enable','on');
                set(app.A1p3,'Enable','on');
                set(app.A23,'Enable','on');
                set(app.A2p3,'Enable','on');
                %reset values
                set(app.A12,'String','0.0');
                set(app.A12p,'String','0.0');
                set(app.A13,'String','0.0');
                set(app.A1p2,'String','0.0');
                set(app.A1p2p,'String','0.0');
                set(app.A1p3,'String','0.0');
                set(app.A23,'String','0.0');
                set(app.A2p3,'String','0.0');
                % activate corresponding check boxes combined Hamaker
                set(app.cbA12,'Enable','on');
                set(app.cbA12p,'Enable','on');
                set(app.cbA13,'Enable','on');
                set(app.cbA1p2,'Enable','on');
                set(app.cbA1p2p,'Enable','on');
                set(app.cbA1p3,'Enable','on');
                set(app.cbA23,'Enable','on');
                set(app.cbA2p3,'Enable','on');
                 % reset corresponding check boxes combined Hamaker
                set(app.cbA12,'Value',0);
                set(app.cbA12p,'Value',0);
                set(app.cbA13,'Value',0);
                set(app.cbA1p2,'Value',0);
                set(app.cbA1p2p,'Value',0);
                set(app.cbA1p3,'Value',0);
                set(app.cbA23,'Value',0);
                set(app.cbA2p3,'Value',0);
                %reset edits color
                set(app.A12,'BackgroundColor',colr);
                set(app.A12p,'BackgroundColor',colr);
                set(app.A13,'BackgroundColor',colr);
                set(app.A1p2,'BackgroundColor',colr);
                set(app.A1p2p,'BackgroundColor',colr);
                set(app.A1p3,'BackgroundColor',colr);
                set(app.A23,'BackgroundColor',colr);
                set(app.A2p3,'BackgroundColor',colr);
                % highlight panel s1
                set(app.cb1,'BackgroundColor',colhs);
                set(app.uipanelS1,'BackgroundColor',colhs);
                set(app.text1S1,'BackgroundColor',colhs);
                set(app.text2S1,'BackgroundColor',colhs);
                set(app.text3S1,'BackgroundColor',colhs);
                set(app.text4S1,'BackgroundColor',colhs);
                % reset panel s2
                set(app.cb2,'BackgroundColor',colr);
                set(app.uipanelS2,'BackgroundColor',colr);
                set(app.text1S2,'BackgroundColor',colr);
                set(app.text2S2,'BackgroundColor',colr);
                % reset panel s3
                set(app.cb3,'BackgroundColor',colr);
                set(app.uipanelS3,'BackgroundColor',colr);
                set(app.text1S3,'BackgroundColor',colr);
                set(app.text2S3,'BackgroundColor',colr);
                %reset calculate fields in hamaker contributions
                set(app.s1A1p2p,'String','calculate');
                set(app.s1A12p,'String','calculate');
                set(app.s1A1p2,'String','calculate');
                set(app.s1A12,'String','calculate');
                set(app.s2A12p,'String','calculate');
                set(app.s2A12,'String','calculate');
                set(app.s3A1p2,'String','calculate');
                set(app.s3A12,'String','calculate');
                %reset panel
                set (app.uipanelHam,'BackgroundColor',colr);
                set (app.textA11,'BackgroundColor',colr);
                set (app.textA1p1p,'BackgroundColor',colr);
                set (app.textA22,'BackgroundColor',colr);
                set (app.textA2p2p,'BackgroundColor',colr);
                set (app.textA33,'BackgroundColor',colr);
            
            else
                set(app.cb2,'Value',1);
                set(app.T1,'Enable','off');
                % deactivate corresponding fields for s2
                set(app.A1p2,'Enable','off');
                set(app.A1p2p,'Enable','off');
                set(app.A1p3,'Enable','off');
                set(app.A1p2,'String','N/A');
                set(app.A1p2p,'String','N/A');
                set(app.A1p3,'String','N/A');
                %reset values
                set(app.A12,'String','0.0');
                set(app.A12p,'String','0.0');
                set(app.A13,'String','0.0');
                set(app.A23,'String','0.0');
                set(app.A2p3,'String','0.0');
                % deactivate corresponding cb fields and reset values
                set(app.cbA13,'Enable','off');
                set(app.cbA1p2,'Enable','off');
                set(app.cbA1p2p,'Enable','off');
                set(app.cbA1p3,'Enable','off');
                set(app.cbA13,'Value',0);
                set(app.cbA1p2,'Value',0);
                set(app.cbA1p2p,'Value',0);
                set(app.cbA1p3,'Value',0);
                % check all combined hamaker checkboxes
                % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
                %
            %     if (cbA12+cbA12p+cbA13+cbA1p2+cbA1p2p+cbA1p3+cbA23+cbA2p3)==0
            %
            %     end
                % activate corresponding edit texts combined Hamaker
                set(app.A12,'Enable','on');
                set(app.A12p,'Enable','on');
                set(app.A13,'Enable','on');
                set(app.A23,'Enable','on');
                set(app.A2p3,'Enable','on');
                % activate corresponding check boxes combined Hamaker
                set(app.cbA12,'Enable','on');
                set(app.cbA12p,'Enable','on');
                set(app.cbA13,'Enable','on');
                set(app.cbA23,'Enable','on');
                set(app.cbA2p3,'Enable','on');
               % reset panel s1
                set(app.cb1,'BackgroundColor',colr);
                set(app.uipanelS1,'BackgroundColor',colr);
                set(app.text1S1,'BackgroundColor',colr);
                set(app.text2S1,'BackgroundColor',colr);
                set(app.text3S1,'BackgroundColor',colr);
                set(app.text4S1,'BackgroundColor',colr);
                % highlight panel s2
                set(app.cb2,'BackgroundColor',colhs);
                set(app.uipanelS2,'BackgroundColor',colhs);
                set(app.text1S2,'BackgroundColor',colhs);
                set(app.text2S2,'BackgroundColor',colhs);
                %reset calculate fields in hamaker contributions
                set(app.s1A1p2p,'String','calculate');
                set(app.s1A12p,'String','calculate');
                set(app.s1A1p2,'String','calculate');
                set(app.s1A12,'String','calculate');
                set(app.s2A12p,'String','calculate');
                set(app.s2A12,'String','calculate');
                set(app.s3A1p2,'String','calculate');
                set(app.s3A12,'String','calculate');
                % reset corresponding check boxes combined Hamaker
                set(app.cbA12,'Value',0);
                set(app.cbA12p,'Value',0);
                set(app.cbA13,'Value',0);
                set(app.cbA1p2,'Value',0);
                set(app.cbA1p2p,'Value',0);
                set(app.cbA1p3,'Value',0);
                set(app.cbA23,'Value',0);
                set(app.cbA2p3,'Value',0);
                %reset edits color
                set(app.A12,'BackgroundColor',colr);
                set(app.A12p,'BackgroundColor',colr);
                set(app.A13,'BackgroundColor',colr);
                set(app.A1p2,'BackgroundColor',colr);
                set(app.A1p2p,'BackgroundColor',colr);
                set(app.A1p3,'BackgroundColor',colr);
                set(app.A23,'BackgroundColor',colr);
                set(app.A2p3,'BackgroundColor',colr);
                %reset panel
                set (app.uipanelHam,'BackgroundColor',colr);
                set (app.textA11,'BackgroundColor',colr);
                set (app.textA1p1p,'BackgroundColor',colr);
                set (app.textA22,'BackgroundColor',colr);
                set (app.textA2p2p,'BackgroundColor',colr);
                set (app.textA33,'BackgroundColor',colr);
            end
        end

        % Value changed function: cb2
        function cb2_Callback(app, event)
            % hObject    handle to cb2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            colr =[0.94 0.94 0.94];
            colhs = 1/255*[186 255 150];
            % Hint: get(hObject,'Value') returns toggle state of cb2
            cb2=get(hObject,'Value');
            if cb2
                set(app.cb1,'Value',0);
                set(app.cb3,'Value',0);
                set(app.T1,'Enable','off');
                set(app.T2,'Enable','on');
                % deactivate corresponding edits fields
                set(app.A1p2,'BackgroundColor','white');
                set(app.A1p2p,'BackgroundColor','white');
                set(app.A1p3,'BackgroundColor','white');
                set(app.A1p2,'Enable','off');
                set(app.A1p2p,'Enable','off');
                set(app.A1p3,'Enable','off');
                set(app.A1p2,'String','N/A');
                set(app.A1p2p,'String','N/A');
                set(app.A1p3,'String','N/A');
                %reset values
                set(app.A12,'String','0.0');
                set(app.A12p,'String','0.0');
                set(app.A13,'String','0.0');
                set(app.A23,'String','0.0');
                set(app.A2p3,'String','0.0');
                % deactivate corresponding cb fields and values
                set(app.cbA1p2,'Enable','off');
                set(app.cbA1p2p,'Enable','off');
                set(app.cbA1p3,'Enable','off');
                set(app.cbA1p2,'Value',0);
                set(app.cbA1p2p,'Value',0);
                set(app.cbA1p3,'Value',0);
                % check all combined hamaker checkboxes
                % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
                %
                %reset panel
                set (app.uipanelHam,'BackgroundColor',colr);
                set (app.textA11,'BackgroundColor',colr);
                set (app.textA1p1p,'BackgroundColor',colr);
                set (app.textA22,'BackgroundColor',colr);
                set (app.textA2p2p,'BackgroundColor',colr);
                set (app.textA33,'BackgroundColor',colr);
                % activate corresponding edit texts combined Hamaker
                set(app.A12,'Enable','on');
                set(app.A12p,'Enable','on');
                set(app.A13,'Enable','on');
                set(app.A23,'Enable','on');
                set(app.A2p3,'Enable','on');
                % activate corresponding check boxes combined Hamaker
                set(app.cbA12,'Enable','on');
                set(app.cbA12p,'Enable','on');
                set(app.cbA13,'Enable','on');
                set(app.cbA23,'Enable','on');
                set(app.cbA2p3,'Enable','on');
                % highlight panel s2
                set(app.cb2,'BackgroundColor',colhs);
                set(app.uipanelS2,'BackgroundColor',colhs);
                set(app.text1S2,'BackgroundColor',colhs);
                set(app.text2S2,'BackgroundColor',colhs);
                % reset panel s1
                set(app.cb1,'BackgroundColor',colr);
                set(app.uipanelS1,'BackgroundColor',colr);
                set(app.text1S1,'BackgroundColor',colr);
                set(app.text2S1,'BackgroundColor',colr);
                set(app.text3S1,'BackgroundColor',colr);
                set(app.text4S1,'BackgroundColor',colr);
                % reset panel s3
                set(app.cb3,'BackgroundColor',colr);
                set(app.uipanelS3,'BackgroundColor',colr);
                set(app.text1S3,'BackgroundColor',colr);
                set(app.text2S3,'BackgroundColor',colr);
                %reset calculate fields in hamaker contributions
                set(app.s1A1p2p,'String','calculate');
                set(app.s1A12p,'String','calculate');
                set(app.s1A1p2,'String','calculate');
                set(app.s1A12,'String','calculate');
                set(app.s2A12p,'String','calculate');
                set(app.s2A12,'String','calculate');
                set(app.s3A1p2,'String','calculate');
                set(app.s3A12,'String','calculate');
                 % reset corresponding check boxes combined Hamaker
                set(app.cbA12,'Value',0);
                set(app.cbA12p,'Value',0);
                set(app.cbA13,'Value',0);
                set(app.cbA1p2,'Value',0);
                set(app.cbA1p2p,'Value',0);
                set(app.cbA1p3,'Value',0);
                set(app.cbA23,'Value',0);
                set(app.cbA2p3,'Value',0);
                %reset edits color
                set(app.A12,'BackgroundColor',colr);
                set(app.A12p,'BackgroundColor',colr);
                set(app.A13,'BackgroundColor',colr);
                set(app.A1p2,'BackgroundColor',colr);
                set(app.A1p2p,'BackgroundColor',colr);
                set(app.A1p3,'BackgroundColor',colr);
                set(app.A23,'BackgroundColor',colr);
                set(app.A2p3,'BackgroundColor',colr);
            else
                set(app.cb1,'Value',1);
                set(app.T1,'Enable','on');
                set(app.T2,'Enable','on');
                % activate corresponding edit texts combined Hamaker
                set(app.A12,'Enable','on');
                set(app.A12p,'Enable','on');
                set(app.A13,'Enable','on');
                set(app.A1p2,'Enable','on');
                set(app.A1p2p,'Enable','on');
                set(app.A1p3,'Enable','on');
                set(app.A23,'Enable','on');
                set(app.A2p3,'Enable','on');
                %reset values
                set(app.A12,'String','0.0');
                set(app.A12p,'String','0.0');
                set(app.A13,'String','0.0');
                set(app.A1p2,'String','0.0');
                set(app.A1p2p,'String','0.0');
                set(app.A1p3,'String','0.0');
                set(app.A23,'String','0.0');
                set(app.A2p3,'String','0.0');
                % activate corresponding edit fields check boxes','on');
                set(app.cbA12p,'Enable','on');
                set(app.cbA13,'Enable','on');
                set(app.cbA1p2,'Enable','on');
                set(app.cbA1p2p,'Enable','on');
                set(app.cbA1p3,'Enable','on');
                set(app.cbA23,'Enable','on');
                set(app.cbA2p3,'Enable','on');
                % reset panel s2
                set(app.cb2,'BackgroundColor',colr);
                set(app.uipanelS2,'BackgroundColor',colr);
                set(app.text1S2,'BackgroundColor',colr);
                set(app.text2S2,'BackgroundColor',colr);
                % highlight panel s1
                set(app.cb1,'BackgroundColor',colhs);
                set(app.uipanelS1,'BackgroundColor',colhs);
                set(app.text1S1,'BackgroundColor',colhs);
                set(app.text2S1,'BackgroundColor',colhs);
                set(app.text3S1,'BackgroundColor',colhs);
                set(app.text4S1,'BackgroundColor',colhs);
                %reset calculate fields in hamaker contributions
                set(app.s1A1p2p,'String','calculate');
                set(app.s1A12p,'String','calculate');
                set(app.s1A1p2,'String','calculate');
                set(app.s1A12,'String','calculate');
                set(app.s2A12p,'String','calculate');
                set(app.s2A12,'String','calculate');
                set(app.s3A1p2,'String','calculate');
                set(app.s3A12,'String','calculate');
                 % reset corresponding check boxes combined Hamaker
                set(app.cbA12,'Value',0);
                set(app.cbA12p,'Value',0);
                set(app.cbA13,'Value',0);
                set(app.cbA1p2,'Value',0);
                set(app.cbA1p2p,'Value',0);
                set(app.cbA1p3,'Value',0);
                set(app.cbA23,'Value',0);
                set(app.cbA2p3,'Value',0);
                 %reset edits color
                set(app.A12,'BackgroundColor',colr);
                set(app.A12p,'BackgroundColor',colr);
                set(app.A13,'BackgroundColor',colr);
                set(app.A1p2,'BackgroundColor',colr);
                set(app.A1p2p,'BackgroundColor',colr);
                set(app.A1p3,'BackgroundColor',colr);
                set(app.A23,'BackgroundColor',colr);
                set(app.A2p3,'BackgroundColor',colr);
                %reset panel
                set (app.uipanelHam,'BackgroundColor',colr);
                set (app.textA11,'BackgroundColor',colr);
                set (app.textA1p1p,'BackgroundColor',colr);
                set (app.textA22,'BackgroundColor',colr);
                set (app.textA2p2p,'BackgroundColor',colr);
                set (app.textA33,'BackgroundColor',colr);
            end
        end

        % Value changed function: cb3
        function cb3_Callback(app, event)
            % hObject    handle to cb3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            colr =[0.94 0.94 0.94];
            colhs = 1/255*[186 255 150];
            % Hint: get(hObject,'Value') returns toggle state of cb3
            cb3=get(hObject,'Value');
            if cb3
                set(app.cb1,'Value',0);
                set(app.cb2,'Value',0);
                set(app.T1,'Enable','on');
                set(app.T2,'Enable','off');
                % activate corresponding edit texts combined Hamaker
                set(app.A12,'Enable','on');
                set(app.A13,'Enable','on');
                set(app.A1p2,'Enable','on');
                set(app.A1p3,'Enable','on');
                set(app.A23,'Enable','on');
                set(app.A12,'String','0.0');
                set(app.A13,'String','0.0');
                set(app.A1p2,'String','0.0');
                set(app.A1p3,'String','0.0');
                set(app.A23,'String','0.0');
                %deactivate corresponding edit texts and color combined Hamaker
                set(app.A12p,'BackgroundColor','white');
                set(app.A1p2p,'BackgroundColor','white');
                set(app.A2p3,'BackgroundColor','white');
                set(app.A12p,'Enable','off');
                set(app.A1p2p,'Enable','off');
                set(app.A2p3,'Enable','off');
                set(app.A12p,'String','N/A');
                set(app.A1p2p,'String','N/A');
                set(app.A2p3,'String','N/A');
                % activate corresponding check boxes combined Hamaker
                set(app.cbA12,'Enable','on');
                set(app.cbA13,'Enable','on');
                set(app.cbA1p2,'Enable','on');
                set(app.cbA1p3,'Enable','on');
                set(app.cbA23,'Enable','on');
                %deactivate corresponding check boxes combined Hamaker and values
                set(app.cbA12p,'Enable','off');
                set(app.cbA1p2p,'Enable','off');
                set(app.cbA2p3,'Enable','off');
                set(app.cbA12p,'Value',0);
                set(app.cbA1p2p,'Value',0);
                set(app.cbA2p3,'Value',0);
                % check all combined hamaker checkboxes
                % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
                %
                %reset panel
                set (app.uipanelHam,'BackgroundColor',colr);
                set (app.textA11,'BackgroundColor',colr);
                set (app.textA1p1p,'BackgroundColor',colr);
                set (app.textA22,'BackgroundColor',colr);
                set (app.textA2p2p,'BackgroundColor',colr);
                set (app.textA33,'BackgroundColor',colr);
                % highlight panel s3
                set(app.cb3,'BackgroundColor',colhs);
                set(app.uipanelS3,'BackgroundColor',colhs);
                set(app.text1S3,'BackgroundColor',colhs);
                set(app.text2S3,'BackgroundColor',colhs);
                % reset panel s1
                set(app.cb1,'BackgroundColor',colr);
                set(app.uipanelS1,'BackgroundColor',colr);
                set(app.text1S1,'BackgroundColor',colr);
                set(app.text2S1,'BackgroundColor',colr);
                set(app.text3S1,'BackgroundColor',colr);
                set(app.text4S1,'BackgroundColor',colr);
                % reset panel s2
                set(app.cb2,'BackgroundColor',colr);
                set(app.uipanelS2,'BackgroundColor',colr);
                set(app.text1S2,'BackgroundColor',colr);
                set(app.text2S2,'BackgroundColor',colr);
                %reset calculate fields in hamaker contributions
                set(app.s1A1p2p,'String','calculate');
                set(app.s1A12p,'String','calculate');
                set(app.s1A1p2,'String','calculate');
                set(app.s1A12,'String','calculate');
                set(app.s2A12p,'String','calculate');
                set(app.s2A12,'String','calculate');
                set(app.s3A1p2,'String','calculate');
                set(app.s3A12,'String','calculate');
                 % reset corresponding check boxes combined Hamaker
                set(app.cbA12,'Value',0);
                set(app.cbA12p,'Value',0);
                set(app.cbA13,'Value',0);
                set(app.cbA1p2,'Value',0);
                set(app.cbA1p2p,'Value',0);
                set(app.cbA1p3,'Value',0);
                set(app.cbA23,'Value',0);
                set(app.cbA2p3,'Value',0);
                %reset edits color
                set(app.A12,'BackgroundColor',colr);
                set(app.A12p,'BackgroundColor',colr);
                set(app.A13,'BackgroundColor',colr);
                set(app.A1p2,'BackgroundColor',colr);
                set(app.A1p2p,'BackgroundColor',colr);
                set(app.A1p3,'BackgroundColor',colr);
                set(app.A23,'BackgroundColor',colr);
                set(app.A2p3,'BackgroundColor',colr);
            else
                set(app.cb1,'Value',1);
                set(app.T1,'Enable','on');
                set(app.T2,'Enable','on');
            % activate corresponding edit texts combined Hamaker
                set(app.A12,'Enable','on');
                set(app.A12p,'Enable','on');
                set(app.A13,'Enable','on');
                set(app.A1p2,'Enable','on');
                set(app.A1p2p,'Enable','on');
                set(app.A1p3,'Enable','on');
                set(app.A23,'Enable','on');
                set(app.A2p3,'Enable','on');
                set(app.A12,'String','0.0');
                set(app.A12p,'String','0.0');
                set(app.A13,'String','0.0');
                set(app.A1p2,'String','0.0');
                set(app.A1p2p,'String','0.0');
                set(app.A1p3,'String','0.0');
                set(app.A23,'String','0.0');
                set(app.A2p3,'String','0.0');
                % activate corresponding check boxes combined Hamaker
                set(app.cbA12,'Enable','on');
                set(app.cbA12p,'Enable','on');
                set(app.cbA13,'Enable','on');
                set(app.cbA1p2,'Enable','on');
                set(app.cbA1p2p,'Enable','on');
                set(app.cbA1p3,'Enable','on');
                set(app.cbA23,'Enable','on');
                set(app.cbA2p3,'Enable','on');
                % reset panel s3
                set(app.cb3,'BackgroundColor',colr);
                set(app.uipanelS3,'BackgroundColor',colr);
                set(app.text1S3,'BackgroundColor',colr);
                set(app.text2S3,'BackgroundColor',colr);
                % highlight panel s1
                set(app.cb1,'BackgroundColor',colhs);
                set(app.uipanelS1,'BackgroundColor',colhs);
                set(app.text1S1,'BackgroundColor',colhs);
                set(app.text2S1,'BackgroundColor',colhs);
                set(app.text3S1,'BackgroundColor',colhs);
                set(app.text4S1,'BackgroundColor',colhs);
                %reset calculate fields in hamaker contributions
                set(app.s1A1p2p,'String','calculate');
                set(app.s1A12p,'String','calculate');
                set(app.s1A1p2,'String','calculate');
                set(app.s1A12,'String','calculate');
                set(app.s2A12p,'String','calculate');
                set(app.s2A12,'String','calculate');
                set(app.s3A1p2,'String','calculate');
                set(app.s3A12,'String','calculate');
                 % reset corresponding check boxes combined Hamaker
                set(app.cbA12,'Value',0);
                set(app.cbA12p,'Value',0);
                set(app.cbA13,'Value',0);
                set(app.cbA1p2,'Value',0);
                set(app.cbA1p2p,'Value',0);
                set(app.cbA1p3,'Value',0);
                set(app.cbA23,'Value',0);
                set(app.cbA2p3,'Value',0);
                %reset edits color
                set(app.A12,'BackgroundColor',colr);
                set(app.A12p,'BackgroundColor',colr);
                set(app.A13,'BackgroundColor',colr);
                set(app.A1p2,'BackgroundColor',colr);
                set(app.A1p2p,'BackgroundColor',colr);
                set(app.A1p3,'BackgroundColor',colr);
                set(app.A23,'BackgroundColor',colr);
                set(app.A2p3,'BackgroundColor',colr);
                %reset panel
                set (app.uipanelHam,'BackgroundColor',colr);
                set (app.textA11,'BackgroundColor',colr);
                set (app.textA1p1p,'BackgroundColor',colr);
                set (app.textA22,'BackgroundColor',colr);
                set (app.textA2p2p,'BackgroundColor',colr);
                set (app.textA33,'BackgroundColor',colr);
            end
        end

        % Value changed function: cbA12
        function cbA12_Callback(app, event)
            % hObject    handle to cbA12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hint: get(hObject,'Value') returns toggle state of cbA12
            % highlight color
            chHam = [0.68 0.92 1.0];
            % reset color
            colr = [0.94 0.94 0.94];
            global oldA12
            cbA12=get(hObject,'Value');
            if cbA12
                %highlight text field
                set (app.A12,'BackgroundColor',chHam);
                %highlight panel
                set (app.uipanelHam,'BackgroundColor',chHam);
                set (app.textA11,'BackgroundColor',chHam);
                set (app.textA1p1p,'BackgroundColor',chHam);
                set (app.textA22,'BackgroundColor',chHam);
                set (app.textA2p2p,'BackgroundColor',chHam);
            
                % save older value
                oldA12 = str2double (get(app.A12,'String'));
                % disable edid text
                set (app.A12,'Enable','inactive')
                % calculate A12
                A11 = str2double (get(app.A11,'String'));
                A22 = str2double (get(app.A22,'String'));
                A12 = A11^0.5*A22^0.5;
                % set value
                set (app.A12,'String',num2str(A12));
            else
                % enable edid text
                set (app.A12,'Enable','on')
                % load older value
                set (app.A12,'String',num2str(oldA12));
                % reset color
                set (app.A12,'BackgroundColor',colr);
                % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
                if (cbA12+cbA12p+cbA13+cbA1p2+cbA1p2p+cbA1p3+cbA23+cbA2p3)==0
                    %reset panel
                    set (app.uipanelHam,'BackgroundColor',colr);
                    set (app.textA11,'BackgroundColor',colr);
                    set (app.textA1p1p,'BackgroundColor',colr);
                    set (app.textA22,'BackgroundColor',colr);
                    set (app.textA2p2p,'BackgroundColor',colr);
                    set (app.textA33,'BackgroundColor',colr);
                end
            
            end
        end

        % Value changed function: cbA12p
        function cbA12p_Callback(app, event)
            % hObject    handle to cbA12p (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbA12p
            % highlight color
            chHam = [0.68 0.92 1.0];
            % reset color
            colr = [0.94 0.94 0.94];
            global oldA12p
            cbA12p=get(hObject,'Value');
            if cbA12p
                % save older value
                oldA12p = str2double (get(app.A12p,'String'));
                % disable edid text
                set (app.A12p,'Enable','inactive')
                % calculate A12p
                A11 = str2double (get(app.A11,'String'));
                A2p2p = str2double (get(app.A2p2p,'String'));
                A12p = A11^0.5*A2p2p^0.5;
                % set value
                set (app.A12p,'String',num2str(A12p));
            	%highlight text field
                set (app.A12p,'BackgroundColor',chHam);
                %highlight panel
                set (app.uipanelHam,'BackgroundColor',chHam);
                set (app.textA11,'BackgroundColor',chHam);
                set (app.textA1p1p,'BackgroundColor',chHam);
                set (app.textA22,'BackgroundColor',chHam);
                set (app.textA2p2p,'BackgroundColor',chHam);
            
            else
                % enable edid text
                set (app.A12p,'Enable','on')
                % load older value
                set (app.A12p,'String',num2str(oldA12p));
            	%reset edit field
            	set (app.A12p,'BackgroundColor',colr) ;
            % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
                if (cbA12+cbA12p+cbA13+cbA1p2+cbA1p2p+cbA1p3+cbA23+cbA2p3)==0
                    %reset panel
                    set (app.uipanelHam,'BackgroundColor',colr);
                    set (app.textA11,'BackgroundColor',colr);
                    set (app.textA1p1p,'BackgroundColor',colr);
                    set (app.textA22,'BackgroundColor',colr);
                    set (app.textA2p2p,'BackgroundColor',colr);
                    set (app.textA33,'BackgroundColor',colr);
                end
            end
        end

        % Value changed function: cbA132
        function cbA132_Callback(app, event)
            % hObject    handle to cbA132 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbA132
            cbA132=get(hObject,'Value');
            % colh1 = [0.68 0.92 1];
            colh1 = [1 240/255 0];
            colr = [0.94 0.94 0.94];
            if cbA132
                %disable calculate profiles
                set(app.calculateButton,'Enable','off')
                % highlight panel
                set(app.uipanelA132,'BackgroundColor',colh1')
                % enable edit text fields
                set(app.ve,'Enable','on')
                set(app.e1,'Enable','on')
                set(app.n1,'Enable','on')
                set(app.e2,'Enable','on')
                set(app.n2,'Enable','on')
                set(app.e3,'Enable','on')
                set(app.n3,'Enable','on')
                % highlight static text
                set(app.veTEXT,'BackgroundColor',colh1)
                set(app.e1TEXT,'BackgroundColor',colh1)
                set(app.n1TEXT,'BackgroundColor',colh1)
                set(app.e2TEXT,'BackgroundColor',colh1)
                set(app.n2TEXT,'BackgroundColor',colh1)
                set(app.e3TEXT,'BackgroundColor',colh1)
                set(app.n3TEXT,'BackgroundColor',colh1)
                set(app.A132TEXT,'BackgroundColor',colh1)
                %enable calcA132 and pbCOATED buttons
                set(app.calcA132,'Enable','on')
                set(app.pbCOATED,'Enable','on')
                %deactivate manual input
                set (app.A132,'Enable','inactive');
            else
            
                % reset Hamaker from fundamentals panel
                set(app.uipanelA132,'BackgroundColor',colr)
                % disable edit text fields
                set(app.ve,'Enable','off')
                set(app.e1,'Enable','off')
                set(app.n1,'Enable','off')
                set(app.e2,'Enable','off')
                set(app.n2,'Enable','off')
                set(app.e3,'Enable','off')
                set(app.n3,'Enable','off')
                % reset static text
                set(app.veTEXT,'BackgroundColor',colr)
                set(app.e1TEXT,'BackgroundColor',colr)
                set(app.n1TEXT,'BackgroundColor',colr)
                set(app.e2TEXT,'BackgroundColor',colr)
                set(app.n2TEXT,'BackgroundColor',colr)
                set(app.e3TEXT,'BackgroundColor',colr)
                set(app.n3TEXT,'BackgroundColor',colr)
                set(app.A132TEXT,'BackgroundColor',colr)
                %disable calcA132 and pbCOATED buttons
                set(app.calcA132,'Enable','off')
                set(app.pbCOATED,'Enable','off')
                %activate manual input
                set (app.A132,'Enable','on');
                set (app.A132,'BackgroundColor','white');
                %deactivate calculated edit
                set (app.A132calc,'Enable','off');
                set (app.A132calc,'String','calculate');
                set (app.A132,'BackgroundColor','white');
                %% enable/disable calculate profiles if all conditions apply
                % initialize flag
                calcFLAG = 1;
                % get checkboxes values
                cbCOATED = get(app.cbCOATED,'Value');
                cbA132 = get(app.cbA132,'Value');
                cbgammaAB = get(app.cbgammaAB,'Value');
                cbW132 = get(app.cbW132,'Value');
                cbacont = get(app.cbacont,'Value');
                % evaluate each checkbox
                if cbCOATED+cbA132+cbgammaAB+cbW132+cbacont==0
                    %enable
                    calcFLAG =1;
                else
                    if cbA132==1
                        if isnan(str2double(get(app.A132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbgammaAB==1
                        if isnan(str2double(get(app.gammaABcalc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbW132==1
                        if isnan(str2double(get(app.W132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbacont==1
                        if isnan(str2double(get(app.acontCALC,'String')))
                            calcFLAG = 0;
                        end
                    end
                end
                if calcFLAG ==1
                    set(app.calculateButton,'Enable','on')
                else
                    set(app.calculateButton,'Enable','off')
                end
                %%
            end
        end

        % Value changed function: cbA13
        function cbA13_Callback(app, event)
            % hObject    handle to cbA13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbA13
            % highlight color
            chHam = [0.68 0.92 1.0];
            % reset color
            colr = [0.94 0.94 0.94];
            global oldA13
            cbA13=get(hObject,'Value');
            if cbA13
                % save older value
                oldA13 = str2double (get(app.A13,'String'));
                % disable edid text
                set (app.A13,'Enable','inactive')
                % calculate A13
                A11 = str2double (get(app.A11,'String'));
                A33 = str2double (get(app.A33,'String'));
                A13 = A11^0.5*A33^0.5;
                % set value
                set (app.A13,'String',num2str(A13));
            	%highlight text field
                set (app.A13,'BackgroundColor',chHam);
                %highlight panel
                set (app.uipanelHam,'BackgroundColor',chHam);
                set (app.textA11,'BackgroundColor',chHam);
                set (app.textA1p1p,'BackgroundColor',chHam);
                set (app.textA22,'BackgroundColor',chHam);
                set (app.textA2p2p,'BackgroundColor',chHam);
            
            else
                % enable edid text
                set (app.A13,'Enable','on')
                % load older value
                set (app.A13,'String',num2str(oldA13));
            % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
            	%reset edit field
            	set (app.A13,'BackgroundColor',colr) ;
                if (cbA12+cbA12p+cbA13+cbA1p2+cbA1p2p+cbA1p3+cbA23+cbA2p3)==0
                    %reset panel
                    set (app.uipanelHam,'BackgroundColor',colr);
                    set (app.textA11,'BackgroundColor',colr);
                    set (app.textA1p1p,'BackgroundColor',colr);
                    set (app.textA22,'BackgroundColor',colr);
                    set (app.textA2p2p,'BackgroundColor',colr);
                    set (app.textA33,'BackgroundColor',colr);
                end
            
            end
        end

        % Value changed function: cbA1p2
        function cbA1p2_Callback(app, event)
            % hObject    handle to cbA1p2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % highlight color
            chHam = [0.68 0.92 1.0];
            % reset color
            colr = [0.94 0.94 0.94];
            % Hint: get(hObject,'Value') returns toggle state of cbA1p2
            global oldA1p2
            cbA1p2=get(hObject,'Value');
            if cbA1p2
                % save older value
                oldA1p2 = str2double (get(app.A1p2,'String'));
                % disable edid text
                set (app.A1p2,'Enable','inactive')
                % calculate A13
                A1p1p = str2double (get(app.A1p1p,'String'));
                A22= str2double (get(app.A22,'String'));
                A1p2 = A1p1p^0.5*A22^0.5;
                % set value
                set (app.A1p2,'String',num2str(A1p2));
            %highlight text field
                set (app.A1p2,'BackgroundColor',chHam);
                %highlight panel
                set (app.uipanelHam,'BackgroundColor',chHam);
                set (app.textA11,'BackgroundColor',chHam);
                set (app.textA1p1p,'BackgroundColor',chHam);
                set (app.textA22,'BackgroundColor',chHam);
                set (app.textA2p2p,'BackgroundColor',chHam);
            
            else
                % enable edid text
                set (app.A1p2,'Enable','on')
                % load older value
                set (app.A1p2,'String',num2str(oldA1p2));
            % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
            	%reset edit field
            	set (app.A1p2,'BackgroundColor',colr) ;
                if (cbA12+cbA12p+cbA13+cbA1p2+cbA1p2p+cbA1p3+cbA23+cbA2p3)==0
                    %reset panel
                    set (app.uipanelHam,'BackgroundColor',colr);
                    set (app.textA11,'BackgroundColor',colr);
                    set (app.textA1p1p,'BackgroundColor',colr);
                    set (app.textA22,'BackgroundColor',colr);
                    set (app.textA2p2p,'BackgroundColor',colr);
                    set (app.textA33,'BackgroundColor',colr);
                end
            end
        end

        % Value changed function: cbA1p2p
        function cbA1p2p_Callback(app, event)
            % hObject    handle to cbA1p2p (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % highlight color
            chHam = [0.68 0.92 1.0];
            % reset color
            colr = [0.94 0.94 0.94];
            % Hint: get(hObject,'Value') returns toggle state of cbA1p2p
            global oldA1p2p
            cbA1p2p=get(hObject,'Value');
            if cbA1p2p
                % save older value
                oldA1p2p = str2double (get(app.A1p2p,'String'));
                % disable edid text
                set (app.A1p2p,'Enable','inactive')
                % calculate A13
                A1p1p = str2double (get(app.A1p1p,'String'));
                A2p2p= str2double (get(app.A2p2p,'String'));
                A1p2p = A1p1p^0.5*A2p2p^0.5;
                % set value
                set (app.A1p2p,'String',num2str(A1p2p));
            	%highlight text field
                set (app.A1p2p,'BackgroundColor',chHam);
                %highlight panel
                set (app.uipanelHam,'BackgroundColor',chHam);
                set (app.textA11,'BackgroundColor',chHam);
                set (app.textA1p1p,'BackgroundColor',chHam);
                set (app.textA22,'BackgroundColor',chHam);
                set (app.textA2p2p,'BackgroundColor',chHam);
            
            else
                % enable edid text
                set (app.A1p2p,'Enable','on')
                % load older value
                set (app.A1p2p,'String',num2str(oldA1p2p));
            % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
            	%reset edit field
            	set (app.A1p2p,'BackgroundColor',colr) ;
                if (cbA12+cbA12p+cbA13+cbA1p2+cbA1p2p+cbA1p3+cbA23+cbA2p3)==0
                    %reset panel
                    set (app.uipanelHam,'BackgroundColor',colr);
                    set (app.textA11,'BackgroundColor',colr);
                    set (app.textA1p1p,'BackgroundColor',colr);
                    set (app.textA22,'BackgroundColor',colr);
                    set (app.textA2p2p,'BackgroundColor',colr);
                    set (app.textA33,'BackgroundColor',colr);
                end
            end
        end

        % Value changed function: cbA1p3
        function cbA1p3_Callback(app, event)
            % hObject    handle to cbA1p3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbA1p3
            % highlight color
            chHam = [0.68 0.92 1.0];
            % reset color
            colr = [0.94 0.94 0.94];
            global oldA1p3
            cbA1p3=get(hObject,'Value');
            if cbA1p3
                % save older value
                oldA1p3 = str2double (get(app.A1p3,'String'));
                % disable edid text
                set (app.A1p3,'Enable','inactive')
                % calculate A1p3
                A1p1p = str2double (get(app.A1p1p,'String'));
                A33= str2double (get(app.A33,'String'));
                A1p3 = A1p1p^0.5*A33^0.5;
                % set value
                set (app.A1p3,'String',num2str(A1p3));
            %highlight text field
                set (app.A1p3,'BackgroundColor',chHam);
                %highlight panel
                set (app.uipanelHam,'BackgroundColor',chHam);
                set (app.textA11,'BackgroundColor',chHam);
                set (app.textA1p1p,'BackgroundColor',chHam);
                set (app.textA22,'BackgroundColor',chHam);
                set (app.textA2p2p,'BackgroundColor',chHam);
            
            else
                % enable edid text
                set (app.A1p3,'Enable','on')
                % load older value
                set (app.A1p3,'String',num2str(oldA1p3));
            % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
            	%reset edit field
            	set (app.A1p3,'BackgroundColor',colr) ;
                if (cbA12+cbA12p+cbA13+cbA1p2+cbA1p2p+cbA1p3+cbA23+cbA2p3)==0
                    %reset panel
                    set (app.uipanelHam,'BackgroundColor',colr);
                    set (app.textA11,'BackgroundColor',colr);
                    set (app.textA1p1p,'BackgroundColor',colr);
                    set (app.textA22,'BackgroundColor',colr);
                    set (app.textA2p2p,'BackgroundColor',colr);
                    set (app.textA33,'BackgroundColor',colr);
                end
            
            end
        end

        % Value changed function: cbA23
        function cbA23_Callback(app, event)
            % hObject    handle to cbA23 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbA23
            % highlight color
            chHam = [0.68 0.92 1.0];
            % reset color
            colr = [0.94 0.94 0.94];
            global oldA23
            cbA23=get(hObject,'Value');
            if cbA23
                % save older value
                oldA23 = str2double (get(app.A23,'String'));
                % disable edid text
                set (app.A23,'Enable','inactive')
                % calculate A1p3
                A22 = str2double (get(app.A22,'String'));
                A33= str2double (get(app.A33,'String'));
                A23 = A22^0.5*A33^0.5;
                % set value
                set (app.A23,'String',num2str(A23));
                %highlight text field
                set (app.A23,'BackgroundColor',chHam);
                %highlight panel
                set (app.uipanelHam,'BackgroundColor',chHam);
                set (app.textA11,'BackgroundColor',chHam);
                set (app.textA1p1p,'BackgroundColor',chHam);
                set (app.textA22,'BackgroundColor',chHam);
                set (app.textA2p2p,'BackgroundColor',chHam);
            
            else
                % enable edid text
                set (app.A23,'Enable','on')
                % load older value
                set (app.A23,'String',num2str(oldA23));
            	% get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
            	%reset edit field
            	set (app.A23,'BackgroundColor',colr) ;
                if (cbA12+cbA12p+cbA13+cbA1p2+cbA1p2p+cbA1p3+cbA23+cbA2p3)==0
                    %reset panel
                    set (app.uipanelHam,'BackgroundColor',colr);
                    set (app.textA11,'BackgroundColor',colr);
                    set (app.textA1p1p,'BackgroundColor',colr);
                    set (app.textA22,'BackgroundColor',colr);
                    set (app.textA2p2p,'BackgroundColor',colr);
                    set (app.textA33,'BackgroundColor',colr);
                end
            end
        end

        % Value changed function: cbA2p3
        function cbA2p3_Callback(app, event)
            % hObject    handle to cbA2p3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbA2p3
            % highlight color
            chHam = [0.68 0.92 1.0];
            % reset color
            colr = [0.94 0.94 0.94];
            global oldA2p3
            cbA2p3=get(hObject,'Value');
            if cbA2p3
                % save older value
                oldA2p3 = str2double (get(app.A2p3,'String'));
                % disable edid text
                set (app.A2p3,'Enable','inactive')
                % calculate A1p3
                A2p2p = str2double (get(app.A2p2p ,'String'));
                A33= str2double (get(app.A33,'String'));
                A2p3 = A2p2p^0.5*A33^0.5;
                % set value
                set (app.A2p3,'String',num2str(A2p3));
                %highlight text field
                set (app.A2p3,'BackgroundColor',chHam);
                %highlight panel
                set (app.uipanelHam,'BackgroundColor',chHam);
                set (app.textA11,'BackgroundColor',chHam);
                set (app.textA1p1p,'BackgroundColor',chHam);
                set (app.textA22,'BackgroundColor',chHam);
                set (app.textA2p2p,'BackgroundColor',chHam);
            
            else
                % enable edid text
                set (app.A2p3,'Enable','on')
                % load older value
                set (app.A2p3,'String',num2str(oldA2p3));
            % get values of all other checkboxes
                cbA12=get(app.cbA12,'Value');
                cbA12p=get(app.cbA12p,'Value');
                cbA13=get(app.cbA13,'Value');
                cbA1p2=get(app.A1p2,'Value');
                cbA1p2p=get(app.cbA1p2p,'Value');
                cbA1p3=get(app.cbA1p3,'Value');
                cbA23=get(app.cbA23,'Value');
                cbA2p3=get(app.cbA2p3,'Value');
            	%reset edit field
            	set (app.A2p3,'BackgroundColor',colr) ;
                if (cbA12+cbA12p+cbA13+cbA1p2+cbA1p2p+cbA1p3+cbA23+cbA2p3)==0
                    %reset panel
                    set (app.uipanelHam,'BackgroundColor',colr);
                    set (app.textA11,'BackgroundColor',colr);
                    set (app.textA1p1p,'BackgroundColor',colr);
                    set (app.textA22,'BackgroundColor',colr);
                    set (app.textA2p2p,'BackgroundColor',colr);
                    set (app.textA33,'BackgroundColor',colr);
                end
            end
        end

        % Value changed function: cbCOATED
        function cbCOATED_Callback(app, event)
            % hObject    handle to cbCOATED (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbCOATED
            global atemp
            cbCOATED=get(hObject,'Value');
            colr = [0.94 0.94 0.94];
            if cbCOATED
                %disable calculate profiles
                set(app.calculateButton,'Enable','off')
                %reset aspect
                set (app.cbCOATED,'BackgroundColor',colr)
                set (app.cbCOATED,'ForegroundColor','k');
                %disable hamaker
                set(app.A132,'Enable','off')
                %save hamaker value
                atemp = get(app.A132,'String');
                set(app.A132,'String','N/A')
                set(app.cbA132,'Enable','off')
                set(app.cbA132,'Value',0)
                set(app.uipanelCOATED,'visible','on')
                % enable pbCOATED
                set(app.pbCOATED,'Enable','on')
            
             % reset Hamaker from fundamentals panel
                set(app.uipanelA132,'BackgroundColor',colr)
                % disable edit text fields
                set(app.ve,'Enable','off')
                set(app.e1,'Enable','off')
                set(app.n1,'Enable','off')
                set(app.e2,'Enable','off')
                set(app.n2,'Enable','off')
                set(app.e3,'Enable','off')
                set(app.n3,'Enable','off')
                % reset static text
                set(app.veTEXT,'BackgroundColor',colr)
                set(app.e1TEXT,'BackgroundColor',colr)
                set(app.n1TEXT,'BackgroundColor',colr)
                set(app.e2TEXT,'BackgroundColor',colr)
                set(app.n2TEXT,'BackgroundColor',colr)
                set(app.e3TEXT,'BackgroundColor',colr)
                set(app.n3TEXT,'BackgroundColor',colr)
                set(app.A132TEXT,'BackgroundColor',colr)
                %disable calcA132 and pbCOATED buttons
                set(app.calcA132,'Enable','off')
                set(app.pbCOATED,'Enable','off')
                %activate manual input
                set (app.A132,'Enable','on');
                set (app.A132,'BackgroundColor','white');
                %deactivate calculated edit
                set (app.A132calc,'Enable','off');
                set (app.A132calc,'String','calculate');
                set (app.A132,'BackgroundColor','white');
                %check if roughness mode is active
                cbRmode1 = get(app.cbRmode1,'Value');
                cbRmode2 = get(app.cbRmode2,'Value');
                cbRmode3 = get(app.cbRmode3,'Value');
                if cbRmode1+cbRmode2+cbRmode3>0
                    % reset value
                    set(app.cbRmode0,'Value',1);
                    set(app.cbRmode1,'Value',0);
                    set(app.cbRmode2,'Value',0);
                    set(app.cbRmode3,'Value',0);
                    % indicate checkbox color change
                    set(app.cbRmode0,'ForegroundColor','r');
                    % alert user with message
                    msg_cancel_roughness
                end
                %reset asperity height value
                set(app.aasp,'String','0.0');
            else
                set(app.A132,'Enable','on')
                set(app.A132,'String',atemp)
                set(app.cbA132,'Enable','on')
                %disable pbCOATED
                set(app.pbCOATED,'Enable','off')
                set(app.uipanelCOATED,'visible','off')
                %% enable/disable calculate profiles if all conditions apply
                % initialize flag
                calcFLAG = 1;
                % get checkboxes values
                cbCOATED = get(app.cbCOATED,'Value');
                cbA132 = get(app.cbA132,'Value');
                cbgammaAB = get(app.cbgammaAB,'Value');
                cbW132 = get(app.cbW132,'Value');
                cbacont = get(app.cbacont,'Value');
                % evaluate each checkbox
                if cbCOATED+cbA132+cbgammaAB+cbW132+cbacont==0
                    %enable
                    calcFLAG =1;
                else
                    if cbA132==1
                        if isnan(str2double(get(app.A132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbgammaAB==1
                        if isnan(str2double(get(app.gammaABcalc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbW132==1
                        if isnan(str2double(get(app.W132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbacont==1
                        if isnan(str2double(get(app.acontCALC,'String')))
                            calcFLAG = 0;
                        end
                    end
                end
                if calcFLAG ==1
                    set(app.calculateButton,'Enable','on')
                else
                    set(app.calculateButton,'Enable','off')
                end
                %%
            end
        end

        % Value changed function: cbHET
        function cbHET_Callback(app, event)
            % hObject    handle to cbHET (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbHET
            global cbMEAN cbHET cbHETPLOTS
            % Hint: get(hObject,'Value') returns toggle state of cbMEAN
            cbHET=get(hObject,'Value');
            if cbHET
                set(app.cbMEAN,'Value',0)
                % enable fields
                set(app.zetahet,'Enable','on')
                set(app.rhet02,'ForegroundColor','r')
                set(app.rhet04,'ForegroundColor','r')
                set(app.rhet06,'ForegroundColor','r')
                set(app.rhet08,'ForegroundColor','r')
                % enable hetdomain plots checkbox
                set(app.cbHETPLOTS,'Visible','on')
                set(app.cbHETPLOTS,'Value',1)
            else
                set(app.cbMEAN,'Value',1)
                % disable fields
                set(app.zetahet,'Enable','off')
                set(app.rhet02,'String','calculate')
                set(app.rhet02,'ForegroundColor','k')
                set(app.rhet04,'String','calculate')
                set(app.rhet04,'ForegroundColor','k')
                set(app.rhet06,'String','calculate')
                set(app.rhet06,'ForegroundColor','k')
                set(app.rhet08,'String','calculate')
                set(app.rhet08,'ForegroundColor','k')
                % disable hetdomain plots checkbox
                set(app.cbHETPLOTS,'Visible','off')
                set(app.cbHETPLOTS,'Value',0)
            end
        end

        % Value changed function: cbMEAN
        function cbMEAN_Callback(app, event)
            % hObject    handle to cbMEAN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global cbMEAN cbHET
            % Hint: get(hObject,'Value') returns toggle state of cbMEAN
            cbMEAN=get(hObject,'Value');
            if cbMEAN
                set(app.cbHET,'Value',0)
                % disable fields
                set(app.zetahet,'Enable','off')
            else
                set(app.cbHET,'Value',1)
                % enable fields
                set(app.zetahet,'Enable','on')
            end
        end

        % Value changed function: cbRmode0
        function cbRmode0_Callback(app, event)
            global RMODE atemp
            % hObject    handle to cbRmode0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            colr =[0.94 0.94 0.94];
            % Hint: get(hObject,'Value') returns toggle state of cbRmode0
            cb=get(app.cbRmode0,'Value');
            if cb
                RMODE = 0;
                set (app.cbRmode1,'Value',0)
                set (app.cbRmode2,'Value',0)
                set (app.cbRmode3,'Value',0)
                %reset color smooth surfaces (in case coating warning happened)
                set(app.cbRmode1,'BackgroundColor',colr);
                set(app.cbRmode2,'BackgroundColor',colr);
                set(app.cbRmode3,'BackgroundColor',colr);
                % highlight to green
                set(app.cbRmode0,'BackgroundColor','g');
                %reset asperty height value
                set(app.aasp,'String','0.0');
            else
                RMODE = 1;
                set (app.cbRmode1,'Value',1)
                set (app.cbRmode1,'BackgroundColor','g')
                %reset color smooth surfaces (in case coating warning happened)
                set(app.cbRmode0,'BackgroundColor',colr);
                % save Hamaker if numerical value exist
                %% check coated sytstem status
                %save hamaker value
                if isnan(str2double(get(app.A132,'String')))
                else
                    atemp = get(app.A132,'String');
                end
                %check if coated system is selected, then reset to uncoated
                if get(app.cbCOATED,'Value')
                    % reset value coating
                    set(app.cbCOATED,'Value',0);
                    set(app.cbCOATED,'ForegroundColor','r');
                    % reset Hamaker value and enable Hamaker checkbox from fundamentals
                    set(app.A132,'String',atemp);
                    set(app.cbA132,'Enable','on');
                    % alert user with message
                    msg_cancel_coating_roughness
                end
                %%
                %check if asperity height is zero, reset ro 15 nm
                aasp = str2double(get(app.aasp,'String'));
                if aasp < 5.0e-9
                    % reset value and alert user
                    msg_set_asperity
                    set(app.aasp,'String','1.50e-8')
                end
                %%
            end
        end

        % Value changed function: cbRmode1
        function cbRmode1_Callback(app, event)
            global RMODE atemp
            % hObject    handle to cbRmode0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            colr =[0.94 0.94 0.94];
            % Hint: get(hObject,'Value') returns toggle state of cbRmode0
            cb=get(hObject,'Value');
            if cb
                RMODE = 1;
                set (app.cbRmode0,'Value',0)
                set (app.cbRmode2,'Value',0)
                set (app.cbRmode3,'Value',0)
                %reset color smooth surfaces (in case coating warning happened)
                set(app.cbRmode0,'BackgroundColor',colr);
                set(app.cbRmode2,'BackgroundColor',colr);
                set(app.cbRmode3,'BackgroundColor',colr);
                % highlight to green
                set(app.cbRmode1,'BackgroundColor','g');
                %% check coated system status
                %save hamaker value
                if isnan(str2double(get(app.A132,'String')))
                else
                    atemp = get(app.A132,'String');
                end
                %check if coated system is selected, then reset to uncoated
                if get(app.cbCOATED,'Value')
                    % reset value coating
                    set(app.cbCOATED,'Value',0);
                    set(app.cbCOATED,'ForegroundColor','r');
                    % reset Hamaker value and enable Hamaker checkbox from fundamentals
                    set(app.A132,'String',atemp);
                    set(app.cbA132,'Enable','on');
                    % alert user with message
                    msg_cancel_coating_roughness
                end
                %%
                %check if asperity height is zero, reset ro 10 nm
                aasp = str2double(get(app.aasp,'String'));
                if aasp < 5.0e-9
                    % reset value and alert user
                    msg_set_asperity
                    set(app.aasp,'String','1.0e-8')
                end
                % hide coating panel
                set(app.uipanelCOATED,'Visible','off')
                % activate profile calculation
                set(app.calculateButton,'Enable','on')
            else
                RMODE = 0;
                set (app.cbRmode0,'Value',1)
                set(app.cbRmode0,'BackgroundColor','g');
                %reset color smooth surfaces (in case coating warning happened)
                set(app.cbRmode0,'ForegroundColor','k');
                set(app.cbRmode1,'BackgroundColor',colr);
                %reset asperty height value
                set(app.aasp,'String','0.0');
            end
        end

        % Value changed function: cbRmode2
        function cbRmode2_Callback(app, event)
            global RMODE atemp
            % hObject    handle to cbRmode2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            colr = [0.94 0.94 0.94];
            % Hint: get(hObject,'Value') returns toggle state of cbRmode2
            cb=get(hObject,'Value');
            if cb
                RMODE = 2;
                set (app.cbRmode0,'Value',0)
                set (app.cbRmode1,'Value',0)
                set (app.cbRmode3,'Value',0)
                %reset color smooth surfaces (in case coating warning happened)
                set(app.cbRmode0,'BackgroundColor',colr);
                set(app.cbRmode1,'BackgroundColor',colr);
                set(app.cbRmode3,'BackgroundColor',colr);
                % highlight to green
                set(app.cbRmode2,'BackgroundColor','g');
                %% check coated sytstem status
                %save hamaker value
                if isnan(str2double(get(app.A132,'String')))
                else
                    atemp = get(app.A132,'String');
                end
                %check if coated system is selected, then reset to uncoated
                if get(app.cbCOATED,'Value')
                    % reset value coating
                    set(app.cbCOATED,'Value',0);
                    set(app.cbCOATED,'ForegroundColor','r');
                    % reset Hamaker value and enable Hamaker checkbox from fundamentals
                    set(app.A132,'String',atemp);
                    set(app.cbA132,'Enable','on');
                    % alert user with message
                    msg_cancel_coating_roughness
                end
                %%
                %check if asperity height is zero, reset ro 10 nm
                aasp = str2double(get(app.aasp,'String'));
                if aasp < 5.0e-9
                    % reset value and alert user
                    msg_set_asperity
                    set(app.aasp,'String','1.0e-8')
                end
                % hide coating panel
                set(app.uipanelCOATED,'Visible','off')
                % activate profile calculation
                set(app.calculateButton,'Enable','on')
            else
                RMODE = 0;
                set (app.cbRmode0,'Value',1)
                set(app.cbRmode0,'BackgroundColor','g');
                %reset color smooth surfaces (in case coating warning happened)
                set(app.cbRmode0,'ForegroundColor','k');
                set(app.cbRmode2,'BackgroundColor',colr);
                %reset asperty height value
                set(app.aasp,'String','0.0');
            end
        end

        % Value changed function: cbRmode3
        function cbRmode3_Callback(app, event)
            global RMODE atemp
            % hObject    handle to cbRmode0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            colr = [0.94 0.94 0.94];
            % Hint: get(hObject,'Value') returns toggle state of cbRmode0
            cb=get(hObject,'Value');
            if cb
                RMODE = 3;
                set (app.cbRmode0,'Value',0)
                set (app.cbRmode1,'Value',0)
                set (app.cbRmode2,'Value',0)
                %reset color smooth surfaces (in case coating warning happened)
                set(app.cbRmode0,'BackgroundColor',colr);
                set(app.cbRmode1,'BackgroundColor',colr);
                set(app.cbRmode2,'BackgroundColor',colr);
                % highlight to green
                set(app.cbRmode3,'BackgroundColor','g');
                %% check coated sytstem status
                %save hamaker value
                if isnan(str2double(get(app.A132,'String')))
                else
                    atemp = get(app.A132,'String');
                end
                %check if coated system is selected, then reset to uncoated
                if get(app.cbCOATED,'Value')
                    % reset value coating
                    set(app.cbCOATED,'Value',0);
                    set(app.cbCOATED,'ForegroundColor','r');
                    % reset Hamaker value and enable Hamaker checkbox from fundamentals
                    set(app.A132,'String',atemp);
                    set(app.cbA132,'Enable','on');
                    % alert user with message
                    msg_cancel_coating_roughness
                end
                %%
                %check if asperity height is zero, reset ro 10 nm
                aasp = str2double(get(app.aasp,'String'));
                if aasp < 5.0e-9
                    % reset value and alert user
                    msg_set_asperity
                    set(app.aasp,'String','1.0e-8')
                end
                % hide coating panel
                set(app.uipanelCOATED,'Visible','off')
                % activate profile calculation
                set(app.calculateButton,'Enable','on')
            else
                RMODE = 0;
                set (app.cbRmode0,'Value',1)
                set(app.cbRmode0,'BackgroundColor','g');
                set(app.cbRmode3,'BackgroundColor',colr);
                %reset asperty height value
                set(app.aasp,'String','0.0');
            end
        end

        % Value changed function: cbW132
        function cbW132_Callback(app, event)
            % hObject    handle to cbW132 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbW132
            cbW132=get(hObject,'Value');
            colh3 = 1/255*[255 100 100];
            colr = [0.94 0.94 0.94];
            if cbW132
                %disable calculate profiles
                set(app.calculateButton,'Enable','off')
                % highlight panel
                set(app.uipanelW132,'BackgroundColor',colh3')
                % enable edit text fields
                set(app.g1LW,'Enable','on')
                set(app.g2LW,'Enable','on')
                set(app.g3LW,'Enable','on')
                % highlight static text
                set(app.g1LWTEXT,'BackgroundColor',colh3)
                set(app.g2LWTEXT,'BackgroundColor',colh3)
                set(app.g3LWTEXT,'BackgroundColor',colh3)
                set(app.INDgammaABTEXT,'BackgroundColor',colh3)
                set(app.W132calcTEXT,'BackgroundColor',colh3)
                %enable calcgammaAB button
                set(app.calcW132,'Enable','on')
                %deactivate manual input
                set (app.W132,'Enable','inactive');
            else
                % reset panel
                set(app.uipanelW132,'BackgroundColor',colr)
                % disable edit text fields
                    set(app.g1LW,'Enable','off')
                set(app.g2LW,'Enable','off')
                set(app.g3LW,'Enable','off')
                set(app.INDgammaAB,'Enable','off')
                % resete static text
                set(app.g1LWTEXT,'BackgroundColor',colr)
                set(app.g2LWTEXT,'BackgroundColor',colr)
                set(app.g3LWTEXT,'BackgroundColor',colr)
                set(app.INDgammaABTEXT,'BackgroundColor',colr)
                set(app.W132calcTEXT,'BackgroundColor',colr)
                set(app.INDW132,'Enable','off')
                set(app.INDW132,'BackgroundColor',colr)
                %disable calcgammaAB button
                set(app.calcW132,'Enable','off')
                %activate manual input
                set (app.W132,'Enable','on');
                set (app.W132,'BackgroundColor','white');
                %deactivate calculated edit
                set (app.W132calc,'Enable','off');
                set (app.W132calc,'String','calculate');
                %enable calculate profiles
                set(app.calculateButton,'Enable','on')
                %% enable/disable calculate profiles if all conditions apply
                % initialize flag
                calcFLAG = 1;
                % get checkboxes values
                cbCOATED = get(app.cbCOATED,'Value');
                cbA132 = get(app.cbA132,'Value');
                cbgammaAB = get(app.cbgammaAB,'Value');
                cbW132 = get(app.cbW132,'Value');
                cbacont = get(app.cbacont,'Value');
                % evaluate each checkbox
                if cbCOATED+cbA132+cbgammaAB+cbW132+cbacont==0
                    %enable
                    calcFLAG =1;
                else
                    if cbA132==1
                        if isnan(str2double(get(app.A132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbgammaAB==1
                        if isnan(str2double(get(app.gammaABcalc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbW132==1
                        if isnan(str2double(get(app.W132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbacont==1
                        if isnan(str2double(get(app.acontCALC,'String')))
                            calcFLAG = 0;
                        end
                    end
                end
                if calcFLAG ==1
                    set(app.calculateButton,'Enable','on')
                else
                    set(app.calculateButton,'Enable','off')
                end
                %%
            end
        end

        % Value changed function: cbacont
        function cbacont_Callback(app, event)
            % hObject    handle to cbacont (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbacont
            cbacont=get(hObject,'Value');
            colh4 = 1/255*[200 250 200];
            colr = [0.94 0.94 0.94];
            if cbacont
                %disable calculate profiles
                set(app.calculateButton,'Enable','off')
                % highlight panel
                set(app.uipanelacont,'BackgroundColor',colh4')
                % enable edit text fields
                set(app.E1,'Enable','on')
                set(app.v1,'Enable','on')
                set(app.E2,'Enable','on')
                set(app.v2,'Enable','on')
            %     set(app.INDgammaAB,'Enable','inactive')
                % highlight static text
                set(app.E1TEXT,'BackgroundColor',colh4)
                set(app.v1TEXT,'BackgroundColor',colh4)
                set(app.E2TEXT,'BackgroundColor',colh4)
                set(app.v2TEXT,'BackgroundColor',colh4)
                set(app.INDW132TEXT,'BackgroundColor',colh4)
                set(app.KintTEXT,'BackgroundColor',colh4)
                set(app.acontCALCTEXT,'BackgroundColor',colh4)
                %enable calcgammaAB button
                set(app.CALCacont,'Enable','on')
                %deactivate manual input
                set (app.acont,'Enable','inactive');
            else
                % reset panel
                set(app.uipanelacont,'BackgroundColor',colr)
                % disable edit text fields
                set(app.E1,'Enable','of')
                set(app.v1,'Enable','of')
                set(app.E2,'Enable','of')
                set(app.v2,'Enable','of')
                % resete static text
                set(app.E1TEXT,'BackgroundColor',colr)
                set(app.v1TEXT,'BackgroundColor',colr)
                set(app.E2TEXT,'BackgroundColor',colr)
                set(app.v2TEXT,'BackgroundColor',colr)
                set(app.INDW132TEXT,'BackgroundColor',colr)
                set(app.KintTEXT,'BackgroundColor',colr)
                set(app.acontCALCTEXT,'BackgroundColor',colr)
                set(app.INDW132,'Enable','off')
                %disable calcgammaAB button
                set(app.CALCacont,'Enable','off')
                %activate manual input
                set (app.acont,'Enable','on');
                set (app.acont,'BackgroundColor','white');
                %deactivate calculated edit
                set (app.Kint,'Enable','off');
                set (app.Kint,'String','calculate');
                set (app.acontCALC,'Enable','off');
                set (app.acontCALC,'String','calculate');
                %enable calculate profiles
                set(app.calculateButton,'Enable','on')
                %% enable/disable calculate profiles if all conditions apply
                % initialize flag
                calcFLAG = 1;
                % get checkboxes values
                cbCOATED = get(app.cbCOATED,'Value');
                cbA132 = get(app.cbA132,'Value');
                cbgammaAB = get(app.cbgammaAB,'Value');
                cbW132 = get(app.cbW132,'Value');
                cbacont = get(app.cbacont,'Value');
                % evaluate each checkbox
                if cbCOATED+cbA132+cbgammaAB+cbW132+cbacont==0
                    %enable
                    calcFLAG =1;
                else
                    if cbA132==1
                        if isnan(str2double(get(app.A132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbgammaAB==1
                        if isnan(str2double(get(app.gammaABcalc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbW132==1
                        if isnan(str2double(get(app.W132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbacont==1
                        if isnan(str2double(get(app.acontCALC,'String')))
                            calcFLAG = 0;
                        end
                    end
                end
                if calcFLAG ==1
                    set(app.calculateButton,'Enable','on')
                else
                    set(app.calculateButton,'Enable','off')
                end
                %%
            end
        end

        % Value changed function: cbgammaAB
        function cbgammaAB_Callback(app, event)
            % hObject    handle to cbgammaAB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbgammaAB
            cbgammaAB=get(hObject,'Value');
            colh2 = [0.68 0.92 1];
            colr = [0.94 0.94 0.94];
            if cbgammaAB
                %disable calculate profiles
                set(app.calculateButton,'Enable','off')
                % highlight panel
                set(app.uipanelgammaAB,'BackgroundColor',colh2')
                % enable edit text fields
                set(app.g1pos,'Enable','on')
                set(app.g1neg,'Enable','on')
                set(app.g2pos,'Enable','on')
                set(app.g2neg,'Enable','on')
                set(app.g3pos,'Enable','on')
                set(app.g3neg,'Enable','on')
                % highlight static text
                set(app.g1posTEXT,'BackgroundColor',colh2)
                set(app.g1negTEXT,'BackgroundColor',colh2)
                set(app.g2posTEXT,'BackgroundColor',colh2)
                set(app.g2negTEXT,'BackgroundColor',colh2)
                set(app.g3posTEXT,'BackgroundColor',colh2)
                set(app.g3negTEXT,'BackgroundColor',colh2)
                set(app.gammaABTEXT,'BackgroundColor',colh2)
                %enable calcgammaAB button
                set(app.calcgammaAB,'Enable','on')
                %deactivate manual input
                set (app.gammaAB,'Enable','inactive');
            else
                % reset panel
                set(app.uipanelgammaAB,'BackgroundColor',colr)
                % disable edit text fields
                set(app.g1pos,'Enable','off')
                set(app.g1neg,'Enable','off')
                set(app.g2pos,'Enable','off')
                set(app.g2neg,'Enable','off')
                set(app.g3pos,'Enable','off')
                set(app.g3neg,'Enable','off')
                set(app.n3,'Enable','off')
                % reset static text
                set(app.g1posTEXT,'BackgroundColor',colr)
                set(app.g1negTEXT,'BackgroundColor',colr)
                set(app.g2posTEXT,'BackgroundColor',colr)
                set(app.g2negTEXT,'BackgroundColor',colr)
                set(app.g3posTEXT,'BackgroundColor',colr)
                set(app.g3negTEXT,'BackgroundColor',colr)
                set(app.gammaABTEXT,'BackgroundColor',colr)
                %disable calcgammaAB button
                set(app.calcgammaAB,'Enable','off')
                %activate manual input
                set (app.gammaAB,'Enable','on');
                set (app.gammaAB,'BackgroundColor','white');
                %deactivate calculated edit
                set (app.gammaABcalc,'Enable','off');
                set (app.gammaABcalc,'String','calculate');
                %deactivate indicator in W132 panel
                set(app.INDgammaAB,'Enable','off');
                set (app.INDgammaAB,'BackgroundColor','white');
                %enable calculate profiles
                set(app.calculateButton,'Enable','on')
                %% enable/disable calculate profiles if all conditions apply
                % initialize flag
                calcFLAG = 1;
                % get checkboxes values
                cbCOATED = get(app.cbCOATED,'Value');
                cbA132 = get(app.cbA132,'Value');
                cbgammaAB = get(app.cbgammaAB,'Value');
                cbW132 = get(app.cbW132,'Value');
                cbacont = get(app.cbacont,'Value');
                % evaluate each checkbox
                if cbCOATED+cbA132+cbgammaAB+cbW132+cbacont==0
                    %enable
                    calcFLAG =1;
                else
                    if cbA132==1
                        if isnan(str2double(get(app.A132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbgammaAB==1
                        if isnan(str2double(get(app.gammaABcalc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbW132==1
                        if isnan(str2double(get(app.W132calc,'String')))
                            calcFLAG = 0;
                        end
                    end
                    if cbacont==1
                        if isnan(str2double(get(app.acontCALC,'String')))
                            calcFLAG = 0;
                        end
                    end
                end
                if calcFLAG ==1
                    set(app.calculateButton,'Enable','on')
                else
                    set(app.calculateButton,'Enable','off')
                end
                %%
            end
        end

        % Value changed function: checkboxSP
        function checkboxSP_Callback(app, event)
            % hObject    handle to checkboxSP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkboxSP
            global SP a2temp
            SP = get(hObject,'Value');
            if SP
                set(app.checkboxSS,'Value',0);
                set(app.a2,'Enable','off');
                a2temp = get(app.a2,'String');
                set(app.a2,'String','N/A');
            else
                set(app.checkboxSS,'Value',1);
                set(app.a2,'Enable','on');
                set(app.a2,'String',a2temp);
            end
        end

        % Value changed function: checkboxSS
        function checkboxSS_Callback(app, event)
            % hObject    handle to checkboxSS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkboxSS
            global SS a2temp
            SS = get(hObject,'Value');
            if SS
                set(app.checkboxSP,'Value',0);
                set(app.a2,'Enable','on');
                set(app.a2,'String',a2temp);
            else
                set(app.checkboxSP,'Value',1);
                set(app.a2,'Enable','off');
                a2temp = get(app.a2,'String');
                set(app.a2,'String','N/A');
            
            end
        end

        % Value changed function: e1
        function e1_Callback(app, event)
            % hObject    handle to e1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of e1 as text
            %        str2double(get(hObject,'String')) returns contents of e1 as a double
            global e1
            e1 = str2double(get(hObject,'String' ));
        end

        % Value changed function: e2
        function e2_Callback(app, event)
            % hObject    handle to e2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of e2 as text
            %        str2double(get(hObject,'String')) returns contents of e2 as a double
            global e2
            e2 = str2double(get(hObject,'String' ));
        end

        % Value changed function: e3
        function e3_Callback(app, event)
            % hObject    handle to e3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of e3 as text
            %        str2double(get(hObject,'String')) returns contents of e3 as a double
            global e3
            e3 = str2double(get(hObject,'String' ));
        end

        % Value changed function: epsilonR
        function epsilonR_Callback(app, event)
            % hObject    handle to epsilonR (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of epsilonR as text
            %        str2double(get(hObject,'String')) returns contents of epsilonR as a double
            global epsilonR
            epsilonR = str2double(get(hObject,'String' ));
        end

        % Value changed function: g1neg
        function g1neg_Callback(app, event)
            % hObject    handle to g1neg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of g1neg as text
            %        str2double(get(hObject,'String')) returns contents of g1neg as a double
            global g1neg
            g1neg = str2double(get(hObject,'String' ));
        end

        % Value changed function: g1pos
        function g1pos_Callback(app, event)
            % hObject    handle to g1pos (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of g1pos as text
            %        str2double(get(hObject,'String')) returns contents of g1pos as a double
            global g1pos
            g1pos = str2double(get(hObject,'String' ));
        end

        % Value changed function: g2neg
        function g2neg_Callback(app, event)
            % hObject    handle to g2neg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of g2neg as text
            %        str2double(get(hObject,'String')) returns contents of g2neg as a double
            global g2neg
            g2neg = str2double(get(hObject,'String' ));
        end

        % Value changed function: g2pos
        function g2pos_Callback(app, event)
            % hObject    handle to g2pos (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of g2pos as text
            %        str2double(get(hObject,'String')) returns contents of g2pos as a double
            global g2pos
            g2pos = str2double(get(hObject,'String' ));
        end

        % Value changed function: g3neg
        function g3neg_Callback(app, event)
            % hObject    handle to g3neg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of g3neg as text
            %        str2double(get(hObject,'String')) returns contents of g3neg as a double
            global g3neg
            g3neg = str2double(get(hObject,'String' ));
        end

        % Value changed function: g3pos
        function g3pos_Callback(app, event)
            % hObject    handle to g3pos (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of g3pos as text
            %        str2double(get(hObject,'String')) returns contents of g3pos as a double
            global g3pos
            g3pos = str2double(get(hObject,'String' ));
        end

        % Value changed function: gammaAB
        function gammaAB_Callback(app, event)
            % hObject    handle to gammaAB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of gammaAB as text
            %        str2double(get(hObject,'String')) returns contents of gammaAB as a double
            global gammaAB
            gammaAB = str2double(get(hObject,'String' ));
            set(app.INDgammaAB,'String',num2str(gammaAB));
        end

        % Value changed function: gammaSTE
        function gammaSTE_Callback(app, event)
            % hObject    handle to gammaSTE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of gammaSTE as text
            %        str2double(get(hObject,'String')) returns contents of gammaSTE as a double
            global gammaSTE
            gammaSTE = str2double(get(hObject,'String' ));
        end

        % Value changed function: lambdaAB
        function lambdaAB_Callback(app, event)
            % hObject    handle to lambdaAB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lambdaAB as text
            %        str2double(get(hObject,'String')) returns contents of lambdaAB as a double
            global lambdaAB
            lambdaAB = str2double(get(hObject,'String' ));
        end

        % Value changed function: lambdaSTE
        function lambdaSTE_Callback(app, event)
            % hObject    handle to lambdaSTE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lambdaSTE as text
            %        str2double(get(hObject,'String')) returns contents of lambdaSTE as a double
            global lambdaSTE
            lambdaSTE = str2double(get(hObject,'String' ));
        end

        % Value changed function: lambdaVDW
        function lambdaVDW_Callback(app, event)
            % hObject    handle to lambdaVDW (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lambdaVDW as text
            %        str2double(get(hObject,'String')) returns contents of lambdaVDW as a double
            global lambdaVDW
            lambdaVDW = str2double(get(hObject,'String' ));
        end

        % Value changed function: n1
        function n1_Callback(app, event)
            % hObject    handle to n1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of n1 as text
            %        str2double(get(hObject,'String')) returns contents of n1 as a double
            global n1
            n1 = str2double(get(hObject,'String' ));
        end

        % Value changed function: n2
        function n2_Callback(app, event)
            % hObject    handle to n2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of n2 as text
            %        str2double(get(hObject,'String')) returns contents of n2 as a double
            global n2
            n2 = str2double(get(hObject,'String' ));
        end

        % Value changed function: n3
        function n3_Callback(app, event)
            % hObject    handle to n3TEXT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of n3TEXT as text
            %        str2double(get(hObject,'String')) returns contents of n3TEXT as a double
            global n3
            n3 = str2double(get(hObject,'String' ));
        end

        % Button pushed function: pbCLOSEcoated
        function pbCLOSEcoated_Callback(app, event)
            % hObject    handle to pbCLOSEcoated (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global atemp
            % check if hamaker constant contrbutions were calculated, otherwise
            % indicate user that coated system parameters will not be used and reset
            % checkbox in main panel
            % determine if coating system hamaker constant contributions were calulated
            c1 = str2double(get(app.s1A1p2p,'String'));
            c2 = str2double(get(app.s2A12p,'String'));
            c3 = str2double(get(app.s3A1p2,'String'));
            %  let user know that calculate has to be pressed before accepting values
            if isnan(c1)&&isnan(c2)&&isnan(c3)
                set(app.uipanelCOATED,'visible','off');
                set (app.cbCOATED,'Value',0);
                set (app.cbCOATED,'ForegroundColor','r');
                msg_cancel_coating;
                set(app.A132,'Enable','on');
                set(app.A132,'String',atemp);
                set(app.cbA132,'Enable','on');
            else
                set(app.uipanelCOATED,'visible','off')
                set(app.calculateButton,'Enable','on')
            end
        end

        % Button pushed function: pbCOATED
        function pbCOATED_Callback(app, event)
            % hObject    handle to pbCOATED (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % toggle coating system panel visibility
            %% maybe useful code for later on
            % x=get(app.uipanelCOATED,'visible');
            % if strcmp(x,'on')
            %     set(app.uipanelCOATED,'visible','off')
            % %     set(app.pbCOATED,'BackgroundColor','green')
            % else
            %     set(app.uipanelCOATED,'visible','on')
            % %     set(app.pbCOATED,'BackgroundColor','red')
            % end
            %% open panel and deactivate itself
            set(app.uipanelCOATED,'visible','on')
            set(app.pbCOATED,'Enable','inactive')
        end

        % Button pushed function: pbCalcCoated
        function pbCalcCoated_Callback(app, event)
            % hObject    handle to pbCalcCoated (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global system
            global T1 T2 A33
            global A11 A1p1p A22 A2p2p
            global A12 A12p A13 A1p2 A1p2p A1p3 A23 A2p3
            global s1A1p2p s1A12p s1A1p2 s1A12
            global s2A12p s2A12
            global s3A1p2 s3A12
            colr =[0.94 0.94 0.94];
            colhs = 1/255*[186 255 150];
            chHam = [0.68 0.92 1.0];
            % get type of coating system
            cb1=get(app.cb1,'Value');
            cb2=get(app.cb2,'Value');
            cb3=get(app.cb3,'Value');
            % layered colloid - layered collector
            if cb1
                system =1;
            end
            % colloid - layered collector
            if cb2
                system =2;
            end
            % layered colloid - collector
            if cb3
                system =3;
            end
            
            %% get coating thickness and fluid hamaker
            T1 = str2double(get(app.T1,'String'));
            T2 = str2double(get(app.T2,'String'));
            A33 = str2double(get(app.A33,'String'));
            %% recalculate combined Hamakers if needed otherwise load from edit texts
            if get(app.cbA12,'Value')
                A12 = (str2double(get(app.A11,'String')))^0.5*...
                   (str2double(get(app.A22,'String')))^0.5;
                set (app.A12,'BackgroundColor',chHam);
            else
                A12 = str2double(get(app.A12,'String'));
                set (app.A12,'BackgroundColor','white');
            end
            if get(app.cbA12p,'Value')
                A12p = (str2double(get(app.A11,'String')))^0.5*...
                   (str2double(get(app.A2p2p,'String')))^0.5;
                set (app.A12p,'BackgroundColor',chHam);
            else
                A12p = str2double(get(app.A12p,'String'));
                set (app.A12p,'BackgroundColor','white');
            end
            if get(app.cbA13,'Value')
                A13 = (str2double(get(app.A11,'String')))^0.5*...
                   (str2double(get(app.A33,'String')))^0.5;
                set (app.A13,'BackgroundColor',chHam);
            else
                A13 = str2double(get(app.A13,'String'));
                set (app.A13,'BackgroundColor','white');
            end
            if get(app.cbA1p2,'Value')
                A1p2 = (str2double(get(app.A1p1p,'String')))^0.5*...
                   (str2double(get(app.A22,'String')))^0.5;
                set (app.A1p2,'BackgroundColor',chHam);
            else
                A1p2 = str2double(get(app.A1p2,'String'));
                set (app.A1p2,'BackgroundColor','white');
            end
            if get(app.cbA1p2p,'Value')
                A1p2p = (str2double(get(app.A1p1p,'String')))^0.5*...
                   (str2double(get(app.A2p2p,'String')))^0.5;
                set (app.A1p2p,'BackgroundColor',chHam);
            else
                A1p2p = str2double(get(app.A1p2p,'String'));
                set (app.A1p2p,'BackgroundColor','white');
            end
            if get(app.cbA1p3,'Value')
                A1p3 = (str2double(get(app.A1p1p,'String')))^0.5*...
                   (str2double(get(app.A33,'String')))^0.5;
                set (app.A1p3,'BackgroundColor',chHam);
            else
                A1p3 = str2double(get(app.A1p3,'String'));
                set (app.A1p3,'BackgroundColor','white');
            end
            if get(app.cbA23,'Value')
                A23 = (str2double(get(app.A22,'String')))^0.5*...
                   (str2double(get(app.A33,'String')))^0.5;
                set (app.A23,'BackgroundColor',chHam);
            else
                A23 = str2double(get(app.A23,'String'));
                set (app.A23,'BackgroundColor','white');
            end
            if get(app.cbA2p3,'Value')
                A2p3 = (str2double(get(app.A2p2p,'String')))^0.5*...
                   (str2double(get(app.A33,'String')))^0.5;
                set (app.A2p3,'BackgroundColor',chHam);
            else
                A2p3 = str2double(get(app.A2p3,'String'));
                set (app.A2p3,'BackgroundColor','white');
            end
            % A11 A1p1p A22 A2p2p A33
            % A12 A12p A13 A1p2 A1p2p A1p3 A23 A2p3
            
            %% for system 1
            if system==1
                % calculate hamaker constant contributions
                s1A1p2p = A1p2p-A2p3-A1p3+A33;
                s1A12p = A12p-A1p2p-A13+A1p3;
                s1A1p2 = A1p2-A23-A1p2p+A2p3;
                s1A12 = A12-A1p2-A12p+A1p2p;
                %update fields
                set (app.s1A1p2p,'String',num2str(s1A1p2p));
                set (app.s1A12p,'String',num2str(s1A12p));
                set (app.s1A1p2,'String',num2str(s1A1p2));
                set (app.s1A12,'String',num2str(s1A12));
            else
                set (app.s1A1p2p,'String','calculate');
                set (app.s1A12p,'String','calculate');
                set (app.s1A1p2,'String','calculate');
                set (app.s1A12,'String','calculate');
            
            end
            if system==2
                % calculate hamaker constant contributions
                s2A12p = A12p-A2p3-A13+A33;
                s2A12 = A12-A23-A12p+A33;
                %update fields
                set (app.s2A12p,'String',num2str(s2A12p));
                set (app.s2A12,'String',num2str(s2A12));
            else
                set (app.s2A12p,'String','calculate');
                set (app.s2A12,'String','calculate');
            end
            if system==3
                % calculate hamaker constant contribution
                s3A1p2 = A1p2-A23-A1p3+A33;
                s3A12 = A12-A1p2-A13+A1p3;
                %update fields
                set (app.s3A1p2,'String',num2str(s3A1p2));
                set (app.s3A12,'String',num2str(s3A12));
            else
                set (app.s3A1p2,'String','calculate');
                set (app.s3A12,'String','calculate');
            end
        end

        % Button pushed function: pbLOAD
        function pbLOAD_Callback(app, event)
            % hObject    handle to pbLOAD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global inputPar
            clear inputPar
            % % set working directory
            if ~isdeployed
                % MATLAB environment
                mname = mfilename('fullpath');
                inputpath = strrep(mname,'\GUIxDLVO','\')
            %     mkdir(outputpath,OUTDIR);
            %     outputdir = strrep(mfilename('fullpath'),'\GUIxDLVO',outs);
            else
               %deployed application
                inputpath=pwd
            %     mkdir(outputpath,OUTDIR);
            %     outputdir=strcat(outputpath,outs);
            end
            inputpath1= strcat(inputpath,'*.xls');
            
            [FileName,PathName] =uigetfile(inputpath1,'Select existing output file');
            % fname = uigetdir(outputpath,'Select Output Directory');
            display('ok')
            if isequal(FileName,0)
               disp('User selected Cancel');
            else
                fullfname = strcat(PathName,FileName);
                inputPar = load_input_par(fullfname,'Input_Parameters','A1:D124');
                display('ok')
            end
            %% populate GUI fields and checkboxes
            % define format for GUI fields
            prec = '%10.5e\n';
            %% geometry
            set(app.checkboxSS,'Value',cell2mat(inputPar(2,2)))
            set(app.checkboxSP,'Value',cell2mat(inputPar(3,2)))
            %% roughness mode
            cond =cell2mat(inputPar(5,2));
            if cond==0
                set(app.cbRmode0,'Value',1)
            end
            if cond==1
                set(app.cbRmode1,'Value',1)
            end
            if cond==2
                set(app.cbRmode2,'Value',1)
            end
            if cond==3
                set(app.cbRmode3,'Value',1)
            end
            %% Main_DLVO_Parameters
            set(app.T,'String',num2str(cell2mat(inputPar(8,2)),prec))
            set(app.IS,'String',num2str(cell2mat(inputPar(9,2)),prec))
            set(app.a1,'String',num2str(cell2mat(inputPar(10,2)),prec))
            set(app.a2,'String',num2str(cell2mat(inputPar(11,2)),prec))
            set(app.zetac,'String',num2str(cell2mat(inputPar(12,2)),prec))
            set(app.zetap,'String',num2str(cell2mat(inputPar(13,2)),prec))
            set(app.z,'String',num2str(cell2mat(inputPar(14,2)),prec))
            set(app.epsilonR,'String',num2str(cell2mat(inputPar(15,2)),prec))
            set(app.lambdaVDW,'String',num2str(cell2mat(inputPar(16,2)),prec))
            set(app.sigmac,'String',num2str(cell2mat(inputPar(17,2)),prec))
            %% Extended_DLVO_Parameters
            set(app.lambdaAB,'String',num2str(cell2mat(inputPar(20,2)),prec))
            set(app.lambdaSTE,'String',num2str(cell2mat(inputPar(21,2)),prec))
            set(app.gammaSTE,'String',num2str(cell2mat(inputPar(22,2)),prec))
            set(app.aasp,'String',num2str(cell2mat(inputPar(23,2)),prec))
            %% van_der_Waals
            set(app.cbCOATED,'Value',cell2mat(inputPar(26,2)))
            set(app.A132,'String',num2str(cell2mat(inputPar(27,2)),prec))
            set(app.cbA132,'Value',cell2mat(inputPar(27,4)))
            cbA132 = get(app.cbA132,'Value');
            if cbA132
                set(app.ve,'String',num2str(cell2mat(inputPar(42,2)),prec))
                set(app.e1,'String',num2str(cell2mat(inputPar(43,2)),prec))
                set(app.n1,'String',num2str(cell2mat(inputPar(44,2)),prec))
                set(app.e2,'String',num2str(cell2mat(inputPar(45,2)),prec))
                set(app.n2,'String',num2str(cell2mat(inputPar(46,2)),prec))
                set(app.e3,'String',num2str(cell2mat(inputPar(47,2)),prec))
                set(app.n3,'String',num2str(cell2mat(inputPar(48,2)),prec))
                set(app.A132calc,'String',num2str(cell2mat(inputPar(49,2)),prec))
            end
            %% acid_base
            set(app.gammaAB,'String',num2str(cell2mat(inputPar(30,2)),prec))
            set(app.cbgammaAB,'Value',cell2mat(inputPar(30,4)))
            cbgammaAB = get(app.cbgammaAB,'Value');
            if cbgammaAB
                set(app.g1pos,'String',num2str(cell2mat(inputPar(52,2)),prec))
                set(app.g1neg,'String',num2str(cell2mat(inputPar(53,2)),prec))
                set(app.g2pos,'String',num2str(cell2mat(inputPar(54,2)),prec))
                set(app.g2neg,'String',num2str(cell2mat(inputPar(55,2)),prec))
                set(app.g3pos,'String',num2str(cell2mat(inputPar(56,2)),prec))
                set(app.g3neg,'String',num2str(cell2mat(inputPar(57,2)),prec))
                set(app.gammaABcalc,'String',num2str(cell2mat(inputPar(58,2)),prec))
            end
            %% Work_of_adhesion
            set(app.W132,'String',num2str(cell2mat(inputPar(33,2)),prec))
            set(app.cbW132,'Value',cell2mat(inputPar(33,4)))
            cbW132 = get(app.cbW132,'Value');
            if cbW132
                set(app.g1LW,'String',num2str(cell2mat(inputPar(61,2)),prec))
                set(app.g2LW,'String',num2str(cell2mat(inputPar(62,2)),prec))
                set(app.g3LW,'String',num2str(cell2mat(inputPar(63,2)),prec))
                set(app.INDgammaAB,'String',num2str(cell2mat(inputPar(64,2)),prec))
                set(app.W132calc,'String',num2str(cell2mat(inputPar(65,2)),prec))
            end
            %% Contact_radius(for_steric_interaction)
            set(app.acont,'String',num2str(cell2mat(inputPar(36,2)),prec))
            set(app.cbacont,'Value',cell2mat(inputPar(36,4)))
            cbacont = get(app.cbacont,'Value');
            if cbacont
                set(app.E1,'String',num2str(cell2mat(inputPar(68,2)),prec))
                set(app.E2,'String',num2str(cell2mat(inputPar(69,2)),prec))
                set(app.v1,'String',num2str(cell2mat(inputPar(70,2)),prec))
                set(app.v2,'String',num2str(cell2mat(inputPar(71,2)),prec))
                set(app.INDW132,'String',num2str(cell2mat(inputPar(72,2)),prec))
                set(app.Kint,'String',num2str(cell2mat(inputPar(73,2)),prec))
                set(app.acontCALC,'String',num2str(cell2mat(inputPar(74,2)),prec))
            end
            %% COATED_SYSTEMS_van_der_Waals_PARAMETERS
            cbCOATED = get(app.cbCOATED,'Value');
            if cbCOATED
                % Type_of_coated_system
                set(app.cb1,'Value',cell2mat(inputPar(80,2)))
                set(app.cb2,'Value',cell2mat(inputPar(81,2)))
                set(app.cb3,'Value',cell2mat(inputPar(82,2)))
                %Coating_thickness_and_fluid_Hamaker_constant
                set(app.T1,'String',num2str(cell2mat(inputPar(85,2)),prec))
                set(app.T2,'String',num2str(cell2mat(inputPar(86,2)),prec))
                set(app.A33,'String',num2str(cell2mat(inputPar(87,2)),prec))
                %Combined_Hamaker_constant_Coated_sytems
                set(app.A12,'String',num2str(cell2mat(inputPar(90,2)),prec))
                set(app.cbA12,'Value',cell2mat(inputPar(90,4)))
                set(app.A12p,'String',num2str(cell2mat(inputPar(91,2)),prec))
                set(app.cbA12p,'Value',cell2mat(inputPar(91,4)))
                set(app.A13,'String',num2str(cell2mat(inputPar(92,2)),prec))
                set(app.cbA13,'Value',cell2mat(inputPar(92,4)))
                set(app.A1p2,'String',num2str(cell2mat(inputPar(93,2)),prec))
                set(app.cbA1p2,'Value',cell2mat(inputPar(93,4)))
                set(app.A1p2p,'String',num2str(cell2mat(inputPar(94,2)),prec))
                set(app.cbA1p2p,'Value',cell2mat(inputPar(94,4)))
                set(app.A1p3,'String',num2str(cell2mat(inputPar(95,2)),prec))
                set(app.cbA1p3,'Value',cell2mat(inputPar(95,4)))
                set(app.A23,'String',num2str(cell2mat(inputPar(96,2)),prec))
                set(app.cbA23,'Value',cell2mat(inputPar(96,4)))
                set(app.A2p3,'String',num2str(cell2mat(inputPar(97,2)),prec))
                set(app.cbA2p3,'Value',cell2mat(inputPar(97,4)))
                %Hamaker_constants_Single_material_values
                set(app.A11,'String',num2str(cell2mat(inputPar(100,2)),prec))
                set(app.A1p1p,'String',num2str(cell2mat(inputPar(101,2)),prec))
                set(app.A22,'String',num2str(cell2mat(inputPar(102,2)),prec))
                set(app.A2p2p,'String',num2str(cell2mat(inputPar(103,2)),prec))
                %% Hamaker_constant_contributions
                %SYSTEM_Coated_colloid-Coated_collector
                set(app.s1A1p2p,'String',num2str(cell2mat(inputPar(109,2)),prec))
                set(app.s1A12p,'String',num2str(cell2mat(inputPar(110,2)),prec))
                set(app.s1A1p2,'String',num2str(cell2mat(inputPar(111,2)),prec))
                set(app.s1A12,'String',num2str(cell2mat(inputPar(112,2)),prec))
                %SYSTEM_Colloid-Coated_collector
                set(app.s2A12p,'String',num2str(cell2mat(inputPar(115,2)),prec))
                set(app.s2A12,'String',num2str(cell2mat(inputPar(116,2)),prec))
                %SYSTEM_Coated_colloid-Collector
                set(app.s3A1p2,'String',num2str(cell2mat(inputPar(119,2)),prec))
                set(app.s3A12,'String',num2str(cell2mat(inputPar(120,2)),prec))
            end
            %% SYSTEM_Heterodomain_influence
            set(app.cbHET,'Value',cell2mat(inputPar(123,2)))
            set(app.zetahet,'String',num2str(cell2mat(inputPar(124,2)),prec))
        end

        % Button pushed function: pbOKcoating
        function pbOKcoating_Callback(app, event)
            % hObject    handle to pbOKcoating (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % determine if coating system hamaker constant contributions were calulated
            c1 = str2double(get(app.s1A1p2p,'String'));
            c2 = str2double(get(app.s2A12p,'String'));
            c3 = str2double(get(app.s3A1p2,'String'));
            %  let user know that calculate has to be pressed before accepting values
            if isnan(c1)&&isnan(c2)&&isnan(c3)
                msg_not_calculated_values
            else
                set(app.uipanelCOATED,'visible','off')
                %enable calculate profiles
                set(app.calculateButton,'Enable','on')
            end
        end

        % Button pushed function: pb_setdir
        function pb_setdir_Callback(app, event)
            % hObject    handle to pb_setdir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global dname
            %% Create OUTPUT directory to write output profile, and sim parameters
            % %  create string from output directory
            % OUTDIR=get(app.outputname,'String');
            OUTDIR='Output_xDLVO';
            outs = strcat('\',OUTDIR,'\');
            % % set working directory
            if ~isdeployed
                % MATLAB environment
                mname = mfilename('fullpath');
                outputpath = strrep(mname,'\GUIxDLVO','\')
            %     mkdir(outputpath,OUTDIR);
            %     outputdir = strrep(mfilename('fullpath'),'\GUIxDLVO',outs);
            else
               %deployed application
                outputpath=pwd
            %     mkdir(outputpath,OUTDIR);
            %     outputdir=strcat(outputpath,outs);
            end
            dname = uigetdir(outputpath,'Select Output Directory');
            %% enable save button
            set(app.savebutton,'Enable','on')
        end

        % Button pushed function: ppClosePlots
        function ppClosePlots_Callback(app, event)
            % hObject    handle to ppClosePlots (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.uipanelPLOTS,'Visible','off')
            aux = get(app.cbHET,'Value');
            if aux
            %     close (2)
            end
        end

        % Button pushed function: savebutton
        function savebutton_Callback(app, event)
            % hObject    handle to savebutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % start waitbar
            h = waitbar(0,'Saving data to file');
            % output parameters
            global H EJ Ekt F dname
            global cbHET EhetT FhetT
            %% input parameters
            global  a1 a2 IS zetac zetap aasp sigmac T
            global lambdaVDW lambdaAB lambdaSTE gammaSTE epsilonR z A132
            global SP SS Rmode VDWmode
            % global vdw from fundamentals
            global ve e1 n1 e2 n2 e3 n3 A132calc
            % global vdw coated systems
            global T1 T2 A33 %coating thickness and fluid Hamaker
            global A12  A12p A13  A1p2  A1p2p A1p3 A23 A2p3 %combined Hamaker
            global A12c A12pc A13c A1p2c  A1p2pc A1p3c A23c A2p3c  %combined Hamaker calculated
            global A11 A1p1p A22 A2p2p % single materials Hamaker
            global s1A1p2p s1A12p s1A1p2 s1A12 s2A12p s2A12 s3A1p2 s3A12 % hamaker constributions
            %acid-base energy fundamentals
            global g1pos g1neg g2pos g2neg g3pos g3neg gammaABcalc gammaAB
            %work of adhesion fundamentals
            global g1LW g2LW g3LW INDgammaAB W132calc W132
            %aconr for steric fundamentals
            global E1 E2 v1 v2 INDW132  Kint acontCALC  acont
            % checkboxes
            global cbCOATED cbA132  cbgammaAB cbW132 cbacont
            global cb1 cb2 cb3
            % pass hetdomain radii
            global rhetv
            
            %% WRITE TO FILE
            filecount = 1;
            filename1 = strcat(dname,'\001_output_xDLVO.xls');
            % delete previous output file
            while exist(filename1, 'file')==2
            %   delete(filename1);
                filecount=filecount+1;
                strcount = strcat('00',num2str(filecount));
                filename1 = strcat(dname,'\',strcount,'_output_xDLVO.xls');
            end
            filename1
            %headers for Energy and Force
            col_headerE={'H(m)','E_van_der_Waals','E_EDL','E_AB','E_Born','E_Steric','E_total'};
            col_headerF={'H(m)','F_van_der_Waals','F_EDL','F_AB','F_Born','F_Steric','F_total'}; %Row cell array (for column labels)
            col_headerEhet={'H(m)','AFRACT_0.2_ZOI','AFRACT_0.4_ZOI','AFRACT_0.6_ZOI','AFRACT_0.8_ZOI'};
            col_headerFhet={'H(m)','AFRACT_0.2_ZOI','AFRACT_0.4_ZOI','AFRACT_0.6_ZOI','AFRACT_0.8_ZOI'};
            % headers with hetdomain radii
            col_headersRHETV={'Rhet_radii(m)',num2str(rhetv(1)),num2str(rhetv(2)),num2str(rhetv(3)),num2str(rhetv(4))};
            %% parameter values
            par_cell=fpar_out_xDLVO();
            waitbar(0.1,h) % show saving progress
            
            %%  write parameters
            
             %% write data
            xlswrite(filename1,par_cell,'Input_Parameters');     %Write data    %Column cell array (for row labels)
            xlswrite(filename1,col_headerE,'Energy(J)','A1');     %Write column header
            waitbar(0.25,h) % show saving progress
            xlswrite(filename1,H','Energy(J)','A2');     %Write H,as a column
            xlswrite(filename1,EJ,'Energy(J)','B2');     %Write Energy J
            waitbar(0.50,h) % show saving progress
            xlswrite(filename1,col_headerE,'Energy(kT)','A1');     %Write column header
            xlswrite(filename1,H','Energy(kT)','A2');     %Write H,as a column
            xlswrite(filename1,Ekt,'Energy(kT)','B2');     %Write Energy kt
            waitbar(0.75,h) % show saving progress
            xlswrite(filename1,col_headerF,'Force(N)','A1');     %Write column header
            xlswrite(filename1,H','Force(N)','A2');     %Write H,as a column
            xlswrite(filename1,F,'Force(N)','B2');     %Write Force N
            if cbHET
            
                xlswrite(filename1,col_headersRHETV,'HET_Energy(kT)','A1');     %Write column header1
                xlswrite(filename1,col_headerEhet,'HET_Energy(kT)','A2');     %Write column header2
                xlswrite(filename1,H','HET_Energy(kT)','A3');     %Write H,as a column
                xlswrite(filename1,EhetT,'HET_Energy(kT)','B3');     %Write Energy kt
                waitbar(0.82,h) % show saving progress
                xlswrite(filename1,col_headersRHETV,'HET_Force(N)','A1');     %Write column header1
                xlswrite(filename1,col_headerFhet,'HET_Force(N)','A2');     %Write column header2
                xlswrite(filename1,H','HET_Force(N)','A3');     %Write H,as a column
                xlswrite(filename1,FhetT,'HET_Force(N)','B3');     %Write Force N
            end
            waitbar(1.0,h) % show saving progress
            % xlswrite(filename1,row_header,'Energy','A2');      %Write row header
            % A = {'Time','Temperature'; 12,98; 13,99; 14,97};
            % sheet = 2;
            % xlRange = 'E1';
            % xlswrite(filename1,A,sheet,xlRange)
            disp('Output Complete');
            close(h)
        end

        % Value changed function: sigmac
        function sigmac_Callback(app, event)
            % hObject    handle to sigmac (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of sigmac as text
            %        str2double(get(hObject,'String')) returns contents of sigmac as a double
            global sigmac
            sigmac = str2double(get(hObject,'String' ));
        end

        % Value changed function: sliderEx
        function sliderEx_Callback(app, event)
            % hObject    handle to sliderEx (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            xl = get(app.sliderEx,'Value');
            set (app.axesE,'Xlim',[0 xl])
            set (app.Xemax,'String',num2str(xl))
        end

        % Value changed function: sliderFx
        function sliderFx_Callback(app, event)
            % hObject    handle to sliderFx (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            xl = get(app.sliderFx,'Value');
            set (app.axesF,'Xlim',[0 xl])
            set (app.Xfmax,'String',num2str(xl))
        end

        % Value changed function: ve
        function ve_Callback(app, event)
            % hObject    handle to ve (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ve as text
            %        str2double(get(hObject,'String')) returns contents of ve as a double
            global ve
            ve = str2double(get(hObject,'String' ));
        end

        % Value changed function: z
        function z_Callback(app, event)
            % hObject    handle to z (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of z as text
            %        str2double(get(hObject,'String')) returns contents of z as a double
            global z
            z = str2double(get(hObject,'String' ));
        end

        % Value changed function: zetac
        function zetac_Callback(app, event)
            % hObject    handle to zetac (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of zetac as text
            %        str2double(get(hObject,'String')) returns contents of zetac as a double
            global zetac
            zetac = str2double(get(hObject,'String' ));
        end

        % Value changed function: zetap
        function zetap_Callback(app, event)
            % hObject    handle to zetap (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of zetap as text
            %        str2double(get(hObject,'String')) returns contents of zetap as a double
            global zetap
            zetap = str2double(get(hObject,'String' ));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUIxDLVO';
            app.uipanelPLOTS = uipanel(app.figure1);
            app.uipanelPLOTS.Title = 'Energy and Force Profiles';
            app.uipanelPLOTS.Visible = 'off';
            app.axesF = uiaxes(app.uipanelPLOTS);
            app.axesE = uiaxes(app.uipanelPLOTS);
            app.ppClosePlots = uibutton(app.uipanelPLOTS, 'push');
            app.ppClosePlots.ButtonPushedFcn = createCallbackFcn(app, @ppClosePlots_Callback, true);
            app.ppClosePlots.Text = 'Close plots panel';
            app.sliderEx = uislider(app.uipanelPLOTS);
            app.sliderEx.Limits = [0 1];
            app.sliderEx.MajorTicks = [];
            app.sliderEx.ValueChangedFcn = createCallbackFcn(app, @sliderEx_Callback, true);
            app.sliderEx.MinorTicks = [];
            app.text117 = uilabel(app.uipanelPLOTS);
            app.text117.Text = 'Use slider or set Xaxis max value (nm)';
            app.Xemax = uieditfield(app.uipanelPLOTS, 'text');
            app.Xemax.ValueChangedFcn = createCallbackFcn(app, @Xemax_Callback, true);
            app.Xemax.Value = 'Edit Text';
            app.sliderFx = uislider(app.uipanelPLOTS);
            app.sliderFx.Limits = [0 1];
            app.sliderFx.MajorTicks = [];
            app.sliderFx.ValueChangedFcn = createCallbackFcn(app, @sliderFx_Callback, true);
            app.sliderFx.MinorTicks = [];
            app.Xfmax = uieditfield(app.uipanelPLOTS, 'text');
            app.Xfmax.ValueChangedFcn = createCallbackFcn(app, @Xfmax_Callback, true);
            app.Xfmax.Value = 'Edit Text';
            app.text121 = uilabel(app.uipanelPLOTS);
            app.text121.Text = 'Use slider or set Xaxis max value (nm)';
            app.text122 = uilabel(app.uipanelPLOTS);
            app.text122.Text = 'Set Yaxis max value (kt)';
            app.Yemax = uieditfield(app.uipanelPLOTS, 'text');
            app.Yemax.ValueChangedFcn = createCallbackFcn(app, @Yemax_Callback, true);
            app.Yemax.Value = 'Edit Text';
            app.text123 = uilabel(app.uipanelPLOTS);
            app.text123.Text = 'Set Yaxis min value (kt)';
            app.Yemin = uieditfield(app.uipanelPLOTS, 'text');
            app.Yemin.ValueChangedFcn = createCallbackFcn(app, @Yemin_Callback, true);
            app.Yemin.Value = 'Edit Text';
            app.text124 = uilabel(app.uipanelPLOTS);
            app.text124.Text = 'Set Yaxis max value (N)';
            app.Yfmax = uieditfield(app.uipanelPLOTS, 'text');
            app.Yfmax.ValueChangedFcn = createCallbackFcn(app, @Yfmax_Callback, true);
            app.Yfmax.Value = 'Edit Text';
            app.text125 = uilabel(app.uipanelPLOTS);
            app.text125.Text = 'Set Yaxis min value (N)';
            app.Yfmin = uieditfield(app.uipanelPLOTS, 'text');
            app.Yfmin.ValueChangedFcn = createCallbackFcn(app, @Yfmin_Callback, true);
            app.Yfmin.Value = 'Edit Text';
            app.uipanel34 = uipanel(app.figure1);
            app.uipanel35 = uipanel(app.uipanel34);
            app.uipanel35.Title = 'xDLVO Profiles';
            app.calculateButton = uibutton(app.uipanel35, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButton_Callback, true);
            app.calculateButton.Text = 'Calculate Profiles';
            app.cbHET = uicheckbox(app.uipanel35);
            app.cbHET.ValueChangedFcn = createCallbackFcn(app, @cbHET_Callback, true);
            app.cbHET.Text = 'Over heterodomain';
            app.cbMEAN = uicheckbox(app.uipanel35);
            app.cbMEAN.ValueChangedFcn = createCallbackFcn(app, @cbMEAN_Callback, true);
            app.cbMEAN.Text = 'Over  mean-field surface';
            app.cbMEAN.Value = true;
            app.text458 = uilabel(app.uipanel35);
            app.text458.Text = 'Heterodomain zeta potential (zhet) (V)';
            app.zetahet = uieditfield(app.uipanel35, 'text');
            app.zetahet.Enable = 'off';
            app.zetahet.Value = '0.051';
            app.text127 = uilabel(app.uipanel35);
            app.text127.Text = 'Heterodomain radii (rhet) (m) for corresponding ZOI areal fractions';
            app.rZOI = uilabel(app.uipanel35);
            app.rZOI.Text = 'calculate';
            app.text129 = uilabel(app.uipanel35);
            app.text129.Text = 'ZOI radius (rZOI) (m)';
            app.savebutton = uibutton(app.uipanel35, 'push');
            app.savebutton.ButtonPushedFcn = createCallbackFcn(app, @savebutton_Callback, true);
            app.savebutton.Enable = 'off';
            app.savebutton.Text = 'Save to file';
            app.pb_setdir = uibutton(app.uipanel35, 'push');
            app.pb_setdir.ButtonPushedFcn = createCallbackFcn(app, @pb_setdir_Callback, true);
            app.pb_setdir.Enable = 'off';
            app.pb_setdir.Text = 'Set Output Directory';
            app.rhet02 = uilabel(app.uipanel35);
            app.rhet02.Text = 'calculate';
            app.rhet04 = uilabel(app.uipanel35);
            app.rhet04.Text = 'calculate';
            app.rhet06 = uilabel(app.uipanel35);
            app.rhet06.Text = 'calculate';
            app.rhet08 = uilabel(app.uipanel35);
            app.rhet08.Text = 'calculate';
            app.text133 = uilabel(app.uipanel35);
            app.text133.Text = '0.2 ZOI';
            app.text134 = uilabel(app.uipanel35);
            app.text134.Text = '0.4 ZOI';
            app.text135 = uilabel(app.uipanel35);
            app.text135.Text = '0.8 ZOI';
            app.text136 = uilabel(app.uipanel35);
            app.text136.Text = '0.6 ZOI';
            app.pbLOAD = uibutton(app.uipanel34, 'push');
            app.pbLOAD.ButtonPushedFcn = createCallbackFcn(app, @pbLOAD_Callback, true);
            app.pbLOAD.Text = 'Load Parameters from output file';
            app.uipanel30 = uipanel(app.figure1);
            app.uipanel30.Title = 'Authors';
            app.text109 = uilabel(app.uipanel30);
            app.text109.Text = 'Software developed by E. Pazmiño** & S. Jurado**';
            app.text110 = uilabel(app.uipanel30);
            app.text110.Text = 'Source code developed  in W.P. Johnson Research Group: ';
            app.text111 = uilabel(app.uipanel30);
            app.text111.Text = '*University of Utah,Salt Lake City, Utah, USA';
            app.text112 = uilabel(app.uipanel30);
            app.text112.Text = '**Escuela Politécnica Nacional - Quito, Ecuador';
            app.text113 = uilabel(app.uipanel30);
            app.text113.Text = 'www.wpjohnsongroup.utah.edu';
            app.text114 = uilabel(app.uipanel30);
            app.text114.Text = {'W.P. Johnson* E. Pazmiño** '; 'K. VanNess* C. Ron * A. Rasmuson*'};
            app.text48 = uilabel(app.uipanel30);
            app.text48.Text = 'v 0.1.7';
            app.uipanelA132 = uipanel(app.figure1);
            app.uipanelA132.Title = 'Hamaker constant parameters';
            app.ve = uieditfield(app.uipanelA132, 'text');
            app.ve.ValueChangedFcn = createCallbackFcn(app, @ve_Callback, true);
            app.ve.Enable = 'off';
            app.ve.Value = '2.04e15';
            app.veTEXT = uilabel(app.uipanelA132);
            app.veTEXT.Text = 'Main electronic absorption frequency (ve) (s-1)';
            app.e1 = uieditfield(app.uipanelA132, 'text');
            app.e1.ValueChangedFcn = createCallbackFcn(app, @e1_Callback, true);
            app.e1.Enable = 'off';
            app.e1.Value = '2.55';
            app.e1TEXT = uilabel(app.uipanelA132);
            app.e1TEXT.Text = 'Colloid dielectric constant (e1) (-)';
            app.n1 = uieditfield(app.uipanelA132, 'text');
            app.n1.ValueChangedFcn = createCallbackFcn(app, @n1_Callback, true);
            app.n1.Enable = 'off';
            app.n1.Value = '1.557';
            app.n1TEXT = uilabel(app.uipanelA132);
            app.n1TEXT.Text = 'Colloid refractive index (n1) (-)';
            app.e2 = uieditfield(app.uipanelA132, 'text');
            app.e2.ValueChangedFcn = createCallbackFcn(app, @e2_Callback, true);
            app.e2.Enable = 'off';
            app.e2.Value = '3.8';
            app.e2TEXT = uilabel(app.uipanelA132);
            app.e2TEXT.Text = 'Collector dielectric constant (e2) (-)';
            app.n2 = uieditfield(app.uipanelA132, 'text');
            app.n2.ValueChangedFcn = createCallbackFcn(app, @n2_Callback, true);
            app.n2.Enable = 'off';
            app.n2.Value = '1.448';
            app.n2TEXT = uilabel(app.uipanelA132);
            app.n2TEXT.Text = 'Collector refractive index (n2) (-)';
            app.e3 = uieditfield(app.uipanelA132, 'text');
            app.e3.ValueChangedFcn = createCallbackFcn(app, @e3_Callback, true);
            app.e3.Enable = 'off';
            app.e3.Value = '80';
            app.e3TEXT = uilabel(app.uipanelA132);
            app.e3TEXT.Text = ' Fluid dielectric constant (e3) (-)';
            app.n3 = uieditfield(app.uipanelA132, 'text');
            app.n3.ValueChangedFcn = createCallbackFcn(app, @n3_Callback, true);
            app.n3.Enable = 'off';
            app.n3.Value = '1.333';
            app.n3TEXT = uilabel(app.uipanelA132);
            app.n3TEXT.Text = 'Fluid refractive index (n3) (-)';
            app.A132calc = uieditfield(app.uipanelA132, 'text');
            app.A132calc.ValueChangedFcn = createCallbackFcn(app, @A132calc_Callback, true);
            app.A132calc.Enable = 'off';
            app.A132calc.Value = 'calculate';
            app.A132TEXT = uilabel(app.uipanelA132);
            app.A132TEXT.Text = 'Hamaker constant (ve  equivalent)  (J)';
            app.calcA132 = uibutton(app.uipanelA132, 'push');
            app.calcA132.ButtonPushedFcn = createCallbackFcn(app, @calcA132_Callback, true);
            app.calcA132.Enable = 'off';
            app.calcA132.Text = 'Calculate';
            app.uipanelgammaAB = uipanel(app.figure1);
            app.uipanelgammaAB.Title = 'Acid-base surface energy components ';
            app.calcgammaAB = uibutton(app.uipanelgammaAB, 'push');
            app.calcgammaAB.ButtonPushedFcn = createCallbackFcn(app, @calcgammaAB_Callback, true);
            app.calcgammaAB.Enable = 'off';
            app.calcgammaAB.Text = 'Calculate';
            app.gammaABTEXT = uilabel(app.uipanelgammaAB);
            app.gammaABTEXT.Text = {'Acid-base energy at minimum '; 'separation distance (J/m2)'};
            app.gammaABcalc = uieditfield(app.uipanelgammaAB, 'text');
            app.gammaABcalc.Enable = 'off';
            app.gammaABcalc.Value = 'calculate';
            app.g3negTEXT = uilabel(app.uipanelgammaAB);
            app.g3negTEXT.Text = 'Fluid electron donor (g3neg) (J/m2)';
            app.g3neg = uieditfield(app.uipanelgammaAB, 'text');
            app.g3neg.ValueChangedFcn = createCallbackFcn(app, @g3neg_Callback, true);
            app.g3neg.Enable = 'off';
            app.g3neg.Value = '25.50e-3';
            app.g3posTEXT = uilabel(app.uipanelgammaAB);
            app.g3posTEXT.Text = 'Fluid electron acceptor (g3pos) (J/m2)';
            app.g3pos = uieditfield(app.uipanelgammaAB, 'text');
            app.g3pos.ValueChangedFcn = createCallbackFcn(app, @g3pos_Callback, true);
            app.g3pos.Enable = 'off';
            app.g3pos.Value = '25.50e-3';
            app.g2negTEXT = uilabel(app.uipanelgammaAB);
            app.g2negTEXT.Text = 'Collector electron donor (g2neg)  (J/m2)';
            app.g2neg = uieditfield(app.uipanelgammaAB, 'text');
            app.g2neg.ValueChangedFcn = createCallbackFcn(app, @g2neg_Callback, true);
            app.g2neg.Enable = 'off';
            app.g2neg.Value = '37.50e-3';
            app.g2posTEXT = uilabel(app.uipanelgammaAB);
            app.g2posTEXT.Text = 'Collector electron acceptor (g2pos) (J/m2)';
            app.g2pos = uieditfield(app.uipanelgammaAB, 'text');
            app.g2pos.ValueChangedFcn = createCallbackFcn(app, @g2pos_Callback, true);
            app.g2pos.Enable = 'off';
            app.g2pos.Value = '1.00e-4';
            app.g1negTEXT = uilabel(app.uipanelgammaAB);
            app.g1negTEXT.Text = 'Colloid electron donor (g1neg) (J/m2)';
            app.g1neg = uieditfield(app.uipanelgammaAB, 'text');
            app.g1neg.ValueChangedFcn = createCallbackFcn(app, @g1neg_Callback, true);
            app.g1neg.Enable = 'off';
            app.g1neg.Value = '1.10e-3';
            app.g1posTEXT = uilabel(app.uipanelgammaAB);
            app.g1posTEXT.Text = 'Colloid electron acceptor (g1pos) (J/m2)';
            app.g1pos = uieditfield(app.uipanelgammaAB, 'text');
            app.g1pos.ValueChangedFcn = createCallbackFcn(app, @g1pos_Callback, true);
            app.g1pos.Enable = 'off';
            app.g1pos.Value = '0.0';
            app.uipanelW132 = uipanel(app.figure1);
            app.uipanelW132.Title = 'Work of adhesion (for contact area)';
            app.g1LW = uieditfield(app.uipanelW132, 'text');
            app.g1LW.Enable = 'off';
            app.g1LW.Value = '42.00e-3';
            app.g2LW = uieditfield(app.uipanelW132, 'text');
            app.g2LW.Enable = 'off';
            app.g2LW.Value = '27.30e-3';
            app.g2LWTEXT = uilabel(app.uipanelW132);
            app.g2LWTEXT.Text = 'Collector van der Waals free energy (g2LW) (J/m2)';
            app.g3LW = uieditfield(app.uipanelW132, 'text');
            app.g3LW.Enable = 'off';
            app.g3LW.Value = '21.80e-3';
            app.g3LWTEXT = uilabel(app.uipanelW132);
            app.g3LWTEXT.Text = 'Fluid van der Waals free energy (g3LW) (J/m2)';
            app.W132calc = uieditfield(app.uipanelW132, 'text');
            app.W132calc.Enable = 'off';
            app.W132calc.Value = 'calculate';
            app.W132calcTEXT = uilabel(app.uipanelW132);
            app.W132calcTEXT.Text = 'Work of adhesion (W132) (J/m2)';
            app.calcW132 = uibutton(app.uipanelW132, 'push');
            app.calcW132.ButtonPushedFcn = createCallbackFcn(app, @calcW132_Callback, true);
            app.calcW132.Enable = 'off';
            app.calcW132.Text = 'Calculate';
            app.g1LWTEXT = uilabel(app.uipanelW132);
            app.g1LWTEXT.Text = 'Colloid van der Waals free energy (g1LW) (J/m2)';
            app.INDgammaAB = uieditfield(app.uipanelW132, 'text');
            app.INDgammaAB.Enable = 'off';
            app.INDgammaAB.Value = '-0.0270305';
            app.INDgammaABTEXT = uilabel(app.uipanelW132);
            app.INDgammaABTEXT.Text = {'Acid-base energy at minimum '; 'separation distance (J/m2)'};
            app.text47 = uilabel(app.figure1);
            app.text47.Text = 'Parti-Suite: xDLVO';
            app.uipanelacont = uipanel(app.figure1);
            app.uipanelacont.Title = 'Contact Radius (for steric interaction)';
            app.CALCacont = uibutton(app.uipanelacont, 'push');
            app.CALCacont.ButtonPushedFcn = createCallbackFcn(app, @CALCacont_Callback, true);
            app.CALCacont.Enable = 'off';
            app.CALCacont.Text = 'Calculate';
            app.acontCALCTEXT = uilabel(app.uipanelacont);
            app.acontCALCTEXT.Text = 'Contact Radius (acont)  (m)';
            app.acontCALC = uieditfield(app.uipanelacont, 'text');
            app.acontCALC.Enable = 'off';
            app.acontCALC.Value = 'calculate';
            app.KintTEXT = uilabel(app.uipanelacont);
            app.KintTEXT.Text = 'Combined elastic modulus (Kint) (N/m2)';
            app.Kint = uieditfield(app.uipanelacont, 'text');
            app.Kint.Enable = 'off';
            app.Kint.Value = 'calculate';
            app.INDW132TEXT = uilabel(app.uipanelacont);
            app.INDW132TEXT.Text = 'Work of adhesion (W132) (J/m2))';
            app.INDW132 = uieditfield(app.uipanelacont, 'text');
            app.INDW132.Enable = 'off';
            app.INDW132.Value = '-0.028448';
            app.v2TEXT = uilabel(app.uipanelacont);
            app.v2TEXT.Text = 'Collector Poison''s ratio (v2) (-))';
            app.v2 = uieditfield(app.uipanelacont, 'text');
            app.v2.Enable = 'off';
            app.v2.Value = '0.22';
            app.E2TEXT = uilabel(app.uipanelacont);
            app.E2TEXT.Text = 'Collector Young''s modulus (E2) (N/m2)';
            app.E2 = uieditfield(app.uipanelacont, 'text');
            app.E2.Enable = 'off';
            app.E2.Value = '7.3e10';
            app.v1TEXT = uilabel(app.uipanelacont);
            app.v1TEXT.Text = 'Colloid Poison''s ratio (v1) (-)';
            app.v1 = uieditfield(app.uipanelacont, 'text');
            app.v1.Enable = 'off';
            app.v1.Value = '0.33';
            app.E1TEXT = uilabel(app.uipanelacont);
            app.E1TEXT.Text = 'Colloid Young''s modulus (E1) (N/m2)';
            app.E1 = uieditfield(app.uipanelacont, 'text');
            app.E1.Enable = 'off';
            app.E1.Value = '3.0e9';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Main parameters (default = CML-water-silica @ pH 6.7, IS 6 mM)';
            app.a1 = uieditfield(app.uipanel3, 'text');
            app.a1.ValueChangedFcn = createCallbackFcn(app, @a1_Callback, true);
            app.a1.Value = '2.2e-6';
            app.text2 = uilabel(app.uipanel3);
            app.text2.Text = 'Colloid radius (a1) (m)';
            app.a2 = uieditfield(app.uipanel3, 'text');
            app.a2.ValueChangedFcn = createCallbackFcn(app, @a2_Callback, true);
            app.a2.Value = '2.55e-4';
            app.text4 = uilabel(app.uipanel3);
            app.text4.Text = 'Collector radius (a2) (m)';
            app.IS = uieditfield(app.uipanel3, 'text');
            app.IS.ValueChangedFcn = createCallbackFcn(app, @IS_Callback, true);
            app.IS.Value = '6.0';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Text = 'Ionic strength (IS) (mol/m3)';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Text = 'Colloid zeta potential (z1) (V)';
            app.zetac = uieditfield(app.uipanel3, 'text');
            app.zetac.ValueChangedFcn = createCallbackFcn(app, @zetac_Callback, true);
            app.zetac.Value = '-0.065';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Text = 'Collector zeta potential (z2) (V)';
            app.zetap = uieditfield(app.uipanel3, 'text');
            app.zetap.ValueChangedFcn = createCallbackFcn(app, @zetap_Callback, true);
            app.zetap.Value = '-0.070';
            app.text11 = uilabel(app.uipanel3);
            app.text11.Text = 'Born collision diameter (sigmac) (m)';
            app.sigmac = uieditfield(app.uipanel3, 'text');
            app.sigmac.ValueChangedFcn = createCallbackFcn(app, @sigmac_Callback, true);
            app.sigmac.Value = '3.0e-10';
            app.T = uieditfield(app.uipanel3, 'text');
            app.T.ValueChangedFcn = createCallbackFcn(app, @T_Callback, true);
            app.T.Value = '293.15';
            app.text13 = uilabel(app.uipanel3);
            app.text13.Text = 'Temperature (T) (Kelvin)';
            app.lambdaVDW = uieditfield(app.uipanel3, 'text');
            app.lambdaVDW.ValueChangedFcn = createCallbackFcn(app, @lambdaVDW_Callback, true);
            app.lambdaVDW.Value = '1.0e-7';
            app.text14 = uilabel(app.uipanel3);
            app.text14.Text = 'vdW characterisitic wavelength (lambdaVDW) (m)';
            app.epsilonR = uieditfield(app.uipanel3, 'text');
            app.epsilonR.ValueChangedFcn = createCallbackFcn(app, @epsilonR_Callback, true);
            app.epsilonR.Value = '80.0';
            app.text18 = uilabel(app.uipanel3);
            app.text18.Text = 'Relative permittivity of water (epsilonR) (-)';
            app.z = uieditfield(app.uipanel3, 'text');
            app.z.ValueChangedFcn = createCallbackFcn(app, @z_Callback, true);
            app.z.Value = '1';
            app.text19 = uilabel(app.uipanel3);
            app.text19.Text = 'Valence of the symmetric electrolyte (z) (-)';
            app.uipanel11 = uipanel(app.uipanel3);
            app.uipanel11.Title = 'Acid-Base energy at minimum separation distance (gammaAB) (J/m2)';
            app.cbgammaAB = uicheckbox(app.uipanel11);
            app.cbgammaAB.ValueChangedFcn = createCallbackFcn(app, @cbgammaAB_Callback, true);
            app.cbgammaAB.Text = 'Calculate from fundamentals';
            app.gammaAB = uieditfield(app.uipanel11, 'text');
            app.gammaAB.ValueChangedFcn = createCallbackFcn(app, @gammaAB_Callback, true);
            app.gammaAB.Value = '-0.0270305';
            app.uipanel12 = uipanel(app.uipanel3);
            app.uipanel12.Title = 'Work of adhesion (W132) (J/m2)';
            app.cbW132 = uicheckbox(app.uipanel12);
            app.cbW132.ValueChangedFcn = createCallbackFcn(app, @cbW132_Callback, true);
            app.cbW132.Text = 'Calculate from fundamentals';
            app.W132 = uieditfield(app.uipanel12, 'text');
            app.W132.ValueChangedFcn = createCallbackFcn(app, @W132_Callback, true);
            app.W132.Value = '-0.029';
            app.uipanel13 = uipanel(app.uipanel3);
            app.uipanel13.Title = 'Roughness mode';
            app.cbRmode0 = uicheckbox(app.uipanel13);
            app.cbRmode0.ValueChangedFcn = createCallbackFcn(app, @cbRmode0_Callback, true);
            app.cbRmode0.Text = 'Smooth Surfaces';
            app.cbRmode0.Value = true;
            app.cbRmode2 = uicheckbox(app.uipanel13);
            app.cbRmode2.ValueChangedFcn = createCallbackFcn(app, @cbRmode2_Callback, true);
            app.cbRmode2.Text = 'Rough Collector';
            app.cbRmode1 = uicheckbox(app.uipanel13);
            app.cbRmode1.ValueChangedFcn = createCallbackFcn(app, @cbRmode1_Callback, true);
            app.cbRmode1.Text = 'Rough Colloid';
            app.cbRmode3 = uicheckbox(app.uipanel13);
            app.cbRmode3.ValueChangedFcn = createCallbackFcn(app, @cbRmode3_Callback, true);
            app.cbRmode3.Text = 'Rough Colloid & Collector';
            app.uipanel16 = uipanel(app.uipanel3);
            app.uipanel16.Title = 'Contact Radius (acont) (m)';
            app.cbacont = uicheckbox(app.uipanel16);
            app.cbacont.ValueChangedFcn = createCallbackFcn(app, @cbacont_Callback, true);
            app.cbacont.Text = 'Calculate from fundamentals';
            app.acont = uieditfield(app.uipanel16, 'text');
            app.acont.ValueChangedFcn = createCallbackFcn(app, @acont_Callback, true);
            app.acont.Value = '5.0e-8';
            app.uipanel21 = uipanel(app.uipanel3);
            app.uipanel21.Title = 'van der Waals';
            app.uipanelUNCOATED = uipanel(app.uipanel21);
            app.uipanelUNCOATED.Title = 'Hamaker constant (A132) (J)';
            app.cbA132 = uicheckbox(app.uipanelUNCOATED);
            app.cbA132.ValueChangedFcn = createCallbackFcn(app, @cbA132_Callback, true);
            app.cbA132.Text = 'Calculate from fundamentals';
            app.A132 = uieditfield(app.uipanelUNCOATED, 'text');
            app.A132.ValueChangedFcn = createCallbackFcn(app, @A132_Callback, true);
            app.A132.Value = '7.17e-21';
            app.cbCOATED = uicheckbox(app.uipanel21);
            app.cbCOATED.ValueChangedFcn = createCallbackFcn(app, @cbCOATED_Callback, true);
            app.cbCOATED.Text = 'Coated System';
            app.pbCOATED = uibutton(app.uipanel21, 'push');
            app.pbCOATED.ButtonPushedFcn = createCallbackFcn(app, @pbCOATED_Callback, true);
            app.pbCOATED.Enable = 'off';
            app.pbCOATED.Visible = 'off';
            app.pbCOATED.Text = '...';
            app.uipanel22 = uipanel(app.uipanel3);
            app.uipanel22.Title = 'Extended DLVO parameters';
            app.text9 = uilabel(app.uipanel22);
            app.text9.Text = 'Asperity height above mean surface (aasp) (m)';
            app.aasp = uieditfield(app.uipanel22, 'text');
            app.aasp.ValueChangedFcn = createCallbackFcn(app, @aasp_Callback, true);
            app.aasp.Value = '0.0';
            app.text10 = uilabel(app.uipanel22);
            app.text10.Text = '# of interactions per asperity (Nco) (1-4)';
            app.Nco = uieditfield(app.uipanel22, 'text');
            app.Nco.ValueChangedFcn = createCallbackFcn(app, @Nco_Callback, true);
            app.Nco.Value = '2.5';
            app.lambdaAB = uieditfield(app.uipanel22, 'text');
            app.lambdaAB.ValueChangedFcn = createCallbackFcn(app, @lambdaAB_Callback, true);
            app.lambdaAB.Value = '6.0e-10';
            app.text15 = uilabel(app.uipanel22);
            app.text15.Text = 'Lewis acid-base decay length (lambdaAB) (m)';
            app.lambdaSTE = uieditfield(app.uipanel22, 'text');
            app.lambdaSTE.ValueChangedFcn = createCallbackFcn(app, @lambdaSTE_Callback, true);
            app.lambdaSTE.Value = '4.1e-10';
            app.text16 = uilabel(app.uipanel22);
            app.text16.Text = 'Steric decay length (lambdaSTE) (m)';
            app.gammaSTE = uieditfield(app.uipanel22, 'text');
            app.gammaSTE.ValueChangedFcn = createCallbackFcn(app, @gammaSTE_Callback, true);
            app.gammaSTE.Value = '1.7e-2';
            app.text17 = uilabel(app.uipanel22);
            app.text17.Text = 'Steric energy at minimum separation distance (gammaSTE) (J/m2)';
            app.uipanel4 = uipanel(app.uipanel3);
            app.uipanel4.Title = 'Geometry';
            app.text76 = uilabel(app.uipanel4);
            app.text76.Text = {'Sphere'; 'Plate'};
            app.text75 = uilabel(app.uipanel4);
            app.text75.Text = {'Sphere'; 'Sphere'};
            app.checkboxSP = uicheckbox(app.uipanel4);
            app.checkboxSP.ValueChangedFcn = createCallbackFcn(app, @checkboxSP_Callback, true);
            app.checkboxSP.Text = '';
            app.checkboxSS = uicheckbox(app.uipanel4);
            app.checkboxSS.ValueChangedFcn = createCallbackFcn(app, @checkboxSS_Callback, true);
            app.checkboxSS.Text = '';
            app.checkboxSS.Value = true;
            app.uipanelCOATED = uipanel(app.figure1);
            app.uipanelCOATED.Title = 'Coated System';
            app.uipanelCOATED.Visible = 'off';
            app.uipanel17 = uipanel(app.uipanelCOATED);
            app.uipanel17.Title = 'Type of Coated System';
            app.cb2 = uicheckbox(app.uipanel17);
            app.cb2.ValueChangedFcn = createCallbackFcn(app, @cb2_Callback, true);
            app.cb2.Text = 'Colloid - Coated Collector';
            app.cb3 = uicheckbox(app.uipanel17);
            app.cb3.ValueChangedFcn = createCallbackFcn(app, @cb3_Callback, true);
            app.cb3.Text = 'Coated Colloid -  Collector';
            app.cb1 = uicheckbox(app.uipanel17);
            app.cb1.ValueChangedFcn = createCallbackFcn(app, @cb1_Callback, true);
            app.cb1.Text = 'Coated Colloid - Coated Collector';
            app.cb1.Value = true;
            app.uipanel18 = uipanel(app.uipanelCOATED);
            app.uipanel18.Title = 'Coating thickness  and Fluid Hamaker constant';
            app.T1 = uieditfield(app.uipanel18, 'text');
            app.T1.Value = '2.30e-9';
            app.text60 = uilabel(app.uipanel18);
            app.text60.Text = 'Colloid coating thickness (T1) (m)';
            app.T2 = uieditfield(app.uipanel18, 'text');
            app.T2.Value = '1.0e-7';
            app.text61 = uilabel(app.uipanel18);
            app.text61.Text = 'Collector coating thickness (T2) (m)';
            app.A33 = uieditfield(app.uipanel18, 'text');
            app.A33.Value = '3.70e-20';
            app.textA33 = uilabel(app.uipanel18);
            app.textA33.Text = 'Fluid Hamaker Constant (A33) (J)';
            app.uipanel19 = uipanel(app.uipanelCOATED);
            app.uipanel19.Title = 'Combined Hamaker constant - Coated system';
            app.A12 = uieditfield(app.uipanel19, 'text');
            app.A12.Value = '0.0';
            app.text63 = uilabel(app.uipanel19);
            app.text63.Text = 'Colloid - Collector (A12) (J)';
            app.A12p = uieditfield(app.uipanel19, 'text');
            app.A12p.Value = '0.0';
            app.text64 = uilabel(app.uipanel19);
            app.text64.Text = 'Colloid - Collector Coating (A12p) (J)';
            app.A13 = uieditfield(app.uipanel19, 'text');
            app.A13.Value = '0.0';
            app.text65 = uilabel(app.uipanel19);
            app.text65.Text = 'Colloid - Fluid (A13) (J)';
            app.A1p2 = uieditfield(app.uipanel19, 'text');
            app.A1p2.Value = '0.0';
            app.text66 = uilabel(app.uipanel19);
            app.text66.Text = 'Colloid Coating - Collector  (A1p2) (J)';
            app.A1p2p = uieditfield(app.uipanel19, 'text');
            app.A1p2p.Value = '0.0';
            app.text67 = uilabel(app.uipanel19);
            app.text67.Text = 'Colloid Coating - Collector Coating (A1p2p) (J)';
            app.A1p3 = uieditfield(app.uipanel19, 'text');
            app.A1p3.Value = '0.0';
            app.text68 = uilabel(app.uipanel19);
            app.text68.Text = 'Colloid Coating - Fluid  (A1p3) (J)';
            app.text69 = uilabel(app.uipanel19);
            app.text69.Text = 'Collector - Fluid  (A23) (J)';
            app.A23 = uieditfield(app.uipanel19, 'text');
            app.A23.Value = '0.0';
            app.text70 = uilabel(app.uipanel19);
            app.text70.Text = 'Collector Coating - Fluid  (A2p3) (J)';
            app.A2p3 = uieditfield(app.uipanel19, 'text');
            app.A2p3.Value = '0.0';
            app.cbA12 = uicheckbox(app.uipanel19);
            app.cbA12.ValueChangedFcn = createCallbackFcn(app, @cbA12_Callback, true);
            app.cbA12.Text = 'Calculate from single material values';
            app.cbA13 = uicheckbox(app.uipanel19);
            app.cbA13.ValueChangedFcn = createCallbackFcn(app, @cbA13_Callback, true);
            app.cbA13.Text = 'Calculate from single material values';
            app.cbA1p2 = uicheckbox(app.uipanel19);
            app.cbA1p2.ValueChangedFcn = createCallbackFcn(app, @cbA1p2_Callback, true);
            app.cbA1p2.Text = 'Calculate from single material values';
            app.cbA1p2p = uicheckbox(app.uipanel19);
            app.cbA1p2p.ValueChangedFcn = createCallbackFcn(app, @cbA1p2p_Callback, true);
            app.cbA1p2p.Text = 'Calculate from single material values';
            app.cbA1p3 = uicheckbox(app.uipanel19);
            app.cbA1p3.ValueChangedFcn = createCallbackFcn(app, @cbA1p3_Callback, true);
            app.cbA1p3.Text = 'Calculate from single material values';
            app.cbA23 = uicheckbox(app.uipanel19);
            app.cbA23.ValueChangedFcn = createCallbackFcn(app, @cbA23_Callback, true);
            app.cbA23.Text = 'Calculate from single material values';
            app.cbA12p = uicheckbox(app.uipanel19);
            app.cbA12p.ValueChangedFcn = createCallbackFcn(app, @cbA12p_Callback, true);
            app.cbA12p.Text = 'Calculate from single material values';
            app.cbA2p3 = uicheckbox(app.uipanel19);
            app.cbA2p3.ValueChangedFcn = createCallbackFcn(app, @cbA2p3_Callback, true);
            app.cbA2p3.Text = 'Calculate from single material values';
            app.uipanelHam = uipanel(app.uipanelCOATED);
            app.uipanelHam.Title = 'Hamaker constants - Single material values';
            app.A11 = uieditfield(app.uipanelHam, 'text');
            app.A11.Value = '6.50e-20';
            app.textA11 = uilabel(app.uipanelHam);
            app.textA11.Text = 'Colloid Hamaker constant (A11) (J)';
            app.A1p1p = uieditfield(app.uipanelHam, 'text');
            app.A1p1p.Value = '7.00e-20';
            app.textA1p1p = uilabel(app.uipanelHam);
            app.textA1p1p.Text = 'Colloid coating Hamaker constant (A1p1p) (J)';
            app.A22 = uieditfield(app.uipanelHam, 'text');
            app.A22.Value = '6.30e-20';
            app.textA22 = uilabel(app.uipanelHam);
            app.textA22.Text = 'Collector Hamaker constant (A22) (J))';
            app.A2p2p = uieditfield(app.uipanelHam, 'text');
            app.A2p2p.Value = '1.51e-19';
            app.textA2p2p = uilabel(app.uipanelHam);
            app.textA2p2p.Text = 'Collector coating Hamaker constant (A2p2p) (J)';
            app.pbCLOSEcoated = uibutton(app.uipanelCOATED, 'push');
            app.pbCLOSEcoated.ButtonPushedFcn = createCallbackFcn(app, @pbCLOSEcoated_Callback, true);
            app.pbCLOSEcoated.Text = 'Close Coated Sytem Panel';
            app.pbCalcCoated = uibutton(app.uipanelCOATED, 'push');
            app.pbCalcCoated.ButtonPushedFcn = createCallbackFcn(app, @pbCalcCoated_Callback, true);
            app.pbCalcCoated.Text = 'Calculate';
            app.pbOKcoating = uibutton(app.uipanelCOATED, 'push');
            app.pbOKcoating.ButtonPushedFcn = createCallbackFcn(app, @pbOKcoating_Callback, true);
            app.pbOKcoating.Text = 'Use calculated values';
            app.uipanelS3 = uipanel(app.uipanelCOATED);
            app.uipanelS3.Title = 'Coated Colloid - Collector';
            app.text108 = uilabel(app.uipanelS3);
            app.text108.Text = '(J)';
            app.text107 = uilabel(app.uipanelS3);
            app.text107.Text = '(J)';
            app.s3A12 = uilabel(app.uipanelS3);
            app.s3A12.Text = 'calculate';
            app.s3A1p2 = uilabel(app.uipanelS3);
            app.s3A1p2.Text = 'calculate';
            app.text2S3 = uilabel(app.uipanelS3);
            app.text2S3.Text = 'Colloid - Collector Coating';
            app.text1S3 = uilabel(app.uipanelS3);
            app.text1S3.Text = 'Colloid coating - Collector';
            app.uipanelS1 = uipanel(app.uipanelCOATED);
            app.uipanelS1.Title = 'Coated Colloid - Coated Collector';
            app.text1S1 = uilabel(app.uipanelS1);
            app.text1S1.Text = 'Colloid coating - Collector Coating';
            app.text2S1 = uilabel(app.uipanelS1);
            app.text2S1.Text = 'Colloid - Collector Coating';
            app.text3S1 = uilabel(app.uipanelS1);
            app.text3S1.Text = 'Colloid coating - Collector';
            app.text4S1 = uilabel(app.uipanelS1);
            app.text4S1.Text = 'Colloid - Collector ';
            app.s1A1p2p = uilabel(app.uipanelS1);
            app.s1A1p2p.Text = 'calculate';
            app.s1A12p = uilabel(app.uipanelS1);
            app.s1A12p.Text = 'calculate';
            app.s1A1p2 = uilabel(app.uipanelS1);
            app.s1A1p2.Text = 'calculate';
            app.s1A12 = uilabel(app.uipanelS1);
            app.s1A12.Text = 'calculate';
            app.text101 = uilabel(app.uipanelS1);
            app.text101.Text = '(J)';
            app.text102 = uilabel(app.uipanelS1);
            app.text102.Text = '(J)';
            app.text103 = uilabel(app.uipanelS1);
            app.text103.Text = '(J)';
            app.text104 = uilabel(app.uipanelS1);
            app.text104.Text = '(J)';
            app.uipanelS2 = uipanel(app.uipanelCOATED);
            app.uipanelS2.Title = 'Colloid - Coated Collector';
            app.text1S2 = uilabel(app.uipanelS2);
            app.text1S2.Text = 'Colloid - Collector Coating';
            app.text2S2 = uilabel(app.uipanelS2);
            app.text2S2.Text = 'Colloid - Collector';
            app.s2A12p = uilabel(app.uipanelS2);
            app.s2A12p.Text = 'calculate';
            app.s2A12 = uilabel(app.uipanelS2);
            app.s2A12.Text = 'calculate';
            app.text105 = uilabel(app.uipanelS2);
            app.text105.Text = '(J)';
            app.text106 = uilabel(app.uipanelS2);
            app.text106.Text = '(J)';
            app.text131 = uilabel(app.uipanelCOATED);
            app.text131.Text = 'Hamaker constant contributions';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIxDLVO_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @GUIxDLVO_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end